classdef exp10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        FilterTab            matlab.ui.container.Tab
        FilterAxes           matlab.ui.control.UIAxes
        TimeFreqTab          matlab.ui.container.Tab
        SNRSelector          matlab.ui.control.DropDown
        SNRSelectorLabel     matlab.ui.control.Label
        SignalSelector       matlab.ui.control.DropDown
        SignalSelectorLabel  matlab.ui.control.Label
        FreqAxes             matlab.ui.control.UIAxes
        TimeAxes             matlab.ui.control.UIAxes
        ParametersTab        matlab.ui.container.Tab
        ThresholdAxes        matlab.ui.control.UIAxes
        AmplitudeAxes        matlab.ui.control.UIAxes
        VarianceAxes         matlab.ui.control.UIAxes
        PDFTab               matlab.ui.container.Tab
        PDFSNRSelector       matlab.ui.control.DropDown
        PDFSNRLabel          matlab.ui.control.Label
        PDFAxes              matlab.ui.control.UIAxes
        DetectionTab         matlab.ui.container.Tab
        DetectionAxes        matlab.ui.control.UIAxes
        Panel                matlab.ui.container.Panel
        RunButton            matlab.ui.control.Button
        SNREditField         matlab.ui.control.EditField
        SNRRangeLabel        matlab.ui.control.Label
        Sigma2EditField      matlab.ui.control.NumericEditField
        Sigma2Label          matlab.ui.control.Label
        TEditField           matlab.ui.control.NumericEditField
        TLabel               matlab.ui.control.Label
        OrderEditField       matlab.ui.control.NumericEditField
        OrderLabel           matlab.ui.control.Label
        BWEditField          matlab.ui.control.NumericEditField
        BWLabel              matlab.ui.control.Label
        f0EditField          matlab.ui.control.NumericEditField
        f0Label              matlab.ui.control.Label
        FsEditField          matlab.ui.control.NumericEditField
        FsLabel              matlab.ui.control.Label
    end


    properties (Access = private)
        Property % Description
        Fs = 200000;
        f0 = 80000;
        BW = 400;
        order = 4;
        T = 1;
        sigma2 = 1;
        SNR_range_str = '-20:5:20';
        SNR_dB_vec = -20:5:20;
        
        % 仿真结果存储变量
        filter_response = [];
        noise_time = [];
        noise_freq = [];
        signal_time_data = cell(1, 0);  % 存储不同信噪比的信号数据
        signal_freq_data = cell(1, 0);
        res_b = [];
        res_Vt = [];
        res_var_N = [];
        res_Pm_theo = [];
        res_Pm_act = [];
        envelope_data = [];
        selected_snr_idx = 1;
        simulation_completed = false;  % 标记仿真是否完成
        progressDialog         % 进度条对话框
    end

    methods (Access = private)

        function runSimulation(app)
            % 运行仿真主函数
            % 解析信噪比范围
            try
                app.SNR_dB_vec = eval(app.SNREditField.Value);
            catch
                app.SNR_dB_vec = -20:5:20;
                app.SNREditField.Value = '-20:5:20';
            end
            
            % 从界面读取所有参数
            app.Fs = app.FsEditField.Value;
            app.f0 = app.f0EditField.Value;
            app.BW = app.BWEditField.Value;
            app.order = app.OrderEditField.Value;
            app.T = app.TEditField.Value;
            app.sigma2 = app.Sigma2EditField.Value;
            
            % 参数合法性检查
            if app.BW <= 0
                uialert(app.UIFigure, '带宽必须大于0', '参数错误');
                app.RunButton.Enable = 'on';
                app.RunButton.Text = '开始仿真';
                return;
            end
            
            if app.Fs <= 2*app.f0
                uialert(app.UIFigure, '采样频率必须大于2倍中心频率', '参数错误');
                app.RunButton.Enable = 'on';
                app.RunButton.Text = '开始仿真';
                return;
            end
            
            % 1. 设计窄带带通滤波器并立即更新图形
            f_low = app.f0 - app.BW/2;
            f_high = app.f0 + app.BW/2;
            Wn = [f_low f_high] / (app.Fs/2);
            [b_filt, a_filt] = butter(app.order, Wn, 'bandpass');
            
            % 获取滤波器频率响应
            [h_resp, f_resp] = freqz(b_filt, a_filt, 4096, app.Fs);
            app.filter_response = [f_resp, 20*log10(abs(h_resp))];
            
            % 立即更新滤波器幅频响应图
            cla(app.FilterAxes);
            plot(app.FilterAxes, f_resp, 20*log10(abs(h_resp)), 'b', 'LineWidth', 1.5);
            title(app.FilterAxes, sprintf('窄带带通滤波器幅频响应(f_0=%dHz, B=%dHz)', app.f0, app.BW));
            xlabel(app.FilterAxes, '频率/Hz');
            ylabel(app.FilterAxes, '幅度/dB');
            grid(app.FilterAxes, 'on');
            legend(app.FilterAxes, '窄带带通滤波器幅频响应曲线', 'Location','northeast');
            xlim(app.FilterAxes, [app.f0-2000, app.f0+2000]);
            drawnow;  % 立即更新滤波器图形
            
            % 更新下拉框选项
            app.PDFSNRSelector.Items = arrayfun(@num2str, app.SNR_dB_vec, 'UniformOutput', false);
            app.PDFSNRSelector.Value = num2str(app.SNR_dB_vec(end - 3));
            app.SNRSelector.Items = arrayfun(@num2str, app.SNR_dB_vec, 'UniformOutput', false);
            app.SNRSelector.Value = num2str(app.SNR_dB_vec(end - 3));
            
            % 2. 生成窄带噪声
            N = round(app.T * app.Fs);
            t = (0:N-1) / app.Fs;
            
            noise_raw = randn(1, N);
            noise_nb = filter(b_filt, a_filt, noise_raw);
            
            % 归一化噪声到指定方差
            noise_std = std(noise_nb);
            noise_nb = noise_nb / noise_std * sqrt(app.sigma2);
            
            % 存储噪声数据
            app.noise_time = [t(1:min(1000,N)); noise_nb(1:min(1000,N))];
            
            % 计算噪声功率谱
            [Pxx_noise, F_noise] = periodogram(noise_nb, rectwin(length(noise_nb)), 4096, app.Fs);
            app.noise_freq = [F_noise, 10*log10(Pxx_noise)];
            
            % 3. 初始化信号数据存储容器
            app.signal_time_data = cell(1, length(app.SNR_dB_vec));
            app.signal_freq_data = cell(1, length(app.SNR_dB_vec));
            
            % 4. 信噪比循环仿真
            num_trials = 1000;
            
            % 预分配结果数组
            app.res_b = zeros(size(app.SNR_dB_vec));
            app.res_Vt = zeros(size(app.SNR_dB_vec));
            app.res_Pm_theo = zeros(size(app.SNR_dB_vec));
            app.res_Pm_act = zeros(size(app.SNR_dB_vec));
            app.res_var_N = zeros(size(app.SNR_dB_vec));
            app.envelope_data = cell(size(app.SNR_dB_vec));
            
            a_axis = 0:0.001:30;
            
            % 创建百分比进度条
            app.progressDialog = uiprogressdlg(app.UIFigure, 'Title', '包络检测仿真进度', ...
                'Message', '正在初始化仿真...', ...
                'Value', 0, ...
                'Indeterminate', 'off');
            
            total_iterations = length(app.SNR_dB_vec) * num_trials;
            completed_iterations = 0;
            
            % 主仿真循环
            for i = 1:length(app.SNR_dB_vec)
                snr_val = app.SNR_dB_vec(i);
                
                % 更新进度条信息
                progress_percentage_iter = i / length(app.SNR_dB_vec);
                app.progressDialog.Message = sprintf('处理 SNR = %d dB, 进度: %.1f%%', ...
                    snr_val, progress_percentage_iter * 100);
                app.progressDialog.Value = progress_percentage_iter * 0.3; % 前30%用于处理SNR循环
                drawnow;
                
                % 计算信号幅度b
                b = sqrt(2 * app.sigma2 * 10^(snr_val/10));
                app.res_b(i) = b;
                
                % 计算理论概率密度函数
                sigma = sqrt(app.sigma2);
                pdf_H0 = (a_axis / app.sigma2) .* exp(-a_axis.^2 / (2*app.sigma2));
                pdf_H1 = (a_axis / app.sigma2) .* exp(-(a_axis.^2 + b^2)/(2*app.sigma2)) .* besseli(0, a_axis * b / app.sigma2);
                
                % 求解检测阈值V_T
                diff_pdf = abs(pdf_H0 - pdf_H1);
                valid_idx = find(a_axis > 0 & a_axis < b+3);
                [~, min_idx_local] = min(diff_pdf(valid_idx));
                idx_Vt = valid_idx(min_idx_local);
                Vt = a_axis(idx_Vt);
                app.res_Vt(i) = Vt;
                
                % 计算理论漏警率
                mask_integral = a_axis <= Vt;
                Pm_theory = trapz(a_axis(mask_integral), pdf_H1(mask_integral));
                app.res_Pm_theo(i) = Pm_theory;
                
                % 蒙特卡洛仿真计算实际漏警率
                Pm_act = 0;
                current_var_sum = 0;
                envelope_store = [];
                
                % 生成用于显示的信号 (使用第一个试次的信号)
                theta = rand * 2 * pi;
                s_t = b * cos(2*pi*app.f0*t + theta);
                v_t_display = s_t + noise_nb;
                
                % 存储当前信噪比的信号数据
                app.signal_time_data{i} = [t(1:min(1000,N)); v_t_display(1:min(1000,N))];
                [Pxx_signal, F_signal] = periodogram(v_t_display, rectwin(length(v_t_display)), 4096, app.Fs);
                app.signal_freq_data{i} = [F_signal, 10*log10(Pxx_signal)];
                
                for k = 1:num_trials
                    % 生成新的窄带噪声
                    n_t = randn(1, N);
                    n_t = filter(b_filt, a_filt, n_t);
                    n_t = n_t / std(n_t) * sigma;
                    current_var_sum = current_var_sum + var(n_t);
                    
                    % 生成带随机相位的正弦信号
                    theta_k = rand * 2 * pi;
                    s_t_k = b * cos(2*pi*app.f0*t + theta_k);
                    
                    % 接收信号
                    v_t = s_t_k + n_t;
                    
                    % 包络检波
                    v_analytic = hilbert(v_t);
                    envelope = abs(v_analytic);
                    envelope_store = [envelope_store, envelope];
                    
                    % 统计漏警次数
                    Pm_act = Pm_act + sum(envelope < Vt)/length(envelope);
                    
                    % 更新进度条百分比
                    completed_iterations = completed_iterations + 1;
                    progress_percentage = completed_iterations / total_iterations; % 后70%用于蒙特卡洛仿真
                    app.progressDialog.Message = sprintf('处理 SNR = %d dB, 试次 %d/%d, 进度: %.1f%%', ...
                        snr_val, k, num_trials, progress_percentage * 100);
                    app.progressDialog.Value = progress_percentage;
                    drawnow;
                end
                
                app.res_Pm_act(i) = Pm_act / num_trials;
                app.res_var_N(i) = current_var_sum / num_trials;
                app.envelope_data{i} = envelope_store;
            end
            
            % 关闭进度条
            close(app.progressDialog);
            
            % 标记仿真已完成
            app.simulation_completed = true;
            
            % 更新参数结果图
            updateParameterPlots(app);
            
            % 更新时域频域图
            updateTimeFreqPlot(app);
            
            % 更新概率密度图
            updatePDFPlot(app);
            
            % 更新检测性能图
            updateDetectionPlot(app);
            
            % 恢复按钮状态
            app.RunButton.Enable = 'on';
            app.RunButton.Text = '开始仿真';
        end

        function updateParameterPlots(app)
            if ~app.simulation_completed || isempty(app.res_var_N)
                % 仿真未完成时不绘制图形
                cla(app.VarianceAxes);
                cla(app.AmplitudeAxes);
                cla(app.ThresholdAxes);
                return;
            end

            % 图1：噪声方差随信噪比变化
            cla(app.VarianceAxes);
            plot(app.VarianceAxes, app.SNR_dB_vec, app.res_var_N, 'b-o', ...
                'LineWidth', 1.5, 'MarkerSize', 4, 'MarkerFaceColor','b');
            title(app.VarianceAxes, '噪声方差随信噪比变化图');
            xlabel(app.VarianceAxes, '信噪比SNR/dB');
            ylabel(app.VarianceAxes, '噪声方差σ²');
            legend(app.VarianceAxes, '噪声方差随信噪比变化曲线', 'Location','northwest');
            grid(app.VarianceAxes, 'on');

            % 图2：信号幅度随信噪比变化
            cla(app.AmplitudeAxes);
            plot(app.AmplitudeAxes, app.SNR_dB_vec, app.res_b, 'b-o', ...
                'LineWidth', 1.5, 'MarkerSize', 4, 'MarkerFaceColor','b');
            title(app.AmplitudeAxes, '信号幅度随信噪比变化图');
            xlabel(app.AmplitudeAxes, '信噪比SNR/dB');
            ylabel(app.AmplitudeAxes, '信号幅度b');
            legend(app.AmplitudeAxes, '信号幅度随信噪比变化曲线', 'Location','northwest');
            grid(app.AmplitudeAxes, 'on');

            % 图3：检测阈值随信噪比变化
            cla(app.ThresholdAxes);
            plot(app.ThresholdAxes, app.SNR_dB_vec, app.res_Vt, 'b-o', ...
                'LineWidth', 1.5, 'MarkerSize', 4, 'MarkerFaceColor','b');
            title(app.ThresholdAxes, '检测阈值随信噪比变化图');
            xlabel(app.ThresholdAxes, '信噪比SNR/dB');
            ylabel(app.ThresholdAxes, '阈值V_T');
            legend(app.ThresholdAxes, '检测阈值随信噪比变化曲线', 'Location','northwest');
            grid(app.ThresholdAxes, 'on');
        end

        function updateTimeFreqPlot(app)
            if ~app.simulation_completed || isempty(app.noise_time)
                % 仿真未完成时不绘制图形
                cla(app.TimeAxes);
                cla(app.FreqAxes);
                return;
            end

            % 获取当前选中的信噪比索引
            selected_snr_str = app.SNRSelector.Value;
            selected_idx = find(app.SNR_dB_vec == str2double(selected_snr_str));
            if isempty(selected_idx)
                selected_idx = 1;
            end

            % 根据选择的信号类型读取对应数据
            switch app.SignalSelector.Value
                case 'N(t)'
                    time_data = app.noise_time;
                    freq_data = app.noise_freq;
                    title_str = '窄带随机噪声 N(t)';
                case 'V(t)'
                    % 检查当前信噪比是否有对应的信号数据
                    if length(app.signal_time_data) >= selected_idx && ~isempty(app.signal_time_data{selected_idx})
                        time_data = app.signal_time_data{selected_idx};
                        freq_data = app.signal_freq_data{selected_idx};
                        title_str = sprintf('接收信号 V(t), SNR = %s dB', selected_snr_str);
                    else
                        % 若数据不存在则使用噪声数据替代
                        time_data = app.noise_time;
                        freq_data = app.noise_freq;
                        title_str = '接收信号 V(t) (使用噪声数据)';
                    end
            end
            
            % 绘制时域波形
            cla(app.TimeAxes);
            plot(app.TimeAxes, time_data(1,:), time_data(2,:), 'b', 'LineWidth', 1);
            title(app.TimeAxes, [title_str, ' 时域波形']);
            xlabel(app.TimeAxes, '时间 (s)');
            ylabel(app.TimeAxes, '幅度 (V)');
            grid(app.TimeAxes, 'on');
            xlim(app.TimeAxes, [time_data(1,1), time_data(1,end)]);
            
            % 绘制频域功率谱
            cla(app.FreqAxes);
            plot(app.FreqAxes, freq_data(:,1), freq_data(:,2), 'b', 'LineWidth', 1);
            title(app.FreqAxes, [title_str, ' 功率谱密度']);
            xlabel(app.FreqAxes, '频率/Hz');
            ylabel(app.FreqAxes, '功率谱(dB/Hz)');
            grid(app.FreqAxes, 'on');
            xlim(app.FreqAxes, [app.f0-10000, app.f0+10000]);
        end

        function updatePDFPlot(app)
            if ~app.simulation_completed || isempty(app.SNR_dB_vec) || isempty(app.envelope_data)
                % 仿真未完成时不绘制图形
                cla(app.PDFAxes);
                return;
            end

            % 创建进度条
            app.progressDialog = uiprogressdlg(app.UIFigure, 'Title', '绘图进行中', ...
                'Message', '正在绘制概率密度函数...', ...
                'Value', 0, ...
                'Indeterminate', 'off');

            % 获取当前选中的信噪比索引
            selected_snr_str = app.PDFSNRSelector.Value;
            selected_idx = find(app.SNR_dB_vec == str2double(selected_snr_str));

            if isempty(selected_idx) || selected_idx > length(app.envelope_data)
                selected_idx = 1;
            end

            % 更新进度条
            app.progressDialog.Message = '加载数据...';
            app.progressDialog.Value = 0.2;
            drawnow;

            % 读取当前信噪比的相关数据
            snr_val = app.SNR_dB_vec(selected_idx);
            b = app.res_b(selected_idx);
            Vt = app.res_Vt(selected_idx);
            envelopedata = app.envelope_data{selected_idx};

            if isempty(envelopedata)
                close(app.progressDialog);
                return;
            end
            
            % 更新进度条
            app.progressDialog.Message = '计算概率密度函数...';
            app.progressDialog.Value = 0.5;
            drawnow;
            
            % 定义幅度轴
            a_max = max(envelopedata) * 1.2;
            a_axis = 0:0.01:a_max;
            % sigma = sqrt(app.sigma2);
            
            % 计算理论概率密度
            pdf_H0 = (a_axis / app.sigma2) .* exp(-a_axis.^2 / (2*app.sigma2));
            pdf_H1 = (a_axis / app.sigma2) .* exp(-(a_axis.^2 + b^2)/(2*app.sigma2)) .* besseli(0, a_axis * b / app.sigma2);
            
            % 更新进度条
            app.progressDialog.Message = '计算经验概率密度...';
            app.progressDialog.Value = 0.7;
            drawnow;
            
            % 用ksdensity计算实际包络的经验概率密度
            [f_est, xi_est] = ksdensity(envelopedata, a_axis);
            
            % 更新进度条
            app.progressDialog.Message = '绘制图形...';
            app.progressDialog.Value = 0.9;
            drawnow;
            
            % 绘制图形
            cla(app.PDFAxes);
            hold(app.PDFAxes, 'on');
            
            % 绘制理论分布曲线
            plot(app.PDFAxes, a_axis, pdf_H0, 'b-', 'LineWidth', 1.5, 'DisplayName', '窄带高斯噪声包络概率密度函数');
            plot(app.PDFAxes, a_axis, pdf_H1, 'r-', 'LineWidth', 1.5, 'DisplayName', '正弦波加窄带高斯噪声包络概率密度函数');
            
            % 绘制实际经验分布曲线
            plot(app.PDFAxes, xi_est, f_est, 'g-', 'LineWidth', 1.5, 'DisplayName', '实际包络概率密度函数');
            
            % 绘制检测阈值线
            xline(app.PDFAxes, Vt, 'k-', 'LineWidth', 1.5, 'DisplayName', sprintf('阈值 V_T = %.2f', Vt));
            
            hold(app.PDFAxes, 'off');
            
            title(app.PDFAxes, sprintf('SNR = %d dB下的包络概率密度函数', snr_val));
            xlabel(app.PDFAxes, '包络幅度a');
            ylabel(app.PDFAxes, '概率密度f(a)');
            legend(app.PDFAxes, 'Location', 'northeast');
            grid(app.PDFAxes, 'on');
            xlim(app.PDFAxes, [0, a_max]);
            
            % 关闭进度条
            app.progressDialog.Value = 1.0;
            pause(0.2);
            close(app.progressDialog);
        end

        function updateDetectionPlot(app)
            if ~app.simulation_completed || isempty(app.res_Pm_theo) || isempty(app.res_Pm_act)
                % 仿真未完成时不绘制图形
                cla(app.DetectionAxes);
                return;
            end

            cla(app.DetectionAxes);

            % 绘制漏警率曲线
            semilogy(app.DetectionAxes, app.SNR_dB_vec, app.res_Pm_theo, 'b-o', ...
                'LineWidth', 1.5, 'MarkerSize', 4,'MarkerFaceColor', 'b', 'DisplayName', '理论漏警概率');
            hold(app.DetectionAxes, 'on');
            semilogy(app.DetectionAxes, app.SNR_dB_vec, app.res_Pm_act, 'r--x', ...
                'LineWidth', 1.5, 'MarkerSize', 4, 'MarkerFaceColor', 'r', 'DisplayName', '实际漏警概率');

            hold(app.DetectionAxes, 'off');

            title(app.DetectionAxes, '漏警概率Pm随信噪比变化曲线');
            xlabel(app.DetectionAxes, '信噪比SNR/dB');
            ylabel(app.DetectionAxes, '漏警概率Pm');
            legend(app.DetectionAxes, 'Location', 'northeast');
            grid(app.DetectionAxes, 'on');
        end
       
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SNREditField
        function SNREditFieldValueChanged(app, event)
            % 验证信噪比范围输入
            try
                temp = eval(app.SNREditField.Value);
                if ~isnumeric(temp) || isempty(temp)
                    error('Invalid input');
                end
            catch
                app.SNREditField.Value = '-20:5:20';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunButton.Enable = 'off';
            app.RunButton.Text = '仿真中...';
            drawnow;

            try
               runSimulation(app);
            catch ME
                uialert(app.UIFigure, ME.message, '仿真错误');
                % 恢复按钮状态
                app.RunButton.Enable = 'on';
                app.RunButton.Text = '开始仿真';
                % 关闭可能存在的进度条
                if ~isempty(app.progressDialog) && isvalid(app.progressDialog)
                    close(app.progressDialog);
                end
            end
        end

        % Value changed function: SignalSelector
        function SignalSelectorValueChanged(app, event)
            updateTimeFreqPlot(app);
        end

        % Value changed function: SNRSelector
        function SNRSelectorValueChanged(app, event)
            updateTimeFreqPlot(app);
        end

        % Value changed function: PDFSNRSelector
        function PDFSNRSelectorValueChanged(app, event)
            updatePDFPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '包络检测器设计';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '控制参数';
            app.FsLabel = uilabel(app.Panel);
            app.FsLabel.Text = '采样频率 Fs/Hz:';
            app.FsEditField = uieditfield(app.Panel, 'numeric');
            app.FsEditField.Limits = [0 Inf];
            app.FsEditField.ValueDisplayFormat = '%.0f';
            app.FsEditField.Value = 200000;
            app.f0Label = uilabel(app.Panel);
            app.f0Label.Text = '中心频率 f0/Hz:';
            app.f0EditField = uieditfield(app.Panel, 'numeric');
            app.f0EditField.Limits = [0 Inf];
            app.f0EditField.ValueDisplayFormat = '%.0f';
            app.f0EditField.Value = 80000;
            app.BWLabel = uilabel(app.Panel);
            app.BWLabel.Text = '带宽 BW/Hz:';
            app.BWEditField = uieditfield(app.Panel, 'numeric');
            app.BWEditField.Limits = [1 Inf];
            app.BWEditField.ValueDisplayFormat = '%.0f';
            app.BWEditField.Value = 400;
            app.OrderLabel = uilabel(app.Panel);
            app.OrderLabel.Text = '滤波器阶数:';
            app.OrderEditField = uieditfield(app.Panel, 'numeric');
            app.OrderEditField.Limits = [1 10];
            app.OrderEditField.RoundFractionalValues = 'on';
            app.OrderEditField.Value = 4;
            app.TLabel = uilabel(app.Panel);
            app.TLabel.Text = '信号时长 T/s:';
            app.TEditField = uieditfield(app.Panel, 'numeric');
            app.TEditField.Limits = [0 10];
            app.TEditField.Value = 1;
            app.Sigma2Label = uilabel(app.Panel);
            app.Sigma2Label.Text = '窄带噪声方差 σ²:';
            app.Sigma2EditField = uieditfield(app.Panel, 'numeric');
            app.Sigma2EditField.Limits = [0 Inf];
            app.Sigma2EditField.Value = 1;
            app.SNRRangeLabel = uilabel(app.Panel);
            app.SNRRangeLabel.Text = '信噪比SNR范围/dB:';
            app.SNREditField = uieditfield(app.Panel, 'text');
            app.SNREditField.ValueChangedFcn = createCallbackFcn(app, @SNREditFieldValueChanged, true);
            app.SNREditField.Value = '-20:5:20';
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = '开始仿真';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = '滤波器幅频响应';
            app.FilterAxes = uiaxes(app.FilterTab);
            title(app.FilterAxes, '窄带带通滤波器幅频响应')
            xlabel(app.FilterAxes, '频率/Hz')
            ylabel(app.FilterAxes, '幅度/dB')
            zlabel(app.FilterAxes, 'Z')
            app.TimeFreqTab = uitab(app.TabGroup);
            app.TimeFreqTab.Title = '时域波形和功率谱';
            app.TimeAxes = uiaxes(app.TimeFreqTab);
            title(app.TimeAxes, '窄带随机噪声N(t)时域波形')
            xlabel(app.TimeAxes, '时间/s')
            ylabel(app.TimeAxes, '幅度')
            zlabel(app.TimeAxes, 'Z')
            app.FreqAxes = uiaxes(app.TimeFreqTab);
            title(app.FreqAxes, '窄带随机噪声N(t)功率谱密度')
            xlabel(app.FreqAxes, '频率/Hz')
            ylabel(app.FreqAxes, '功率谱(dB/Hz)')
            zlabel(app.FreqAxes, 'Z')
            app.SignalSelectorLabel = uilabel(app.TimeFreqTab);
            app.SignalSelectorLabel.Text = '选择信号：';
            app.SignalSelector = uidropdown(app.TimeFreqTab);
            app.SignalSelector.Items = {'N(t)', 'V(t)'};
            app.SignalSelector.ValueChangedFcn = createCallbackFcn(app, @SignalSelectorValueChanged, true);
            app.SignalSelector.Value = 'N(t)';
            app.SNRSelectorLabel = uilabel(app.TimeFreqTab);
            app.SNRSelectorLabel.Text = '选择信噪比/dB：';
            app.SNRSelector = uidropdown(app.TimeFreqTab);
            app.SNRSelector.Items = {'-20', '-15', '-10', '-5', '0', '5', '10', '15', '20'};
            app.SNRSelector.ValueChangedFcn = createCallbackFcn(app, @SNRSelectorValueChanged, true);
            app.SNRSelector.Value = '5';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = '参数结果';
            app.VarianceAxes = uiaxes(app.ParametersTab);
            title(app.VarianceAxes, '噪声方差随信噪比变化图')
            xlabel(app.VarianceAxes, '信噪比SNR/dB')
            ylabel(app.VarianceAxes, '噪声方差σ²')
            zlabel(app.VarianceAxes, 'Z')
            app.AmplitudeAxes = uiaxes(app.ParametersTab);
            title(app.AmplitudeAxes, '信号幅度随信噪比变化图')
            xlabel(app.AmplitudeAxes, '信噪比SNR/dB')
            ylabel(app.AmplitudeAxes, '信号幅度b')
            zlabel(app.AmplitudeAxes, 'Z')
            app.ThresholdAxes = uiaxes(app.ParametersTab);
            title(app.ThresholdAxes, '检测阈值随信噪比变化图')
            xlabel(app.ThresholdAxes, '信噪比SNR/dB')
            ylabel(app.ThresholdAxes, '阈值V_T')
            zlabel(app.ThresholdAxes, 'Z')
            app.PDFTab = uitab(app.TabGroup);
            app.PDFTab.Title = '概率密度函数';
            app.PDFAxes = uiaxes(app.PDFTab);
            title(app.PDFAxes, '概率密度函数')
            xlabel(app.PDFAxes, '包络幅度a')
            ylabel(app.PDFAxes, '概率密度f(a)')
            zlabel(app.PDFAxes, 'Z')
            app.PDFSNRLabel = uilabel(app.PDFTab);
            app.PDFSNRLabel.Text = '选择信噪比/dB：';
            app.PDFSNRSelector = uidropdown(app.PDFTab);
            app.PDFSNRSelector.Items = {'-20', '-15', '-10', '-5', '0', '5', '10', '15', '20'};
            app.PDFSNRSelector.ValueChangedFcn = createCallbackFcn(app, @PDFSNRSelectorValueChanged, true);
            app.PDFSNRSelector.Value = '5';
            app.DetectionTab = uitab(app.TabGroup);
            app.DetectionTab.Title = '检测性能';
            app.DetectionAxes = uiaxes(app.DetectionTab);
            title(app.DetectionAxes, '漏警概率Pm随信噪比变化曲线')
            xlabel(app.DetectionAxes, '信噪比SNR/dB')
            ylabel(app.DetectionAxes, '漏警概率Pm')
            zlabel(app.DetectionAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end