classdef exp5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ProgressLamp                 matlab.ui.control.Lamp
        StatusLabel                  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        DataFilterTab                matlab.ui.container.Tab
        FilterOrderInfoLabel         matlab.ui.control.Label
        FilterResponsePanel          matlab.ui.container.Panel
        FreqzDropDown                matlab.ui.control.DropDown
        FreqzLabel                   matlab.ui.control.Label
        CombinedResponseAxes         matlab.ui.control.UIAxes
        LowpassResponseAxes          matlab.ui.control.UIAxes
        HighpassResponseAxes         matlab.ui.control.UIAxes
        FilterParametersPanel        matlab.ui.container.Panel
        DesignFilterButton           matlab.ui.control.Button
        HighpassStopAttenEditField   matlab.ui.control.NumericEditField
        HighpassStopAttenLabel       matlab.ui.control.Label
        HighpassCutoffEditField      matlab.ui.control.NumericEditField
        HighpassCutoffLabel          matlab.ui.control.Label
        HighpassPassRippleEditField  matlab.ui.control.NumericEditField
        HighpassPassRippleLabel      matlab.ui.control.Label
        HighpassParametersLabel      matlab.ui.control.Label
        LowpassStopAttenEditField    matlab.ui.control.NumericEditField
        LowpassStopAttenLabel        matlab.ui.control.Label
        LowpassCutoffEditField       matlab.ui.control.NumericEditField
        LowpassCutoffLabel           matlab.ui.control.Label
        LowpassPassRippleEditField   matlab.ui.control.NumericEditField
        LowpassPassRippleLabel       matlab.ui.control.Label
        LowpassParametersLabel       matlab.ui.control.Label
        DataInfoLabel                matlab.ui.control.Label
        LoadDataButton               matlab.ui.control.Button
        AnalysisTab                  matlab.ui.container.Tab
        PeakFrequencyText            matlab.ui.control.TextArea
        PeakFrequencyLabel           matlab.ui.control.Label
        StimulusFrequenciesText      matlab.ui.control.TextArea
        StimulusFrequenciesLabel     matlab.ui.control.Label
        RunAnalysisButton            matlab.ui.control.Button
        ShowHarmonicCheckBox         matlab.ui.control.CheckBox
        WindowTypeDropDown           matlab.ui.control.DropDown
        WindowTypeLabel              matlab.ui.control.Label
        FFTEditField                 matlab.ui.control.NumericEditField
        FFTLengthLabel               matlab.ui.control.Label
        TrialSelectorDropDown        matlab.ui.control.DropDown
        TrialSelectorLabel           matlab.ui.control.Label
        ChannelSelectorDropDown      matlab.ui.control.DropDown
        ChannelSelectorLabel         matlab.ui.control.Label
        SpectrumAxes                 matlab.ui.control.UIAxes
        TimeDomainAxes               matlab.ui.control.UIAxes
        ResultsTab                   matlab.ui.container.Tab
        AccuracyTextArea             matlab.ui.control.TextArea
        AccuracyLabel                matlab.ui.control.Label
        ResultsTable                 matlab.ui.control.Table
        RunClassificationButton      matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
        % 数据属性
        RawData                    % 原始脑电数据 (8×3500×20)
        Labels                     % 真实标签 (20×1)
        FilteredData               % 滤波后数据
        Fs = 1000                  % 采样频率 (Hz)

        % 滤波器系数
        lpCoeff                    % 低通滤波器系数
        hpCoeff                    % 高通滤波器系数
        lpH                        % 低通滤波器频率响应
        hpH                        % 高通滤波器频率响应
        combinedH                  % 组合滤波器频率响应
        combinedF                  % 滤波器频率范围

        % 频率参数
        StimFreqs = [8, 8.5, 9, 9.5, 10, 10.5, 11, 11.5, 12, 12.5]  % 刺激频率
        Harmonics = [2, 3, 4]      % 倍频阶数

        % 分析结果
        ChannelPredictions         % 每个通道的基频预测 (20×8)
        ChannelHarmonicPredictions % 每个通道的基倍频预测 (20×8)
        JointPredictions           % 8通道联合预测 (20×1)
        HarmonicPredictions        % 基频+倍频预测 (20×1)
        PeakFrequencies            % 峰值频率 (20×8)

        % 准确率
        ChannelAccuracies         % 每个通道的基频准确率 (1×8)
        ChannelHarmonicAccuracies % 每个通道的基倍频准确率 (1×8)
        JointAccuracy             % 联合准确率
        HarmonicAccuracy          % 基频+倍频准确率

        % 滤波器参数
        LowpassCutoff = 40        % 低通截止频率 (Hz)
        LowpassPassRipple = 7     % 低通通带波纹 (dB)
        LowpassStopAtten = 20     % 低通阻带衰减 (dB)

        HighpassCutoff = 5        % 高通截止频率 (Hz)
        HighpassPassRipple = 7    % 高通通带波纹 (dB)
        HighpassStopAtten = 20    % 高通阻带衰减 (dB)

        HarmonicPeakFrequencies
    end

    methods (Access = private)

        function filterSignal = applyCascadeFilter(app, signal)
            % 应用级联滤波器（先低通后高通）
            % 第一步：低通滤波
            lpFiltered = filtfilt(app.lpCoeff.b, app.lpCoeff.a, signal);

            % 第二步：高通滤波
            filterSignal = filtfilt(app.hpCoeff.b, app.hpCoeff.a, lpFiltered);
        end

        function [spectrum, freq] = computeFFTSpectrum(app, signal)
            % 计算信号的FFT频谱
            N = app.FFTEditField.Value;

            % 加窗
            switch app.WindowTypeDropDown.Value
                case '矩形窗'
                    window = rectwin(length(signal));
                case '汉宁窗'
                    window = hann(length(signal));
                case '汉明窗'
                    window = hamming(length(signal));
                case '布莱克曼窗'
                    window = blackman(length(signal));
                otherwise
                    window = hamming(length(signal));
            end

            % 应用窗函数
            windowedSignal = signal(:) .* window;

            % 补零到FFT长度
            if length(windowedSignal) < N
                paddedSignal = [windowedSignal; zeros(N - length(windowedSignal), 1)];
            else
                paddedSignal = windowedSignal(1:N);
            end

            % 计算FFT
            fftResult = fft(paddedSignal, N);

            % 计算单边频谱
            spectrum = abs(fftResult(1:N/2+1)/app.Fs);
            spectrum(2:end-1) = spectrum(2:end-1) * 2;
            freq = (0:N/2) * app.Fs / N;
        end

        function [targetIdx, peakFreq] = detectFundamentalFrequency(app, spectrum, freq)
            % 在7-15Hz范围内寻找基频峰值
            targetIdx = 0;
            peakFreq = 0;

            % 在7-15Hz范围内寻找峰值
            freqRange = (freq >= 7 & freq <= 15);
            spectrumRange = spectrum(freqRange);
            freqRangeValues = freq(freqRange);

            [peaks, locs] = findpeaks(spectrumRange, 'NPeaks', 3, 'SortStr', 'descend');

            if ~isempty(peaks)
                % 主峰值频率
                mainPeakFreq = freqRangeValues(locs(1));
                peakFreq = mainPeakFreq;

                % 找到最接近的刺激频率
                [~, targetIdx] = min(abs(mainPeakFreq - app.StimFreqs));
            end
        end

        function [targetIdx, peakFreq] = detectHarmonicFrequency(app, spectrum, freq, basePeakVal)
            % 在15-30Hz范围内寻找2倍频峰值
            targetIdx = 0;
            peakFreq = 0;

            % 在15-30Hz范围内寻找峰值
            freqRangeHarmonic = (freq >= 15 & freq <= 30);
            if any(freqRangeHarmonic)
                % 在倍频范围内找最高峰值
                [maxValHarmonic, maxIdxHarmonic] = max(spectrum(freqRangeHarmonic));
                harmonicFreqPeak = freq(freqRangeHarmonic);
                harmonicPeakFreq = harmonicFreqPeak(maxIdxHarmonic);
                peakFreq = harmonicPeakFreq;

                % 将倍频频率转换为基频频率（除以2）
                baseFreqFromHarmonic = harmonicPeakFreq / 2;

                % 找到最接近的刺激频率
                [~, targetIdx] = min(abs(baseFreqFromHarmonic - app.StimFreqs));

                % 检查峰值是否超过阈值（基频峰值的10%）
                if maxValHarmonic <= basePeakVal * 0.1  % 阈值
                    targetIdx = 0;  % 不采用
                end
            end
        end

        function updateResultsDisplay(app)
            % 更新结果显示
            if isempty(app.ChannelPredictions)
                return;
            end

            numTrials = length(app.Labels);
            numChannels = size(app.ChannelPredictions, 2);

            % 创建结果显示表格
            resultData = cell(numTrials, 20);

            % 重新计算每个试次的投票数量，用于显示
            for trial = 1:numTrials
                % ===== 计算基频投票数量 =====
                baseVotes = zeros(1, length(app.StimFreqs));
                for channel = 1:numChannels
                    pred = app.ChannelPredictions(trial, channel);
                    if pred > 0
                        baseVotes(pred) = baseVotes(pred) + 1;
                    end
                end

                % 找到基频投票的最高票数
                % ===== 计算基倍频投票数量 =====
                combinedVotes = zeros(1, length(app.StimFreqs));
                for channel = 1:numChannels
                    % 基频投票
                    basePred = app.ChannelPredictions(trial, channel);
                    if basePred > 0
                        combinedVotes(basePred) = combinedVotes(basePred) + 1;
                    end

                    % 倍频投票
                    harmonicPred = app.ChannelHarmonicPredictions(trial, channel);
                    if harmonicPred > 0
                        combinedVotes(harmonicPred) = combinedVotes(harmonicPred) + 1;
                    end
                end

                % 找到基倍频投票的最高票数

                % 各通道基频检测结果 (列1-8)
                for channel = 1:8
                    if channel <= size(app.ChannelPredictions, 2)
                        pred = app.ChannelPredictions(trial, channel);
                        freqVal = app.PeakFrequencies(trial, channel);
                        if pred > 0 && freqVal > 0
                            resultData{trial, channel} = sprintf('%d (%.1fHz)', pred, app.StimFreqs(pred));
                        else
                            resultData{trial, channel} = '未检测';
                        end
                    else
                        resultData{trial, channel} = 'N/A';
                    end
                end

                % 各通道倍频检测结果 (列9-16)
                for channel = 1:8
                    colIdx = 8 + channel;
                    if channel <= size(app.ChannelHarmonicPredictions, 2)
                        pred = app.ChannelHarmonicPredictions(trial, channel);
                        freqVal = app.HarmonicPeakFrequencies(trial, channel);
                        if pred > 0 && freqVal > 0
                            resultData{trial, colIdx} = sprintf('%d (%.1fHz)', pred, app.StimFreqs(pred));
                        else
                            resultData{trial, colIdx} = '未检测';
                        end
                    else
                        resultData{trial, colIdx} = 'N/A';
                    end
                end

                % 基频检测-8通道联合投票结果 (列17)
                jointPred = app.JointPredictions(trial);
                baseVoteCounts = zeros(1, length(app.StimFreqs));
                for ch = 1:numChannels
                    pred = app.ChannelPredictions(trial, ch);
                    if pred > 0
                        baseVoteCounts(pred) = baseVoteCounts(pred) + 1;
                    end
                end
                voteCount = baseVoteCounts(jointPred);
                totalBaseVotes = sum(baseVoteCounts);
                resultData{trial, 17} = sprintf('%d票/共%d票 → 目标%d', ...
                    voteCount, totalBaseVotes, jointPred);

                % 基频+倍频-16通道联合投票结果 (列18)
                harmonicPred = app.HarmonicPredictions(trial);
                combinedVoteCounts = zeros(1, length(app.StimFreqs));
                for ch = 1:numChannels
                    % 基频投票
                    basePred = app.ChannelPredictions(trial, ch);
                    if basePred > 0
                        combinedVoteCounts(basePred) = combinedVoteCounts(basePred) + 1;
                    end

                    % 倍频投票
                    harmonicPredCh = app.ChannelHarmonicPredictions(trial, ch);
                    if harmonicPredCh > 0
                        combinedVoteCounts(harmonicPredCh) = combinedVoteCounts(harmonicPredCh) + 1;
                    end
                end
                voteCountCombined = combinedVoteCounts(harmonicPred);
                totalCombinedVotes = sum(combinedVoteCounts);
                resultData{trial, 18} = sprintf('%d票/共%d票 → 目标%d', ...
                    voteCountCombined, totalCombinedVotes, harmonicPred);

                % 真实标签 (列19)
                trueLabel = app.Labels(trial);
                resultData{trial, 19} = sprintf('%d (%.1fHz)', trueLabel, app.StimFreqs(trueLabel));

                % 检查是否正确 (列20)
                isJointCorrect = (jointPred == trueLabel);
                isHarmonicCorrect = (harmonicPred == trueLabel);

                resultData{trial, 20} = sprintf('基频投票:%s 基倍频投票:%s', ...
                    char(10004*isJointCorrect + '✗'*~isJointCorrect), ...
                    char(10004*isHarmonicCorrect + '✗'*~isHarmonicCorrect));
            end

            % 设置表格数据
            columnNames = cell(1, 20);
            for i = 1:8
                columnNames{i} = sprintf('基频-通道%d', i);
                columnNames{i+8} = sprintf('倍频-通道%d', i);
            end
            columnNames{17} = '基频投票(8通道)';
            columnNames{18} = '基倍频投票(16通道)';
            columnNames{19} = '真实目标';
            columnNames{20} = '分类结果';

            app.ResultsTable.Data = resultData;
            app.ResultsTable.ColumnName = columnNames;

            % ===== 计算准确率统计 =====
            accuracyText = sprintf('===== 准确率统计 =====\n\n');

            % 1. 基频检测统计
            accuracyText = sprintf('%s【基频检测 - 各通道独立检测 (7-15Hz)】\n', accuracyText);
            totalCorrectBase = 0;

            for channel = 1:numChannels
                channelCorrect = sum(app.ChannelPredictions(:, channel) == app.Labels);
                totalCorrectBase = totalCorrectBase + channelCorrect;

                accuracyText = sprintf('%s  通道 %d: %d/%d = %.2f%%\n', accuracyText, ...
                    channel, channelCorrect, numTrials, app.ChannelAccuracies(channel));
            end

            totalBaseDetections = numTrials * numChannels;
            overallAccuracyBase = totalCorrectBase / totalBaseDetections * 100;
            accuracyText = sprintf('%s  总检测次数: %d (8通道×20试次)\n', accuracyText, totalBaseDetections);
            accuracyText = sprintf('%s  总正确次数: %d\n', accuracyText, totalCorrectBase);
            accuracyText = sprintf('%s  总体准确率: %.2f%%\n\n', accuracyText, overallAccuracyBase);

            % 2. 倍频检测统计
            accuracyText = sprintf('%s【倍频检测 - 各通道独立检测 (15-30Hz, 2倍频)】\n', accuracyText);
            totalCorrectHarmonic = 0;

            for channel = 1:numChannels
                channelCorrectHarmonic = sum(app.ChannelHarmonicPredictions(:, channel) == app.Labels);
                totalCorrectHarmonic = totalCorrectHarmonic + channelCorrectHarmonic;

                accuracyText = sprintf('%s  通道 %d: %d/%d = %.2f%%\n', accuracyText, ...
                    channel, channelCorrectHarmonic, numTrials, app.ChannelHarmonicAccuracies(channel));
            end

            totalHarmonicDetections = numTrials * numChannels;
            overallAccuracyHarmonic = totalCorrectHarmonic / totalHarmonicDetections * 100;
            accuracyText = sprintf('%s  总检测次数: %d (8通道×20试次)\n', accuracyText, totalHarmonicDetections);
            accuracyText = sprintf('%s  总正确次数: %d\n', accuracyText, totalCorrectHarmonic);
            accuracyText = sprintf('%s  总体准确率: %.2f%%\n\n', accuracyText, overallAccuracyHarmonic);

            % 3. 基频检测-8通道联合投票
            jointCorrect = sum(app.JointPredictions == app.Labels);
            accuracyText = sprintf('%s【基频投票分类】\n', accuracyText);
            accuracyText = sprintf('%s  正确数: %d/%d = %.2f%%\n\n', accuracyText, ...
                jointCorrect, numTrials, app.JointAccuracy);

            % 4. 基频+倍频-16通道联合投票
            harmonicCorrect = sum(app.HarmonicPredictions == app.Labels);
            accuracyText = sprintf('%s【基倍频联合投票分类】\n', accuracyText);
            accuracyText = sprintf('%s  正确数: %d/%d = %.2f%%\n\n', accuracyText, ...
                harmonicCorrect, numTrials, app.HarmonicAccuracy);

            % 5. 综合统计
            totalAllDetections = totalBaseDetections + totalHarmonicDetections;
            totalAllCorrect = totalCorrectBase + totalCorrectHarmonic;
            overallAllAccuracy = totalAllCorrect / totalAllDetections * 100;
            accuracyText = sprintf('%s【综合统计 - 基频+倍频所有检测】\n', accuracyText);
            accuracyText = sprintf('%s  总检测次数: %d (基频160 + 倍频160)\n', accuracyText, totalAllDetections);
            accuracyText = sprintf('%s  总正确次数: %d (基频%d + 倍频%d)\n', accuracyText, ...
                totalAllCorrect, totalCorrectBase, totalCorrectHarmonic);
            accuracyText = sprintf('%s  总体准确率: %.2f%%\n\n', accuracyText, overallAllAccuracy);

            % 6. 统计总结
            accuracyText = sprintf('%s===== 统计总结 =====\n', accuracyText);
            accuracyText = sprintf('%s  总试次数: %d\n', accuracyText, numTrials);
            accuracyText = sprintf('%s  总通道数: %d (基频%d + 倍频%d)\n', accuracyText, ...
                numChannels*2, numChannels, numChannels);
            accuracyText = sprintf('%s  总检测次数: %d (基频%d + 倍频%d)\n\n', accuracyText, ...
                totalAllDetections, totalBaseDetections, totalHarmonicDetections);

            % 基频检测通道性能分析
            [bestAccuracyBase, bestChannelBase] = max(app.ChannelAccuracies);
            [worstAccuracyBase, worstChannelBase] = min(app.ChannelAccuracies);

            accuracyText = sprintf('%s基频检测通道性能分析:\n', accuracyText);
            accuracyText = sprintf('%s  最佳通道: 通道%d (%.2f%%)\n', accuracyText, bestChannelBase, bestAccuracyBase);
            accuracyText = sprintf('%s  最差通道: 通道%d (%.2f%%)\n', accuracyText, worstChannelBase, worstAccuracyBase);
            accuracyText = sprintf('%s  准确率范围: %.2f%% ~ %.2f%%\n', accuracyText, worstAccuracyBase, bestAccuracyBase);

            % 倍频检测通道性能分析
            [bestAccuracyHarmonic, bestChannelHarmonic] = max(app.ChannelHarmonicAccuracies);
            [worstAccuracyHarmonic, worstChannelHarmonic] = min(app.ChannelHarmonicAccuracies);

            accuracyText = sprintf('%s\n倍频检测通道性能分析:\n', accuracyText);
            accuracyText = sprintf('%s  最佳通道: 通道%d (%.2f%%)\n', accuracyText, bestChannelHarmonic, bestAccuracyHarmonic);
            accuracyText = sprintf('%s  最差通道: 通道%d (%.2f%%)\n', accuracyText, worstChannelHarmonic, worstAccuracyHarmonic);
            accuracyText = sprintf('%s  准确率范围: %.2f%% ~ %.2f%%\n', accuracyText, worstAccuracyHarmonic, bestAccuracyHarmonic);

            % 方法性能比较
            accuracyText = sprintf('%s\n方法性能比较:\n', accuracyText);
            accuracyText = sprintf('%s  基频检测-各通道独立: %.2f%% (160次检测)\n', accuracyText, overallAccuracyBase);
            accuracyText = sprintf('%s  倍频检测-各通道独立: %.2f%% (160次检测)\n', accuracyText, overallAccuracyHarmonic);
            accuracyText = sprintf('%s  基频投票分类: %.2f%% (20次检测)\n', accuracyText, app.JointAccuracy);
            accuracyText = sprintf('%s  基倍频联合投票分类: %.2f%% (20次检测)\n\n', accuracyText, app.HarmonicAccuracy);

            % 性能提升分析
            if app.HarmonicAccuracy > app.JointAccuracy
                improvement = app.HarmonicAccuracy - app.JointAccuracy;
                accuracyText = sprintf('%s性能提升: 基倍频联合投票分类比基频投票分类高 %.2f%%\n', accuracyText, improvement);
            elseif app.HarmonicAccuracy < app.JointAccuracy
                decline = app.JointAccuracy - app.HarmonicAccuracy;
                accuracyText = sprintf('%s性能下降: 基倍频联合投票分类比基频投票分类低 %.2f%%\n', accuracyText, decline);
            else
                accuracyText = sprintf('%s性能相同: 两种投票方法准确率相同\n', accuracyText);
            end

            app.AccuracyTextArea.Value = accuracyText;
        end

        
        function PlotAmpFreqz(app)
                % 幅频响应
                % 显示低通滤波器响应
                plot(app.LowpassResponseAxes, app.combinedF, 20*log10(abs(app.lpH)), 'b', 'LineWidth', 2);
                app.LowpassResponseAxes.Title.String = sprintf('低通滤波器');
                app.LowpassResponseAxes.XLabel.String = '频率/Hz';
                app.LowpassResponseAxes.YLabel.String = '幅度/dB';
                app.LowpassResponseAxes.XGrid = 'on';
                app.LowpassResponseAxes.YGrid = 'on';
                xlim(app.LowpassResponseAxes, [0 100]);
                ylim(app.LowpassResponseAxes, 'auto')
                yticks(app.LowpassResponseAxes, 'auto')
                yticklabels(app.LowpassResponseAxes, 'auto')

                % 显示高通滤波器响应
                plot(app.HighpassResponseAxes, app.combinedF, 20*log10(abs(app.hpH)), 'b', 'LineWidth', 2);
                app.HighpassResponseAxes.Title.String = sprintf('高通滤波器');
                app.HighpassResponseAxes.XLabel.String = '频率/Hz';
                app.HighpassResponseAxes.YLabel.String = '幅度/dB';
                app.HighpassResponseAxes.XGrid = 'on';
                app.HighpassResponseAxes.YGrid = 'on';
                xlim(app.HighpassResponseAxes, [0 100]);
                ylim(app.HighpassResponseAxes, 'auto')
                yticks(app.HighpassResponseAxes, 'auto')
                yticklabels(app.HighpassResponseAxes, 'auto')

                % 显示组合滤波器响应
                plot(app.CombinedResponseAxes, app.combinedF, 20*log10(abs(app.combinedH)), 'b', 'LineWidth', 2);
                app.CombinedResponseAxes.XLabel.String = '频率/Hz';
                app.CombinedResponseAxes.YLabel.String = '幅度/dB';
                app.CombinedResponseAxes.XGrid = 'on';
                app.CombinedResponseAxes.YGrid = 'on';
                xlim(app.CombinedResponseAxes, [0 100]);       
                ylim(app.CombinedResponseAxes, 'auto')
                yticks(app.CombinedResponseAxes, 'auto')
                yticklabels(app.CombinedResponseAxes, 'auto')
        end
        function PlotPhasedFreqz(app)
                % 相频响应
                % 显示低通滤波器响应
                plot(app.LowpassResponseAxes, app.combinedF, angle(app.lpH), 'b', 'LineWidth', 2);
                app.LowpassResponseAxes.Title.String = sprintf('低通滤波器');
                app.LowpassResponseAxes.XLabel.String = '频率/Hz';
                app.LowpassResponseAxes.YLabel.String = '相位/rad';
                app.LowpassResponseAxes.XGrid = 'on';
                app.LowpassResponseAxes.YGrid = 'on';
                xlim(app.LowpassResponseAxes, [0 100]);
                ylim(app.LowpassResponseAxes, [-pi pi]);
                yticks(app.LowpassResponseAxes, -pi:pi/2:pi);
                yticklabels(app.LowpassResponseAxes, {'-\pi','-\pi/2','0','\pi/2','\pi'});

                % 显示高通滤波器响应
                plot(app.HighpassResponseAxes, app.combinedF, angle(app.hpH), 'b', 'LineWidth', 2);
                app.HighpassResponseAxes.Title.String = sprintf('高通滤波器');
                app.HighpassResponseAxes.XLabel.String = '频率/Hz';
                app.HighpassResponseAxes.YLabel.String = '相位/rad';
                app.HighpassResponseAxes.XGrid = 'on';
                app.HighpassResponseAxes.YGrid = 'on';
                xlim(app.HighpassResponseAxes, [0 100]);
                ylim(app.HighpassResponseAxes, [-pi pi]);
                yticks(app.HighpassResponseAxes, -pi:pi/2:pi);
                yticklabels(app.HighpassResponseAxes, {'-\pi','-\pi/2','0','\pi/2','\pi'});

                % 显示组合滤波器响应
                plot(app.CombinedResponseAxes, app.combinedF, angle(app.combinedH), 'b', 'LineWidth', 2);
                app.CombinedResponseAxes.XLabel.String = '频率/Hz';
                app.CombinedResponseAxes.YLabel.String = '相位/rad';
                app.CombinedResponseAxes.XGrid = 'on';
                app.CombinedResponseAxes.YGrid = 'on';
                xlim(app.CombinedResponseAxes, [0 100]);
                ylim(app.CombinedResponseAxes, [-pi pi]);
                yticks(app.CombinedResponseAxes, -pi:pi/2:pi);
                yticklabels(app.CombinedResponseAxes, {'-\pi','-\pi/2','0','\pi/2','\pi'});
                
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % 加载数据函数 - 一次性导入两个文件
            try
                app.StatusLabel.Text = '正在加载数据...';
                app.ProgressLamp.Color = 'yellow';
                drawnow;

                % 选择文件夹路径
                folderPath = uigetdir('', '选择包含eeg.mat和label.mat文件的文件夹');
                if folderPath == 0
                    app.StatusLabel.Text = '数据加载已取消';
                    app.ProgressLamp.Color = [0.8 0.8 0.8];
                    return;
                end

                % 自动查找eeg.mat文件
                eegFiles = dir(fullfile(folderPath, 'eeg.mat'));
                if isempty(eegFiles)
                    uialert(app.UIFigure, '在选择的文件夹中未找到eeg.mat文件', '文件未找到');
                    return;
                end

                % 加载脑电数据
                eegData = load(fullfile(folderPath, 'eeg.mat'));

                % 检查数据格式
                if ~isfield(eegData, 'x')
                    uialert(app.UIFigure, 'eeg.mat文件必须包含名为"x"的变量', '数据格式错误');
                    return;
                end

                app.RawData = eegData.x;

                % 检查维度
                if ndims(app.RawData) ~= 3
                    uialert(app.UIFigure, '数据应为三维数组(通道×采样点×试次)', '维度错误');
                    return;
                end

                % 自动查找label.mat文件
                labelFiles = dir(fullfile(folderPath, 'label.mat'));
                if ~isempty(labelFiles)
                    % 加载标签文件
                    labelData = load(fullfile(folderPath, 'label.mat'));
                    app.Labels = labelData.x_label;
                else
                    % 如果没有标签文件，创建默认标签
                    app.Labels = repmat([1:10, 1:10], 1)';
                end

                app.Labels = app.Labels(:);

                % 更新数据显示
                [numChannels, numSamples, numTrials] = size(app.RawData);
                app.DataInfoLabel.Text = sprintf('数据已加载: %d通道 × %d采样点 × %d试次', ...
                    numChannels, numSamples, numTrials);

                % 更新通道选择器
                app.ChannelSelectorDropDown.Items = arrayfun(@(x) sprintf('通道 %d', x), 1:numChannels, 'UniformOutput', false);
                app.ChannelSelectorDropDown.Value = '通道 1';

                % 更新试次选择器
                app.TrialSelectorDropDown.Items = arrayfun(@(x) sprintf('试次 %d', x), 1:numTrials, 'UniformOutput', false);
                app.TrialSelectorDropDown.Value = '试次 1';

                % 更新刺激频率显示
                freqText = sprintf('目标1: %.1f Hz\n目标2: %.1f Hz\n目标3: %.1f Hz\n目标4: %.1f Hz\n目标5: %.1f Hz\n目标6: %.1f Hz\n目标7: %.1f Hz\n目标8: %.1f Hz\n目标9: %.1f Hz\n目标10: %.1f Hz', ...
                    app.StimFreqs);
                app.StimulusFrequenciesText.Value = freqText;

                app.StatusLabel.Text = '数据加载完成';
                app.ProgressLamp.Color = 'green';

            catch ME
                uialert(app.UIFigure, ME.message, '数据加载错误');
                app.StatusLabel.Text = '数据加载失败';
                app.ProgressLamp.Color = 'red';
            end
        end

        % Button pushed function: DesignFilterButton
        function DesignFilterButtonPushed(app, event)
            % 设计巴特沃斯IIR滤波器（先低通后高通）
            try
                app.StatusLabel.Text = '正在设计滤波器...';
                app.ProgressLamp.Color = 'yellow';
                drawnow;

                % 获取滤波器参数 - 分别设置低通和高通参数
                app.LowpassCutoff = app.LowpassCutoffEditField.Value;
                app.LowpassPassRipple = app.LowpassPassRippleEditField.Value;
                app.LowpassStopAtten = app.LowpassStopAttenEditField.Value;

                app.HighpassCutoff = app.HighpassCutoffEditField.Value;
                app.HighpassPassRipple = app.HighpassPassRippleEditField.Value;
                app.HighpassStopAtten = app.HighpassStopAttenEditField.Value;

                % 归一化频率
                nyquist = app.Fs / 2;
                lpWp = app.LowpassCutoff / nyquist;
                lpWs = (app.LowpassCutoff + 5) / nyquist; % 阻带频率比通带高5Hz

                hpWp = app.HighpassCutoff / nyquist;
                hpWs = (app.HighpassCutoff - 1) / nyquist; % 阻带频率比通带低1Hz

                % 设计低通滤波器
                [lpN, lpWn] = buttord(lpWp, lpWs, app.LowpassPassRipple, app.LowpassStopAtten);
                [lpB, lpA] = butter(lpN, lpWn, 'low');

                % 设计高通滤波器
                [hpN, hpWn] = buttord(hpWp, hpWs, app.HighpassPassRipple, app.HighpassStopAtten);
                [hpB, hpA] = butter(hpN, hpWn, 'high');

                % 保存滤波器系数
                app.lpCoeff.b = lpB;
                app.lpCoeff.a = lpA;
                app.lpCoeff.order = lpN;

                app.hpCoeff.b = hpB;
                app.hpCoeff.a = hpA;
                app.hpCoeff.order = hpN;

                % 计算频率响应
                N = 1024;
                [app.lpH, lpF] = freqz(lpB, lpA, N, app.Fs);
                [app.hpH, ~] = freqz(hpB, hpA, N, app.Fs);
                app.combinedF = lpF;

                % 计算组合响应
                app.combinedH = app.lpH .* app.hpH;

                % 绘制频率响应
                switch app.FreqzDropDown.Value
                    case '幅频响应'
                        PlotAmpFreqz(app)
                    case '相频响应'
                        PlotPhasedFreqz(app)
                    otherwise
                        PlotAmpFreqz(app)
                end

                % 显示滤波器阶数信息
                app.FilterOrderInfoLabel.Text = sprintf('滤波器阶数 - 低通: %d ,高通: %d', lpN ,hpN);
                app.StatusLabel.Text = '滤波器设计完成';
                app.ProgressLamp.Color = 'green';

            catch ME
                uialert(app.UIFigure, ME.message, '滤波器设计错误');
                app.StatusLabel.Text = '滤波器设计失败';
                app.ProgressLamp.Color = 'red';
            end
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            % 处理单个试次的频谱分析，同时显示时域波形
            try
                if isempty(app.RawData)
                    uialert(app.UIFigure, '请先加载数据', '数据未加载');
                    return;
                end

                if isempty(app.lpCoeff) || isempty(app.hpCoeff)
                    uialert(app.UIFigure, '请先设计滤波器', '滤波器未设计');
                    return;
                end

                app.StatusLabel.Text = '正在进行频谱分析...';
                app.ProgressLamp.Color = 'yellow';
                drawnow;

                % 获取选中的通道和试次
                selectedChannel = str2double(app.ChannelSelectorDropDown.Value(end));
                if length(app.TrialSelectorDropDown.Value) == 3
                    selectedTrial = str2double(app.TrialSelectorDropDown.Value(end));
                else
                    selectedTrial = str2double(app.TrialSelectorDropDown.Value(end-1:end));
                end

                % 提取原始信号
                rawSignal = squeeze(app.RawData(selectedChannel, :, selectedTrial));

                % 应用级联滤波
                filteredSignal = app.applyCascadeFilter(rawSignal);

                % 保存滤波后数据
                app.FilteredData(selectedChannel, :, selectedTrial) = filteredSignal;

                % 创建时间轴
                timeAxis = (0:length(rawSignal)-1) / app.Fs;

                % ===== 显示时域波形 =====
                plot(app.TimeDomainAxes, timeAxis, filteredSignal, 'b', 'LineWidth', 1.5);
                app.TimeDomainAxes.Title.String = sprintf('试次 %d - 通道 %d 时域波形', ...
                    selectedTrial, selectedChannel);
                app.TimeDomainAxes.XLabel.String = '时间 (s)';
                app.TimeDomainAxes.YLabel.String = '幅度';
                app.TimeDomainAxes.XGrid = 'on';
                app.TimeDomainAxes.YGrid = 'on';
                legend(app.TimeDomainAxes, '滤波后信号', 'Location', 'northeast');

                % 计算FFT频谱
                [spectrum, freq] = app.computeFFTSpectrum(filteredSignal);

                % ===== 基频检测 =====
                [baseTargetIdx, basePeakFreq] = app.detectFundamentalFrequency(spectrum, freq);

                % ===== 倍频检测 =====
                if app.ShowHarmonicCheckBox.Value && basePeakFreq > 0
                    [harmonicTargetIdx, harmonicPeakFreq] = app.detectHarmonicFrequency(spectrum, freq, spectrum(freq == basePeakFreq));
                end

                % 显示频谱图
                plot(app.SpectrumAxes, freq, spectrum, 'b', 'LineWidth', 1.5);
                hold(app.SpectrumAxes, 'on');

                % 标记7-15Hz范围
                fill(app.SpectrumAxes, [7 15 15 7], [0 0 max(spectrum)*1.1 max(spectrum)*1.1], ...
                    'y', 'FaceAlpha', 0.2, 'EdgeColor', 'none');

                if basePeakFreq > 0
                    % 标记基频峰值
                    plot(app.SpectrumAxes, basePeakFreq, spectrum(freq == basePeakFreq), 'ro', ...s
                        'MarkerSize', 5, 'LineWidth', 2);
                    text(app.SpectrumAxes, basePeakFreq, spectrum(freq == basePeakFreq)*1.05, ...
                        sprintf('基频: %.1f Hz', basePeakFreq), ...
                        'HorizontalAlignment', 'center', 'FontSize', 10);
                    % 保存峰值频率
                    app.PeakFrequencies(selectedTrial, selectedChannel) = basePeakFreq;

                    % 显示基频检测信息
                    peakText = sprintf('基频检测:\n主峰值频率: %.2f Hz\n最接近刺激频率: %.1f Hz (目标%d)\n频率差: %.2f Hz\n', ...
                        basePeakFreq, app.StimFreqs(baseTargetIdx), baseTargetIdx, abs(basePeakFreq - app.StimFreqs(baseTargetIdx)));

                    % 显示倍频检测信息
                    if app.ShowHarmonicCheckBox.Value
                        % 标记15-30Hz范围
                        fill(app.SpectrumAxes, [15 30 30 15], [0 0 max(spectrum)*1.1 max(spectrum)*1.1], ...
                            'b', 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                        if harmonicPeakFreq > 0
                            % 标记倍频峰值
                            plot(app.SpectrumAxes, harmonicPeakFreq, spectrum(freq == harmonicPeakFreq), 'go', ...
                                'MarkerSize', 5, 'LineWidth', 2);
                            text(app.SpectrumAxes, harmonicPeakFreq, spectrum(freq == harmonicPeakFreq)*1.05, ...
                                sprintf('2倍频: %.1f Hz', harmonicPeakFreq), ...
                                'HorizontalAlignment', 'center', 'FontSize', 10);

                            peakText = sprintf('%s\n倍频检测:\n2倍频峰值: %.2f Hz -> 基频%.2f Hz -> 目标%d (%.1f Hz)\n', ...
                                peakText, harmonicPeakFreq, harmonicPeakFreq/2, harmonicTargetIdx, app.StimFreqs(harmonicTargetIdx));
                        else
                            peakText = sprintf('%s\n倍频检测:\n在15-30Hz范围内未检测到有效峰值\n', peakText);
                        end
                    end
                else
                    peakText = '在7-15Hz范围内未检测到明显峰值';
                end

                app.PeakFrequencyText.Value = peakText;

                % 设置图形属性
                app.SpectrumAxes.Title.String = sprintf('试次 %d - 通道 %d 频谱图', ...
                    selectedTrial, selectedChannel);
                app.SpectrumAxes.XLabel.String = '频率 (Hz)';
                app.SpectrumAxes.YLabel.String = '幅度';
                app.SpectrumAxes.XGrid = 'on';
                app.SpectrumAxes.YGrid = 'on';
                xlim(app.SpectrumAxes, [0 50]);

                % 设置图例
                if app.ShowHarmonicCheckBox.Value && harmonicPeakFreq > 0
                    legend(app.SpectrumAxes, {'频谱', '7-15Hz范围', '基频峰值', '2倍频峰值', '15-30Hz范围'}, 'Location', 'northeast');
                else
                    legend(app.SpectrumAxes, {'频谱', '7-15Hz范围', '基频峰值'}, 'Location', 'northeast');
                end

                hold(app.SpectrumAxes, 'off');

                app.StatusLabel.Text = sprintf('频谱分析完成 - 试次 %d, 通道 %d', ...
                    selectedTrial, selectedChannel);
                app.ProgressLamp.Color = 'green';

            catch ME
                uialert(app.UIFigure, ME.message, '频谱分析错误');
                app.StatusLabel.Text = '频谱分析失败';
                app.ProgressLamp.Color = 'red';
            end
        end

        % Button pushed function: RunClassificationButton
        function RunClassificationButtonPushed(app, event)
            % 对所有试次进行分类
            try
                if isempty(app.RawData)
                    uialert(app.UIFigure, '请先加载数据', '数据未加载');
                    return;
                end

                if isempty(app.lpCoeff) || isempty(app.hpCoeff)
                    uialert(app.UIFigure, '请先设计滤波器', '滤波器未设计');
                    return;
                end

                app.StatusLabel.Text = '正在进行目标分类...';
                app.ProgressLamp.Color = 'yellow';
                drawnow;

                % 获取数据维度
                [numChannels, ~, numTrials] = size(app.RawData);

                % 初始化结果存储
                app.ChannelPredictions = zeros(numTrials, numChannels);
                app.ChannelHarmonicPredictions = zeros(numTrials, numChannels);
                app.JointPredictions = zeros(numTrials, 1);
                app.HarmonicPredictions = zeros(numTrials, 1);
                app.PeakFrequencies = zeros(numTrials, numChannels);
                app.HarmonicPeakFrequencies = zeros(numTrials, numChannels);

                % 处理每个试次
                for trial = 1:numTrials
                    channelVotes = zeros(1, length(app.StimFreqs));
                    channelVotesHarmonic = zeros(1, length(app.StimFreqs));

                    for channel = 1:numChannels
                        % 提取信号并滤波
                        signal = squeeze(app.RawData(channel, :, trial));
                        filteredSignal = app.applyCascadeFilter(signal);

                        % 计算FFT频谱
                        [spectrum, freq] = app.computeFFTSpectrum(filteredSignal);

                        % ===== 基频检测 =====
                        [baseTargetIdx, basePeakFreq] = app.detectFundamentalFrequency(spectrum, freq);

                        if basePeakFreq > 0
                            app.PeakFrequencies(trial, channel) = basePeakFreq;
                            app.ChannelPredictions(trial, channel) = baseTargetIdx;
                            channelVotes(baseTargetIdx) = channelVotes(baseTargetIdx) + 1;

                            % ===== 倍频检测 =====
                            [harmonicTargetIdx, harmonicPeakFreq] = app.detectHarmonicFrequency(spectrum, freq, spectrum(freq == basePeakFreq));

                            if harmonicPeakFreq > 0
                                app.HarmonicPeakFrequencies(trial, channel) = harmonicPeakFreq;
                                app.ChannelHarmonicPredictions(trial, channel) = harmonicTargetIdx;
                                channelVotesHarmonic(harmonicTargetIdx) = channelVotesHarmonic(harmonicTargetIdx) + 1;
                            else
                                app.ChannelHarmonicPredictions(trial, channel) = 0;
                            end
                        else
                            app.ChannelPredictions(trial, channel) = 0;
                            app.ChannelHarmonicPredictions(trial, channel) = 0;
                        end
                    end

                    % 基频检测-8通道联合投票
                    [~, app.JointPredictions(trial)] = max(channelVotes);

                    % 基频+倍频-16通道联合投票
                    combinedVotes = channelVotes + channelVotesHarmonic;
                    [~, app.HarmonicPredictions(trial)] = max(combinedVotes);
                end

                % 计算准确率
                app.ChannelAccuracies = zeros(1, numChannels);
                app.ChannelHarmonicAccuracies = zeros(1, numChannels);

                for channel = 1:numChannels
                    % 基频检测准确率
                    correctBase = sum(app.ChannelPredictions(:, channel) == app.Labels);
                    app.ChannelAccuracies(channel) = correctBase / numTrials * 100;

                    % 倍频检测准确率
                    correctHarmonic = sum(app.ChannelHarmonicPredictions(:, channel) == app.Labels);
                    app.ChannelHarmonicAccuracies(channel) = correctHarmonic / numTrials * 100;
                end

                app.JointAccuracy = sum(app.JointPredictions == app.Labels) / numTrials * 100;
                app.HarmonicAccuracy = sum(app.HarmonicPredictions == app.Labels) / numTrials * 100;

                % 显示结果
                app.updateResultsDisplay();

                app.StatusLabel.Text = sprintf('目标分类完成 - 总准确率: %.2f%%', app.JointAccuracy);
                app.ProgressLamp.Color = 'green';

            catch ME
                uialert(app.UIFigure, ME.message, '分类错误');
                app.StatusLabel.Text = '分类失败';
                app.ProgressLamp.Color = 'red';
            end
        end

        % Value changed function: FreqzDropDown
        function FreqzValueChanged(app, event)
            % 绘制频率响应
            switch app.FreqzDropDown.Value
                case '幅频响应'
                    PlotAmpFreqz(app)
                case '相频响应'
                    PlotPhasedFreqz(app)
                otherwise
                    PlotAmpFreqz(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '脑电SSVEP信号的目标分类';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataFilterTab = uitab(app.TabGroup);
            app.DataFilterTab.Title = '滤波器设计';
            app.LoadDataButton = uibutton(app.DataFilterTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = '加载数据';
            app.DataInfoLabel = uilabel(app.DataFilterTab);
            app.DataInfoLabel.Text = '未加载数据';
            app.FilterParametersPanel = uipanel(app.DataFilterTab);
            app.FilterParametersPanel.Title = '滤波器参数';
            app.LowpassParametersLabel = uilabel(app.FilterParametersPanel);
            app.LowpassParametersLabel.Text = '低通滤波器参数';
            app.LowpassPassRippleLabel = uilabel(app.FilterParametersPanel);
            app.LowpassPassRippleLabel.Text = '通带波纹/dB';
            app.LowpassPassRippleEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.LowpassPassRippleEditField.Limits = [0.1 10];
            app.LowpassPassRippleEditField.Value = 7;
            app.LowpassCutoffLabel = uilabel(app.FilterParametersPanel);
            app.LowpassCutoffLabel.Text = '截止频率/Hz';
            app.LowpassCutoffEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.LowpassCutoffEditField.Limits = [10 200];
            app.LowpassCutoffEditField.Value = 40;
            app.LowpassStopAttenLabel = uilabel(app.FilterParametersPanel);
            app.LowpassStopAttenLabel.Text = '阻带衰减/dB';
            app.LowpassStopAttenEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.LowpassStopAttenEditField.Limits = [5 100];
            app.LowpassStopAttenEditField.Value = 20;
            app.HighpassParametersLabel = uilabel(app.FilterParametersPanel);
            app.HighpassParametersLabel.Text = '高通滤波器参数';
            app.HighpassPassRippleLabel = uilabel(app.FilterParametersPanel);
            app.HighpassPassRippleLabel.Text = '通带波纹/dB';
            app.HighpassPassRippleEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.HighpassPassRippleEditField.Limits = [0.1 10];
            app.HighpassPassRippleEditField.Value = 7;
            app.HighpassCutoffLabel = uilabel(app.FilterParametersPanel);
            app.HighpassCutoffLabel.Text = '截止频率/Hz';
            app.HighpassCutoffEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.HighpassCutoffEditField.Limits = [0.1 20];
            app.HighpassCutoffEditField.Value = 5;
            app.HighpassStopAttenLabel = uilabel(app.FilterParametersPanel);
            app.HighpassStopAttenLabel.Text = '阻带衰减/dB';
            app.HighpassStopAttenEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.HighpassStopAttenEditField.Limits = [5 100];
            app.HighpassStopAttenEditField.Value = 20;
            app.DesignFilterButton = uibutton(app.FilterParametersPanel, 'push');
            app.DesignFilterButton.ButtonPushedFcn = createCallbackFcn(app, @DesignFilterButtonPushed, true);
            app.DesignFilterButton.Text = '设计滤波器';
            app.FilterResponsePanel = uipanel(app.DataFilterTab);
            app.FilterResponsePanel.Title = '滤波器频率响应';
            app.HighpassResponseAxes = uiaxes(app.FilterResponsePanel);
            title(app.HighpassResponseAxes, '高通滤波器响应')
            xlabel(app.HighpassResponseAxes, '频率/Hz')
            ylabel(app.HighpassResponseAxes, '幅度/dB')
            zlabel(app.HighpassResponseAxes, 'Z')
            app.LowpassResponseAxes = uiaxes(app.FilterResponsePanel);
            title(app.LowpassResponseAxes, '低通滤波器响应')
            xlabel(app.LowpassResponseAxes, '频率/Hz')
            ylabel(app.LowpassResponseAxes, '幅度/dB')
            zlabel(app.LowpassResponseAxes, 'Z')
            app.CombinedResponseAxes = uiaxes(app.FilterResponsePanel);
            title(app.CombinedResponseAxes, '组合滤波器响应')
            xlabel(app.CombinedResponseAxes, '频率/Hz')
            ylabel(app.CombinedResponseAxes, '幅度/dB')
            zlabel(app.CombinedResponseAxes, 'Z')
            app.FreqzLabel = uilabel(app.FilterResponsePanel);
            app.FreqzLabel.Text = '频率响应类型';
            app.FreqzDropDown = uidropdown(app.FilterResponsePanel);
            app.FreqzDropDown.Items = {'幅频响应', '相频响应'};
            app.FreqzDropDown.ValueChangedFcn = createCallbackFcn(app, @FreqzValueChanged, true);
            app.FreqzDropDown.Value = '幅频响应';
            app.FilterOrderInfoLabel = uilabel(app.DataFilterTab);
            app.FilterOrderInfoLabel.Text = '滤波器阶数: 未设计';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = '频谱分析';
            app.TimeDomainAxes = uiaxes(app.AnalysisTab);
            title(app.TimeDomainAxes, '时域波形')
            xlabel(app.TimeDomainAxes, '时间/s')
            ylabel(app.TimeDomainAxes, '幅度')
            zlabel(app.TimeDomainAxes, 'Z')
            app.SpectrumAxes = uiaxes(app.AnalysisTab);
            title(app.SpectrumAxes, '频谱图')
            xlabel(app.SpectrumAxes, '频率/Hz')
            ylabel(app.SpectrumAxes, '幅度')
            zlabel(app.SpectrumAxes, 'Z')
            app.ChannelSelectorLabel = uilabel(app.AnalysisTab);
            app.ChannelSelectorLabel.Text = '选择通道';
            app.ChannelSelectorDropDown = uidropdown(app.AnalysisTab);
            app.ChannelSelectorDropDown.Items = {'通道 1', '通道 2', '通道 3', '通道 4', '通道 5', '通道 6', '通道 7', '通道 8'};
            app.ChannelSelectorDropDown.Value = '通道 1';
            app.TrialSelectorLabel = uilabel(app.AnalysisTab);
            app.TrialSelectorLabel.Text = '选择试次';
            app.TrialSelectorDropDown = uidropdown(app.AnalysisTab);
            app.TrialSelectorDropDown.Items = {'试次 1', '试次 2', '试次 3', '试次 4', '试次 5', '试次 6', '试次 7', '试次 8', '试次 9', '试次 10', '试次 11', '试次 12', '试次 13', '试次 14', '试次 15', '试次 16', '试次 17', '试次 18', '试次 19', '试次 20'};
            app.TrialSelectorDropDown.Value = '试次 1';
            app.FFTLengthLabel = uilabel(app.AnalysisTab);
            app.FFTLengthLabel.Text = 'FFT长度';
            app.FFTEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.FFTEditField.Limits = [4096 65536];
            app.FFTEditField.RoundFractionalValues = 'on';
            app.FFTEditField.ValueDisplayFormat = '%.0f';
            app.FFTEditField.Value = 4096;
            app.WindowTypeLabel = uilabel(app.AnalysisTab);
            app.WindowTypeLabel.Text = '窗函数';
            app.WindowTypeDropDown = uidropdown(app.AnalysisTab);
            app.WindowTypeDropDown.Items = {'矩形窗', '汉宁窗 ', '汉明窗', '布莱克曼窗'};
            app.WindowTypeDropDown.Value = '汉明窗';
            app.ShowHarmonicCheckBox = uicheckbox(app.AnalysisTab);
            app.ShowHarmonicCheckBox.Text = '显示倍频检测';
            app.RunAnalysisButton = uibutton(app.AnalysisTab, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = '运行频谱分析';
            app.StimulusFrequenciesLabel = uilabel(app.AnalysisTab);
            app.StimulusFrequenciesLabel.Text = '刺激频率对应目标';
            app.StimulusFrequenciesText = uitextarea(app.AnalysisTab);
            app.StimulusFrequenciesText.Value = {'目标1: 8.0 Hz'; '目标2: 8.5 Hz'; '目标3: 9.0 Hz'; '目标4: 9.5 Hz'; '目标5: 10.0 Hz'; '目标6: 10.5 Hz '; '目标7: 11.0 Hz'; '目标8: 11.5 Hz'; '目标9: 12.0 Hz'; '目标10: 12.5 Hz'};
            app.PeakFrequencyLabel = uilabel(app.AnalysisTab);
            app.PeakFrequencyLabel.Text = '峰值频率分析';
            app.PeakFrequencyText = uitextarea(app.AnalysisTab);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = '目标分类';
            app.RunClassificationButton = uibutton(app.ResultsTab, 'push');
            app.RunClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @RunClassificationButtonPushed, true);
            app.RunClassificationButton.Text = '运行目标分类';
            app.ResultsTable = uitable(app.ResultsTab);
            app.ResultsTable.ColumnName = {'通道1'; '通道2'; '通道3'; '通道4'; '通道5'; '通道6'; '通道7'; '通道8'; '8通道联合'; '基倍频联合'; '真实目标'; '分类结果'};
            app.ResultsTable.RowName = {'试次1'; '试次2'; '试次3'; '试次4'; '试次5'; '试次6'; '试次7'; '试次8'; '试次9'; '试次10'; '试次11'; '试次12'; '试次13'; '试次14'; '试次15'; '试次16'; '试次17'; '试次18'; '试次19'; '试次20'};
            app.AccuracyLabel = uilabel(app.ResultsTab);
            app.AccuracyLabel.Text = '准确率统计：';
            app.AccuracyTextArea = uitextarea(app.ResultsTab);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = '就绪';
            app.ProgressLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end