classdef exp6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VRUIFigure          matlab.ui.Figure
        RightPanel          matlab.ui.container.Panel
        TestResultTable     matlab.ui.control.Table
        TestResultLabel     matlab.ui.control.Label
        CalibLabel          matlab.ui.control.Label
        CalibTable          matlab.ui.control.Table
        AccuracyCurveAxes   matlab.ui.control.UIAxes
        CalibAxes           matlab.ui.control.UIAxes
        LeftPanel           matlab.ui.container.Panel
        DeleteButton        matlab.ui.control.Button
        AccuracyTable       matlab.ui.control.Table
        AccuracyLabel       matlab.ui.control.Label
        StatusLabel         matlab.ui.control.Label
        ProcessButton       matlab.ui.control.Button
        TimePointsDropdown  matlab.ui.control.DropDown
        TimePointsLabel     matlab.ui.control.Label
        FilePathLabel       matlab.ui.control.Label
        LoadDataButton      matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
        % 数据属性
        data_dir                    char     % 数据目录
        left_pre_c                  double   % 校准阶段左眼数据 (10×2×40)
        right_pre_c                 double   % 校准阶段右眼数据 (10×2×40)
        left_pre_o                  double   % 测试阶段左眼数据 (20×2×40)
        right_pre_o                 double   % 测试阶段右眼数据 (20×2×40)
        labels                      double   % 标签数据 (20×1)

        % 计算结果
        calib_centers_left          double   % 左眼校准中心点 (10×2)
        calib_centers_right         double   % 右眼校准中心点 (10×2)
        results                     struct   % 分类结果结构体
    end

    methods (Access = private)

        function calculateCalibrationCenters(app)
            % 初始化中心点矩阵
            app.calib_centers_left = zeros(10, 2);
            app.calib_centers_right = zeros(10, 2);

            % 计算每个目标的平均坐标
            for i = 1:10
                % 左眼数据平均
                left_data = squeeze(app.left_pre_c(i, :, :));  % 2×40
                app.calib_centers_left(i, 1) = mean(left_data(1, :));  % X坐标
                app.calib_centers_left(i, 2) = mean(left_data(2, :));  % Y坐标

                % 右眼数据平均
                right_data = squeeze(app.right_pre_c(i, :, :));  % 2×40
                app.calib_centers_right(i, 1) = mean(right_data(1, :));  % X坐标
                app.calib_centers_right(i, 2) = mean(right_data(2, :));  % Y坐标
            end
        end

        function classifyTargets(app, time_points)
            num_trials = size(app.left_pre_o, 1);
            predictions_left = zeros(num_trials, 1);
            predictions_right = zeros(num_trials, 1);
            predictions_fused = zeros(num_trials, 1);

            % 对每个测试试次进行分类
            for trial = 1:num_trials
                % ========== 左眼分类 ==========
                left_votes = zeros(10, 1);
                left_data = squeeze(app.left_pre_o(trial, :, 1:time_points));  % 2×time_points

                for t = 1:time_points
                    point = left_data(:, t);  % 当前时间点的坐标
                    distances = zeros(10, 1);

                    % 计算与10个校准中心点的距离
                    for target = 1:10  % 目标编号1-10
                        distances(target) = sqrt(sum((point - app.calib_centers_left(target, :)').^2));
                    end

                    % 找到最小距离对应的目标
                    [~, min_idx] = min(distances);
                    left_votes(min_idx) = left_votes(min_idx) + 1;
                end

                % 投票最多的目标作为预测结果
                [~, predictions_left(trial)] = max(left_votes);

                % ========== 右眼分类 ==========
                right_votes = zeros(10, 1);
                right_data = squeeze(app.right_pre_o(trial, :, 1:time_points));  % 2×time_points

                for t = 1:time_points
                    point = right_data(:, t);
                    distances = zeros(10, 1);

                    for target = 1:10  % 目标编号1-10
                        distances(target) = sqrt(sum((point - app.calib_centers_right(target, :)').^2));
                    end

                    % 找到最小距离对应的目标
                    [~, min_idx] = min(distances);
                    right_votes(min_idx) = right_votes(min_idx) + 1;
                end

                [~, predictions_right(trial)] = max(right_votes);

                % ========== 双眼融合分类 ==========
                fused_votes = right_votes + left_votes;
                [~, predictions_fused(trial)] = max(fused_votes);
            end

            % 计算准确率
            accuracy_left = sum(predictions_left == app.labels) / num_trials * 100;
            accuracy_right = sum(predictions_right == app.labels) / num_trials * 100;
            accuracy_fused = sum(predictions_fused == app.labels) / num_trials * 100;

            % 保存结果
            app.results = struct(...
                'predictions_left', predictions_left, ...
                'predictions_right', predictions_right, ...
                'predictions_fused', predictions_fused, ...
                'accuracy_left', accuracy_left, ...
                'accuracy_right', accuracy_right, ...
                'accuracy_fused', accuracy_fused, ...
                'time_points', time_points);
        end

        function [time_points_list, acc_left, acc_right, acc_fused] = calculateAccuracyCurve(app)
            time_points_list = [10, 20, 30, 40];
            acc_left = zeros(4, 1);
            acc_right = zeros(4, 1);
            acc_fused = zeros(4, 1);

            % 显示进度
            app.StatusLabel.Text = '正在计算不同时间点的准确率...';
            drawnow;

            for i = 1:4
                classifyTargets(app, time_points_list(i));
                acc_left(i) = app.results.accuracy_left;
                acc_right(i) = app.results.accuracy_right;
                acc_fused(i) = app.results.accuracy_fused;
            end

            % 计算完成后更新状态
            app.StatusLabel.Text = '准确率计算完成！';
        end

        function updateCalibrationDisplay(app)
            % 清除坐标轴
            cla(app.CalibAxes);

            % 在坐标轴上显示校准中心点
            scatter(app.CalibAxes, ...
                app.calib_centers_left(:,1), app.calib_centers_left(:,2), ...
                15, 'ro', 'filled', 'DisplayName', '左眼');
            hold(app.CalibAxes, 'on');
            scatter(app.CalibAxes, ...
                app.calib_centers_right(:,1), app.calib_centers_right(:,2), ...
                15, 'bs', 'filled', 'DisplayName', '右眼');

            % 添加目标编号标签（1-10）
            for i = 1:10
                text(app.CalibAxes, app.calib_centers_left(i,1), ...
                    app.calib_centers_left(i,2), sprintf('%d', i), ...
                    'HorizontalAlignment', 'left', 'VerticalAlignment', 'top', ...
                    'FontWeight', 'bold', 'Color', 'r');
                text(app.CalibAxes, app.calib_centers_right(i,1), ...
                    app.calib_centers_right(i,2), sprintf('%d', i), ...
                    'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom', ...
                    'FontWeight', 'bold', 'Color', 'b');
            end

            hold(app.CalibAxes, 'off');

            % 设置坐标轴属性
            legend(app.CalibAxes, 'Location', 'northeast');
            xlabel(app.CalibAxes, 'X坐标');
            ylabel(app.CalibAxes, 'Y坐标');
            title(app.CalibAxes, '左右眼10个目标校准点坐标');
            grid(app.CalibAxes, 'on');

            % 更新校准表格数据
            table_data = cell(10, 5);
            for i = 1:10
                table_data{i, 1} = sprintf('目标%d', i);
                table_data{i, 2} = sprintf('%.2f', app.calib_centers_left(i, 1));
                table_data{i, 3} = sprintf('%.2f', app.calib_centers_left(i, 2));
                table_data{i, 4} = sprintf('%.2f', app.calib_centers_right(i, 1));
                table_data{i, 5} = sprintf('%.2f', app.calib_centers_right(i, 2));
            end
            app.CalibTable.Data = table_data;
        end

        function updateResultsDisplay(app)
            % 更新准确率表格
            accuracy_data = {
                '左眼分类', sprintf('%.2f%%', app.results.accuracy_left);
                '右眼分类', sprintf('%.2f%%', app.results.accuracy_right);
                '双眼融合分类', sprintf('%.2f%%', app.results.accuracy_fused)
                };
            app.AccuracyTable.Data = accuracy_data;

            % 更新测试结果表格
            test_data = cell(20, 5);
            for i = 1:20
                test_data{i, 1} = sprintf('试次%d', i);
                test_data{i, 2} = sprintf('目标%d', app.labels(i));
                test_data{i, 3} = sprintf('目标%d', app.results.predictions_left(i));
                test_data{i, 4} = sprintf('目标%d', app.results.predictions_right(i));
                test_data{i, 5} = sprintf('目标%d', app.results.predictions_fused(i));

                % 高亮显示正确的结果
                if app.results.predictions_left(i) == app.labels(i)
                    test_data{i, 3} = [test_data{i, 3}, ' ✓'];
                else
                    test_data{i, 3} = [test_data{i, 3}, ' ✗'];
                end
                if app.results.predictions_right(i) == app.labels(i)
                    test_data{i, 4} = [test_data{i, 4}, ' ✓'];
                else
                    test_data{i, 3} = [test_data{i, 3}, ' ✗'];
                end
                if app.results.predictions_fused(i) == app.labels(i)
                    test_data{i, 5} = [test_data{i, 5}, ' ✓'];
                else
                    test_data{i, 3} = [test_data{i, 3}, ' ✗'];
                end
            end
            app.TestResultTable.Data = test_data;
        end

        function updateAccuracyCurve(app)
            % 计算不同时间点下的准确率
            [time_points_list, acc_left, acc_right, acc_fused] = calculateAccuracyCurve(app);

            % 清除坐标轴
            cla(app.AccuracyCurveAxes);

            % 绘制曲线
            plot(app.AccuracyCurveAxes, time_points_list, acc_left, ...
                'ro-', 'LineWidth', 2, 'MarkerSize', 3, 'DisplayName', '左眼分类');
            hold(app.AccuracyCurveAxes, 'on');
            plot(app.AccuracyCurveAxes, time_points_list, acc_right, ...
                'bs-', 'LineWidth', 2, 'MarkerSize', 3, 'DisplayName', '右眼分类');
            plot(app.AccuracyCurveAxes, time_points_list, acc_fused, ...
                'g^-', 'LineWidth', 2, 'MarkerSize', 3, 'DisplayName', '双眼融合分类');
            hold(app.AccuracyCurveAxes, 'off');

            % 设置坐标轴属性
            xlabel(app.AccuracyCurveAxes, '时间点数');
            ylabel(app.AccuracyCurveAxes, '准确率/%');
            title(app.AccuracyCurveAxes, '不同时间点数下的分类准确率');
            legend(app.AccuracyCurveAxes, 'Location', 'best');
            grid(app.AccuracyCurveAxes, 'on');
            xlim(app.AccuracyCurveAxes, [5, 45]);
            ylim(app.AccuracyCurveAxes, [0, 100]);

            % 添加准确率数值标签
            for i = 1:4
                text(app.AccuracyCurveAxes, time_points_list(i), acc_left(i), ...
                    sprintf('%.1f%%', acc_left(i)), 'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'bottom', 'Color', 'r', 'FontWeight', 'bold');
                text(app.AccuracyCurveAxes, time_points_list(i), acc_right(i), ...
                    sprintf('%.1f%%', acc_right(i)), 'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'top', 'Color', 'b', 'FontWeight', 'bold');
                text(app.AccuracyCurveAxes, time_points_list(i), acc_fused(i), ...
                    sprintf('%.1f%%', acc_fused(i)), 'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'bottom', 'Color', 'g', 'FontWeight', 'bold');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            try
                % 显示状态
                app.StatusLabel.Text = '正在选择数据目录...';
                drawnow;

                % 选择数据目录
                app.data_dir = uigetdir(pwd, '选择数据文件所在目录');
                if app.data_dir == 0
                    app.StatusLabel.Text = '用户取消选择目录';
                    return;
                end

                % 显示状态
                app.StatusLabel.Text = '正在加载数据...';
                app.FilePathLabel.Text = ['数据目录: ', app.data_dir];
                drawnow;

                % 加载calib_data.mat
                calib_file = fullfile(app.data_dir, 'calib_data.mat');
                if exist(calib_file, 'file')
                    calib_data = load(calib_file);

                    % 注意变量名是Left_pre_c和Right_pre_c
                    if isfield(calib_data, 'Left_pre_c')
                        app.left_pre_c = calib_data.Left_pre_c;
                    elseif isfield(calib_data, 'left_pre_c')
                        app.left_pre_c = calib_data.left_pre_c;
                    else
                        error('calib_data.mat中找不到左眼校准数据');
                    end

                    if isfield(calib_data, 'Right_pre_c')
                        app.right_pre_c = calib_data.Right_pre_c;
                    elseif isfield(calib_data, 'right_pre_c')
                        app.right_pre_c = calib_data.right_pre_c;
                    else
                        error('calib_data.mat中找不到右眼校准数据');
                    end
                else
                    error('找不到 calib_data.mat 文件');
                end

                % 加载online.mat
                online_file = fullfile(app.data_dir, 'online.mat');
                if exist(online_file, 'file')
                    online_data = load(online_file);

                    % 注意变量名是Left_pre_o和Right_pre_o
                    if isfield(online_data, 'Left_pre_o')
                        app.left_pre_o = online_data.Left_pre_o;
                    elseif isfield(online_data, 'left_pre_o')
                        app.left_pre_o = online_data.left_pre_o;
                    else
                        error('online.mat中找不到左眼测试数据');
                    end

                    if isfield(online_data, 'Right_pre_o')
                        app.right_pre_o = online_data.Right_pre_o;
                    elseif isfield(online_data, 'right_pre_o')
                        app.right_pre_o = online_data.right_pre_o;
                    else
                        error('online.mat中找不到右眼测试数据');
                    end
                else
                    error('找不到 online.mat 文件');
                end

                % 加载label.mat
                label_file = fullfile(app.data_dir, 'label.mat');
                if exist(label_file, 'file')
                    label_data = load(label_file);

                    % 注意变量名是label
                    if isfield(label_data, 'label')
                        label_matrix = label_data.label;  % 10×2的标签矩阵

                        % 将10×2的标签矩阵转换为20×1的向量（按列顺序）
                        % 第一列：前10个试次，第二列：后10个试次
                        app.labels = label_matrix(:);  % 按列展开

                        % 确保标签在1-10范围内
                        if min(app.labels) < 1 || max(app.labels) > 10
                            error('标签值超出1-10范围');
                        end
                    else
                        error('label.mat中找不到label变量');
                    end
                else
                    error('找不到 label.mat 文件');
                end

                % 验证数据维度
                if ~isequal(size(app.left_pre_c), [10, 2, 40])
                    error('left_pre_c 维度应为 10×2×40，实际维度为 %s', mat2str(size(app.left_pre_c)));
                end
                if ~isequal(size(app.right_pre_c), [10, 2, 40])
                    error('right_pre_c 维度应为 10×2×40，实际维度为 %s', mat2str(size(app.right_pre_c)));
                end
                if ~isequal(size(app.left_pre_o), [20, 2, 40])
                    error('left_pre_o 维度应为 20×2×40，实际维度为 %s', mat2str(size(app.left_pre_o)));
                end
                if ~isequal(size(app.right_pre_o), [20, 2, 40])
                    error('right_pre_o 维度应为 20×2×40，实际维度为 %s', mat2str(size(app.right_pre_o)));
                end
                if ~isequal(size(label_matrix), [10, 2])
                    error('label 矩阵维度应为 10×2，实际维度为 %s', mat2str(size(label_matrix)));
                end

                % 计算校准中心点
                calculateCalibrationCenters(app);

                % 更新显示
                updateCalibrationDisplay(app);

                % 启用处理按钮
                app.ProcessButton.Enable = 'on';

                % 更新状态
                app.StatusLabel.Text = '数据加载成功！请选择时间点数并点击"开始处理"';

            catch ME
                % 更新状态
                app.StatusLabel.Text = '数据加载失败！';

                % 显示错误消息
                uialert(app.VRUIFigure, sprintf('数据加载失败: %s', ME.message), '错误', 'Icon', 'error');
                return;
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % 获取选择的时间点数
            time_points = str2double(app.TimePointsDropdown.Value);

            % 显示处理中消息
            d = uiprogressdlg(app.VRUIFigure, 'Title', '处理中', ...
                'Message', '正在进行目标分类处理...', 'Indeterminate', 'on');

            try
                % 进行分类
                classifyTargets(app, time_points);

                % 更新显示
                updateResultsDisplay(app);

                % 更新准确率曲线
                updateAccuracyCurve(app);

                % 关闭进度对话框
                close(d);

            catch ME
                close(d);
                uialert(app.VRUIFigure, sprintf('处理失败: %s', ME.message), '错误', 'Icon', 'error');
                return;
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VRUIFigure = uifigure('Visible', 'off');
            app.VRUIFigure.Name = 'VR眼动数据的目标识别 ';
            app.LeftPanel = uipanel(app.VRUIFigure);
            app.LeftPanel.Title = '控制面板';
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = '加载数据';
            app.FilePathLabel = uilabel(app.LeftPanel);
            app.FilePathLabel.Text = '数据目录: 未选择';
            app.TimePointsLabel = uilabel(app.LeftPanel);
            app.TimePointsLabel.Text = '选择时间点数:';
            app.TimePointsDropdown = uidropdown(app.LeftPanel);
            app.TimePointsDropdown.Items = {'10', '20', '30', '40'};
            app.TimePointsDropdown.Value = '40';
            app.ProcessButton = uibutton(app.LeftPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = '开始处理';
            app.StatusLabel = uilabel(app.LeftPanel);
            app.StatusLabel.Text = '请点击"加载数据"按钮';
            app.AccuracyLabel = uilabel(app.LeftPanel);
            app.AccuracyLabel.Text = '分类准确率:';
            app.AccuracyTable = uitable(app.LeftPanel);
            app.AccuracyTable.ColumnName = {'分类方法'; '准确率/%'};
            app.AccuracyTable.RowName = {};
            app.DeleteButton = uibutton(app.LeftPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = '关闭界面';
            app.RightPanel = uipanel(app.VRUIFigure);
            app.RightPanel.Title = '结果显示';
            app.CalibAxes = uiaxes(app.RightPanel);
            title(app.CalibAxes, '左右眼10个目标校准点坐标')
            xlabel(app.CalibAxes, 'X坐标')
            ylabel(app.CalibAxes, 'Y坐标')
            zlabel(app.CalibAxes, 'Z')
            app.AccuracyCurveAxes = uiaxes(app.RightPanel);
            title(app.AccuracyCurveAxes, '不同时间点数下的分类准确率')
            xlabel(app.AccuracyCurveAxes, '时间点数')
            ylabel(app.AccuracyCurveAxes, '准确率/%')
            zlabel(app.AccuracyCurveAxes, 'Z')
            app.CalibTable = uitable(app.RightPanel);
            app.CalibTable.ColumnName = {'目标'; '左眼X'; '左眼Y'; '右眼X'; '右眼Y'};
            app.CalibTable.RowName = {};
            app.CalibLabel = uilabel(app.RightPanel);
            app.CalibLabel.Text = '左右眼10个目标校准点坐标：';
            app.TestResultLabel = uilabel(app.RightPanel);
            app.TestResultLabel.Text = '分类结果 (20个试次):';
            app.TestResultTable = uitable(app.RightPanel);
            app.TestResultTable.ColumnName = {'试次'; '真实标签'; '左眼分类'; '右眼分类'; '融合分类'};
            app.TestResultTable.RowName = {};
            % Show the figure after all components are created
            app.VRUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VRUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VRUIFigure)
        end
    end
end