classdef exp7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ERPUIFigure                  matlab.ui.Figure
        ProgressLamp                 matlab.ui.control.Lamp
        StatusLabel                  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        FilterDesignTab              matlab.ui.container.Tab
        FilterResponsePanel          matlab.ui.container.Panel
        FreqzDropDown                matlab.ui.control.DropDown
        FreqzLabel                   matlab.ui.control.Label
        CombinedResponseAxes         matlab.ui.control.UIAxes
        HighpassResponseAxes         matlab.ui.control.UIAxes
        LowpassResponseAxes          matlab.ui.control.UIAxes
        FilterOrderInfoLabel         matlab.ui.control.Label
        FilterParametersPanel        matlab.ui.container.Panel
        DesignFilterButton           matlab.ui.control.Button
        HighpassStopAttenEditField   matlab.ui.control.NumericEditField
        HighpassStopAttenLabel       matlab.ui.control.Label
        HighpassPassRippleEditField  matlab.ui.control.NumericEditField
        HighpassPassRippleLabel      matlab.ui.control.Label
        HighpassCutoffEditField      matlab.ui.control.NumericEditField
        HighpassCutoffLabel          matlab.ui.control.Label
        HighpassParametersLabel      matlab.ui.control.Label
        LowpassStopAttenEditField    matlab.ui.control.NumericEditField
        LowpassStopAttenLabel        matlab.ui.control.Label
        LowpassPassRippleEditField   matlab.ui.control.NumericEditField
        LowpassPassRippleLabel       matlab.ui.control.Label
        LowpassCutoffEditField       matlab.ui.control.NumericEditField
        LowpassCutoffLabel           matlab.ui.control.Label
        LowpassParametersLabel       matlab.ui.control.Label
        ERPWaveformTab               matlab.ui.container.Tab
        ERPLabel                     matlab.ui.control.Label
        WaveformLabel                matlab.ui.control.Label
        ProcessDataButton            matlab.ui.control.Button
        ChannelSelectorDropDown      matlab.ui.control.DropDown
        ChannelSelectorLabel         matlab.ui.control.Label
        FileSelectorDropDown         matlab.ui.control.DropDown
        FileSelectorLabel            matlab.ui.control.Label
        DataInfoLabel                matlab.ui.control.Label
        LoadDataButton               matlab.ui.control.Button
        SpectrumAxes                 matlab.ui.control.UIAxes
        WaveformAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Description
        % 数据属性
        Sig01Data                   % sig01文件数据
        Sig02Data                   % sig02文件数据
        CurrentData                 % 当前选择的数据
        Fs = 1000                   % 采样频率 (Hz)

        % 滤波器系数
        lpCoeff                    % 低通滤波器系数
        hpCoeff                    % 高通滤波器系数
        lpH                        % 低通滤波器频率响应
        hpH                        % 高通滤波器频率响应
        combinedH                  % 组合滤波器频率响应
        combinedF                  % 滤波器频率范围

        % 处理结果
        FilteredData               % 滤波后数据
        BaselineCorrectedData      % 基线校正后数据
        ERPAverage                 % ERP平均波形
        ERPAverageFFT              % ERP平均频谱
        Freq                       % ERP频率范围
        TimeAxis                   % 时间轴

        % 滤波器参数
        LowpassCutoff = 30         % 低通截止频率 (Hz)
        LowpassPassRipple = 8      % 低通通带波纹 (dB)
        LowpassStopAtten = 15      % 低通阻带衰减 (dB)

        HighpassCutoff = 0.5       % 高通截止频率 (Hz)
        HighpassPassRipple = 8     % 高通通带波纹 (dB)
        HighpassStopAtten = 12     % 高通阻带衰减 (dB)

        % 电极名称
        ElectrodeNames = {'FCz', 'Cz'}

        % 当前处理的文件标识
        CurrentFile = 'sig01'

        % 当前选择的导联
        CurrentChannel = 1         % 1=FCz, 2=Cz
    end

    methods (Access = private)

        function filterSignal = applyCascadeFilter(app, signal)
            % 应用级联滤波器（先低通后高通）
            % 第一步：低通滤波
            %
            % % 第二步：高通滤波
            lpFiltered = filter(app.hpCoeff.b, app.hpCoeff.a, signal);
            filterSignal = filter(app.lpCoeff.b, app.lpCoeff.a, lpFiltered);
        end

        function displayResults(app)
            % 显示结果：波形图和频谱图
            if isempty(app.ERPAverage)
                return;
            end

            % 获取当前选择的导联
            channelIdx = app.CurrentChannel;
            channelName = app.ElectrodeNames{channelIdx};

            % 获取当前导联的平均ERP波形
            erpWaveform = app.ERPAverage(channelIdx, :);

            % 显示波形图（左侧）
            plot(app.WaveformAxes, app.TimeAxis, erpWaveform, 'b', 'LineWidth', 2);
            box(app.WaveformAxes, 'on');

            app.WaveformAxes.Title.String = sprintf('%s - %s电极ERP波形', app.CurrentFile, channelName);
            app.WaveformAxes.XLabel.String = '时间/ms';
            app.WaveformAxes.YLabel.String = '幅度/μV';
            app.WaveformAxes.XGrid = 'on';
            app.WaveformAxes.YGrid = 'on';
            legend(app.WaveformAxes,'平均ERP波形', 'Location', 'northeast');
            xlim(app.WaveformAxes, [0, 700]);

            % 计算并显示频谱图（右侧）
            AverageFFT = app.ERPAverageFFT(channelIdx, :);
            plot(app.SpectrumAxes, app.Freq, AverageFFT, 'b', 'LineWidth', 2);
            box(app.SpectrumAxes, 'on');
            
            app.SpectrumAxes.Title.String = sprintf('%s - %s电极ERP频谱', app.CurrentFile, channelName);
            app.SpectrumAxes.XLabel.String = '频率/Hz';
            app.SpectrumAxes.YLabel.String = '幅度';
            app.SpectrumAxes.XGrid = 'on';
            app.SpectrumAxes.YGrid = 'on';
            legend(app.SpectrumAxes, '平均ERP频谱', 'Location', 'northeast');
            xlim(app.SpectrumAxes, [0, 50]);
        end

        function PlotAmpFreqz(app)
            % 幅频响应
            % 显示低通滤波器响应
            plot(app.LowpassResponseAxes, app.combinedF, 20*log10(abs(app.lpH)), 'b', 'LineWidth', 2);
            app.LowpassResponseAxes.Title.String = sprintf('低通滤波器');
            app.LowpassResponseAxes.XLabel.String = '频率/Hz';
            app.LowpassResponseAxes.YLabel.String = '幅度/dB';
            app.LowpassResponseAxes.XGrid = 'on';
            app.LowpassResponseAxes.YGrid = 'on';
            xlim(app.LowpassResponseAxes, [0 100]);
            ylim(app.LowpassResponseAxes, 'auto')
            yticks(app.LowpassResponseAxes, 'auto')
            yticklabels(app.LowpassResponseAxes, 'auto')

            % 显示高通滤波器响应
            plot(app.HighpassResponseAxes, app.combinedF, 20*log10(abs(app.hpH)), 'b', 'LineWidth', 2);
            app.HighpassResponseAxes.Title.String = sprintf('高通滤波器');
            app.HighpassResponseAxes.XLabel.String = '频率/Hz';
            app.HighpassResponseAxes.YLabel.String = '幅度/dB';
            app.HighpassResponseAxes.XGrid = 'on';
            app.HighpassResponseAxes.YGrid = 'on';
            xlim(app.HighpassResponseAxes, [0 5]);
            ylim(app.HighpassResponseAxes, 'auto')
            yticks(app.HighpassResponseAxes, 'auto')
            yticklabels(app.HighpassResponseAxes, 'auto')

            % 显示组合滤波器响应
            plot(app.CombinedResponseAxes, app.combinedF, 20*log10(abs(app.combinedH)), 'b', 'LineWidth', 2);
            app.CombinedResponseAxes.XLabel.String = '频率/Hz';
            app.CombinedResponseAxes.YLabel.String = '幅度/dB';
            app.CombinedResponseAxes.XGrid = 'on';
            app.CombinedResponseAxes.YGrid = 'on';
            xlim(app.CombinedResponseAxes, [0 100]);
            ylim(app.CombinedResponseAxes, 'auto')
            yticks(app.CombinedResponseAxes, 'auto')
            yticklabels(app.CombinedResponseAxes, 'auto')
        end

        function PlotPhasedFreqz(app)
            % 相频响应
            % 显示低通滤波器响应
            plot(app.LowpassResponseAxes, app.combinedF, angle(app.lpH), 'b', 'LineWidth', 2);
            app.LowpassResponseAxes.Title.String = sprintf('低通滤波器');
            app.LowpassResponseAxes.XLabel.String = '频率/Hz';
            app.LowpassResponseAxes.YLabel.String = '相位/rad';
            app.LowpassResponseAxes.XGrid = 'on';
            app.LowpassResponseAxes.YGrid = 'on';
            xlim(app.LowpassResponseAxes, [0 100]);
            ylim(app.LowpassResponseAxes, [-pi pi]);
            yticks(app.LowpassResponseAxes, -pi:pi/2:pi);
            yticklabels(app.LowpassResponseAxes, {'-\pi','-\pi/2','0','\pi/2','\pi'});

            % 显示高通滤波器响应
            plot(app.HighpassResponseAxes, app.combinedF, angle(app.hpH), 'b', 'LineWidth', 2);
            app.HighpassResponseAxes.Title.String = sprintf('高通滤波器');
            app.HighpassResponseAxes.XLabel.String = '频率/Hz';
            app.HighpassResponseAxes.YLabel.String = '相位/rad';
            app.HighpassResponseAxes.XGrid = 'on';
            app.HighpassResponseAxes.YGrid = 'on';
            xlim(app.HighpassResponseAxes, [0 5]);
            ylim(app.HighpassResponseAxes, [-pi pi]);
            yticks(app.HighpassResponseAxes, -pi:pi/2:pi);
            yticklabels(app.HighpassResponseAxes, {'-\pi','-\pi/2','0','\pi/2','\pi'});

            % 显示组合滤波器响应
            plot(app.CombinedResponseAxes, app.combinedF, angle(app.combinedH), 'b', 'LineWidth', 2);
            app.CombinedResponseAxes.XLabel.String = '频率/Hz';
            app.CombinedResponseAxes.YLabel.String = '相位/rad';
            app.CombinedResponseAxes.XGrid = 'on';
            app.CombinedResponseAxes.YGrid = 'on';
            xlim(app.CombinedResponseAxes, [0 100]);
            ylim(app.CombinedResponseAxes, [-pi pi]);
            yticks(app.CombinedResponseAxes, -pi:pi/2:pi);
            yticklabels(app.CombinedResponseAxes, {'-\pi','-\pi/2','0','\pi/2','\pi'});

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DesignFilterButton
        function DesignFilterButtonPushed(app, event)
            % 设计巴特沃斯IIR滤波器（先低通后高通）- 严格按照示例代码规范
            try
                app.StatusLabel.Text = '正在设计滤波器...';
                app.ProgressLamp.Color = 'yellow';
                drawnow;

                % 获取滤波器参数
                app.LowpassCutoff = app.LowpassCutoffEditField.Value;
                app.LowpassPassRipple = app.LowpassPassRippleEditField.Value;
                app.LowpassStopAtten = app.LowpassStopAttenEditField.Value;

                app.HighpassCutoff = app.HighpassCutoffEditField.Value;
                app.HighpassPassRipple = app.HighpassPassRippleEditField.Value;
                app.HighpassStopAtten = app.HighpassStopAttenEditField.Value;

                % 归一化频率
                nyquist = app.Fs / 2;

                % 低通滤波器设计参数
                lpWp = app.LowpassCutoff / nyquist;       % 通带截止频率
                lpWs = (app.LowpassCutoff + 5) / nyquist; % 阻带截止频率（比通带高5Hz）

                % 高通滤波器设计参数
                hpWp = app.HighpassCutoff / nyquist;      % 通带截止频率
                hpWs = (app.HighpassCutoff - 0.1) / nyquist; % 阻带截止频率
                % 设计低通滤波器
                [lpN, lpWn] = buttord(lpWp, lpWs, app.LowpassPassRipple, app.LowpassStopAtten);
                [lpB, lpA] = butter(lpN, lpWn, 'low');

                % 设计高通滤波器
                [hpN, hpWn] = buttord(hpWp, hpWs, app.HighpassPassRipple, app.HighpassStopAtten);
                [hpB, hpA] = butter(hpN, hpWn, 'high');

                % 保存滤波器系数
                app.lpCoeff.b = lpB;
                app.lpCoeff.a = lpA;
                app.lpCoeff.order = lpN;
                app.lpCoeff.passRipple = app.LowpassPassRipple;
                app.lpCoeff.stopAtten = app.LowpassStopAtten;

                app.hpCoeff.b = hpB;
                app.hpCoeff.a = hpA;
                app.hpCoeff.order = hpN;
                app.hpCoeff.passRipple = app.HighpassPassRipple;
                app.hpCoeff.stopAtten = app.HighpassStopAtten;

                % 计算频率响应
                N = 32768;
                [app.lpH, app.combinedF] = freqz(lpB, lpA, N, app.Fs);
                [app.hpH, ~] = freqz(hpB, hpA, N, app.Fs);

                % 计算组合响应
                app.combinedH = app.lpH .* app.hpH;
                % 绘制频率响应
                switch app.FreqzDropDown.Value
                    case '幅频响应'
                        PlotAmpFreqz(app)
                    case '相频响应'
                        PlotPhasedFreqz(app)
                    otherwise
                        PlotAmpFreqz(app)
                end

                % 显示滤波器阶数信息
                app.FilterOrderInfoLabel.Text = sprintf('滤波器阶数 - 低通: %d ,高通: %d', lpN ,hpN);
                app.StatusLabel.Text = '滤波器设计完成';
                app.ProgressLamp.Color = 'green';

            catch ME
                uialert(app.ERPUIFigure, ME.message, '滤波器设计错误');
                app.StatusLabel.Text = '滤波器设计失败';
                app.ProgressLamp.Color = 'red';
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % 加载ERP数据 - 只选择路径，自动识别sig01和sig02文件
            try
                app.StatusLabel.Text = '正在加载ERP数据...';
                app.ProgressLamp.Color = 'yellow';
                drawnow;

                % 选择文件夹
                folderPath = uigetdir('', '选择包含sig01和sig02数据的文件夹');
                if folderPath == 0
                    app.StatusLabel.Text = '数据加载已取消';
                    app.ProgressLamp.Color = [0.8 0.8 0.8];
                    return;
                end

                % 自动查找文件
                sig01Files = dir(fullfile(folderPath, '*sig01*.mat'));
                sig02Files = dir(fullfile(folderPath, '*sig02*.mat'));

                if isempty(sig01Files)
                    % 如果没有找到sig01文件，提示用户选择sig01文件
                    [file1, path1] = uigetfile({'*.mat', 'MATLAB Data Files'}, '请选择sig01数据文件', folderPath);
                    if isequal(file1, 0)
                        app.StatusLabel.Text = '数据加载已取消';
                        app.ProgressLamp.Color = [0.8 0.8 0.8];
                        return;
                    end
                    sig01Path = fullfile(path1, file1);
                else
                    sig01Path = fullfile(folderPath, sig01Files(1).name);
                end

                % 加载sig01
                data1 = load(sig01Path);
                fieldNames1 = fieldnames(data1);
                if ismember('sig01', fieldNames1)
                    app.Sig01Data = data1.sig01;
                elseif length(fieldNames1) == 1
                    app.Sig01Data = data1.(fieldNames1{1});
                else
                    uialert(app.ERPUIFigure, '无法识别sig01数据变量', '数据格式错误');
                    return;
                end

                % 检查sig02文件
                if ~isempty(sig02Files)
                    sig02Path = fullfile(folderPath, sig02Files(1).name);
                    data2 = load(sig02Path);
                    fieldNames2 = fieldnames(data2);
                    if ismember('sig02', fieldNames2)
                        app.Sig02Data = data2.sig02;
                    elseif length(fieldNames2) == 1
                        app.Sig02Data = data2.(fieldNames2{1});
                    else
                        uialert(app.ERPUIFigure, '无法识别sig02数据变量', '数据格式错误');
                        app.Sig02Data = [];
                    end
                else
                    app.Sig02Data = [];
                end

                % 检查数据维度
                [numElectrodes1, numSamples1, numTrials1] = size(app.Sig01Data);
                if numElectrodes1 ~= 2 || numSamples1 ~= 701
                    uialert(app.ERPUIFigure, 'sig01数据格式应为2×701×100', '维度错误');
                    return;
                end

                if ~isempty(app.Sig02Data)
                    [numElectrodes2, numSamples2, numTrials2] = size(app.Sig02Data);
                    if numElectrodes2 ~= 2 || numSamples2 ~= 701
                        uialert(app.ERPUIFigure, 'sig02数据格式应为2×701×100', '维度错误');
                        app.Sig02Data = [];
                    end
                end

                % 更新数据显示
                infoText = sprintf('sig01: %d导联 × %d采样点 × %d试次', ...
                    numElectrodes1, numSamples1, numTrials1);

                if ~isempty(app.Sig02Data)
                    infoText = sprintf('%s\nsig02: %d导联 × %d采样点 × %d试次', ...
                        infoText, numElectrodes2, numSamples2, numTrials2);
                end

                app.DataInfoLabel.Text = infoText;

                % 设置当前数据和下拉列表
                app.CurrentData = app.Sig01Data;
                app.CurrentFile = 'sig01';
                app.StatusLabel.Text = 'ERP数据加载完成';
                app.ProgressLamp.Color = 'green';

            catch ME
                uialert(app.ERPUIFigure, ME.message, '数据加载错误');
                app.StatusLabel.Text = '数据加载失败';
                app.ProgressLamp.Color = 'red';
            end
        end

        % Button pushed function: ProcessDataButton
        function ProcessDataButtonPushed(app, event)
            % 处理ERP数据：滤波、去基线、叠加平均
            try
                if isempty(app.CurrentData)
                    uialert(app.ERPUIFigure, '请先加载数据', '数据未加载');
                    return;
                end

                if isempty(app.lpCoeff) || isempty(app.hpCoeff)
                    uialert(app.ERPUIFigure, '请先设计滤波器', '滤波器未设计');
                    return;
                end

                app.StatusLabel.Text = '正在处理ERP数据...';
                app.ProgressLamp.Color = 'yellow';
                drawnow;

                % 获取数据维度
                [numElectrodes, numSamples, numTrials] = size(app.CurrentData);
                NFFT = 2^(nextpow2(numSamples)+4); % FFT点数，下一个2的幂
                
                % 初始化处理结果
                app.FilteredData = zeros(size(app.CurrentData));
                app.BaselineCorrectedData = zeros(size(app.CurrentData));
                app.ERPAverage = zeros(numElectrodes, numSamples);
                app.ERPAverageFFT = zeros(numElectrodes, NFFT/2+1);
                app.Freq = (0:NFFT/2) * app.Fs / NFFT;

                % 创建时间轴（刺激在200ms处，即第200个采样点）
                app.TimeAxis = (0:numSamples-1) / app.Fs * 1000; % 转换为毫秒

                % 处理每个试次
                for trial = 1:numTrials
                    for electrode = 1:numElectrodes
                        % 提取原始信号
                        rawSignal = squeeze(app.CurrentData(electrode, :, trial));

                        % 步骤1: 滤波
                        filteredSignal = app.applyCascadeFilter(rawSignal);
                        app.FilteredData(electrode, :, trial) = filteredSignal;

                        % 步骤2: 去基线（前200个点求平均）
                        baselineMean = mean(filteredSignal(1:200));
                        baselineCorrected = filteredSignal - baselineMean;
                        app.BaselineCorrectedData(electrode, :, trial) = baselineCorrected;

                        % 对去基线的数据进行频谱分析
                        fftResult = fft(baselineCorrected, NFFT);
                        spectrum = abs(fftResult(1:NFFT/2+1)/app.Fs);
                        % 计算单边频谱
                        spectrum(2:end-1) = spectrum(2:end-1) * 2;
                        
                        % 累加用于平均
                        app.ERPAverage(electrode, :) = app.ERPAverage(electrode, :) + baselineCorrected;
                        app.ERPAverageFFT(electrode, :) = app.ERPAverageFFT(electrode, :) + spectrum;
                    end
                end

                % 步骤3: 叠加平均（除以试次数）
                app.ERPAverage = app.ERPAverage / numTrials;
                app.ERPAverageFFT = app.ERPAverageFFT / numTrials;

                % 显示结果
                app.displayResults();
                app.StatusLabel.Text = sprintf('%s数据处理完成 (%d个试次)', app.CurrentFile, numTrials);
                app.ProgressLamp.Color = 'green';

            catch ME
                uialert(app.ERPUIFigure, ME.message, '数据处理错误');
                app.StatusLabel.Text = '数据处理失败';
                app.ProgressLamp.Color = 'red';
            end
        end

        % Value changed function: FileSelectorDropDown
        function FileSelectorDropDownValueChanged(app, event)
            % 切换当前处理的文件
            selectedFile = app.FileSelectorDropDown.Value;

            if strcmp(selectedFile, 'sig01') && ~isempty(app.Sig01Data)
                app.CurrentData = app.Sig01Data;
                app.CurrentFile = 'sig01';
                app.StatusLabel.Text = '已切换到sig01数据';

            elseif strcmp(selectedFile, 'sig02') && ~isempty(app.Sig02Data)
                app.CurrentData = app.Sig02Data;
                app.CurrentFile = 'sig02';
                app.StatusLabel.Text = '已切换到sig02数据';

            else
                app.StatusLabel.Text = '所选文件数据未加载';
                app.ProgressLamp.Color = 'yellow';
            end
        end

        % Value changed function: ChannelSelectorDropDown
        function ChannelSelectorDropDownValueChanged(app, event)
            % 切换当前选择的导联
            selectedChannel = app.ChannelSelectorDropDown.Value;
            if strcmp(selectedChannel, 'FCz')
                app.CurrentChannel = 1;
            elseif strcmp(selectedChannel, 'Cz')
                app.CurrentChannel = 2;
            end
        end

        % Value changed function: FreqzDropDown
        function FreqzValueChanged(app, event)
            % 绘制频率响应
            switch app.FreqzDropDown.Value
                case '幅频响应'
                    PlotAmpFreqz(app)
                case '相频响应'
                    PlotPhasedFreqz(app)
                otherwise
                    PlotAmpFreqz(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ERPUIFigure = uifigure('Visible', 'off');
            app.ERPUIFigure.Name = '脑电ERP信号的去噪方法';
            app.TabGroup = uitabgroup(app.ERPUIFigure);
            app.FilterDesignTab = uitab(app.TabGroup);
            app.FilterDesignTab.Title = '滤波器设计';
            app.FilterParametersPanel = uipanel(app.FilterDesignTab);
            app.FilterParametersPanel.Title = '滤波器参数';
            app.LowpassParametersLabel = uilabel(app.FilterParametersPanel);
            app.LowpassParametersLabel.Text = '低通滤波器参数';
            app.LowpassCutoffLabel = uilabel(app.FilterParametersPanel);
            app.LowpassCutoffLabel.Text = '截止频率';
            app.LowpassCutoffEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.LowpassCutoffEditField.Limits = [10 200];
            app.LowpassCutoffEditField.Value = 30;
            app.LowpassPassRippleLabel = uilabel(app.FilterParametersPanel);
            app.LowpassPassRippleLabel.Text = '通带波纹/dB';
            app.LowpassPassRippleEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.LowpassPassRippleEditField.Value = 8;
            app.LowpassStopAttenLabel = uilabel(app.FilterParametersPanel);
            app.LowpassStopAttenLabel.Text = '阻带衰减/dB';
            app.LowpassStopAttenEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.LowpassStopAttenEditField.Value = 15;
            app.HighpassParametersLabel = uilabel(app.FilterParametersPanel);
            app.HighpassParametersLabel.Text = '高通滤波器参数';
            app.HighpassCutoffLabel = uilabel(app.FilterParametersPanel);
            app.HighpassCutoffLabel.Text = '截止频率';
            app.HighpassCutoffEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.HighpassCutoffEditField.Limits = [0.1 20];
            app.HighpassCutoffEditField.Value = 0.5;
            app.HighpassPassRippleLabel = uilabel(app.FilterParametersPanel);
            app.HighpassPassRippleLabel.Text = '通带波纹/dB';
            app.HighpassPassRippleEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.HighpassPassRippleEditField.Value = 8;
            app.HighpassStopAttenLabel = uilabel(app.FilterParametersPanel);
            app.HighpassStopAttenLabel.Text = '阻带衰减/dB';
            app.HighpassStopAttenEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.HighpassStopAttenEditField.Value = 12;
            app.DesignFilterButton = uibutton(app.FilterParametersPanel, 'push');
            app.DesignFilterButton.ButtonPushedFcn = createCallbackFcn(app, @DesignFilterButtonPushed, true);
            app.DesignFilterButton.Text = '设计滤波器';
            app.FilterOrderInfoLabel = uilabel(app.FilterDesignTab);
            app.FilterOrderInfoLabel.Text = '滤波器阶数: 未设计';
            app.FilterResponsePanel = uipanel(app.FilterDesignTab);
            app.FilterResponsePanel.Title = '滤波器频率响应';
            app.LowpassResponseAxes = uiaxes(app.FilterResponsePanel);
            title(app.LowpassResponseAxes, '低通滤波器响应')
            xlabel(app.LowpassResponseAxes, '频率/Hz')
            ylabel(app.LowpassResponseAxes, '幅度/dB')
            zlabel(app.LowpassResponseAxes, 'Z')
            app.HighpassResponseAxes = uiaxes(app.FilterResponsePanel);
            title(app.HighpassResponseAxes, '高通滤波器响应')
            xlabel(app.HighpassResponseAxes, '频率/Hz')
            ylabel(app.HighpassResponseAxes, '幅度/dB')
            zlabel(app.HighpassResponseAxes, 'Z')
            app.CombinedResponseAxes = uiaxes(app.FilterResponsePanel);
            title(app.CombinedResponseAxes, '组合带通滤波器响应')
            xlabel(app.CombinedResponseAxes, '频率/Hz')
            ylabel(app.CombinedResponseAxes, '幅度/dB')
            zlabel(app.CombinedResponseAxes, 'Z')
            app.FreqzLabel = uilabel(app.FilterResponsePanel);
            app.FreqzLabel.Text = '频率响应类型';
            app.FreqzDropDown = uidropdown(app.FilterResponsePanel);
            app.FreqzDropDown.Items = {'幅频响应', '相频响应'};
            app.FreqzDropDown.ValueChangedFcn = createCallbackFcn(app, @FreqzValueChanged, true);
            app.FreqzDropDown.Value = '幅频响应';
            app.ERPWaveformTab = uitab(app.TabGroup);
            app.ERPWaveformTab.Title = 'ERP波形显示和频谱分析';
            app.WaveformAxes = uiaxes(app.ERPWaveformTab);
            title(app.WaveformAxes, 'ERP波形图')
            xlabel(app.WaveformAxes, '时间/ms')
            ylabel(app.WaveformAxes, '幅度/μV')
            zlabel(app.WaveformAxes, 'Z')
            app.SpectrumAxes = uiaxes(app.ERPWaveformTab);
            title(app.SpectrumAxes, 'ERP频谱图')
            xlabel(app.SpectrumAxes, '频率/Hz')
            ylabel(app.SpectrumAxes, '幅度')
            zlabel(app.SpectrumAxes, 'Z')
            app.LoadDataButton = uibutton(app.ERPWaveformTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = '加载ERP数据';
            app.DataInfoLabel = uilabel(app.ERPWaveformTab);
            app.DataInfoLabel.Text = '未加载数据';
            app.FileSelectorLabel = uilabel(app.ERPWaveformTab);
            app.FileSelectorLabel.Text = '选择文件';
            app.FileSelectorDropDown = uidropdown(app.ERPWaveformTab);
            app.FileSelectorDropDown.Items = {'sig01', 'sig02'};
            app.FileSelectorDropDown.ValueChangedFcn = createCallbackFcn(app, @FileSelectorDropDownValueChanged, true);
            app.FileSelectorDropDown.Value = 'sig01';
            app.ChannelSelectorLabel = uilabel(app.ERPWaveformTab);
            app.ChannelSelectorLabel.Text = '选择导联';
            app.ChannelSelectorDropDown = uidropdown(app.ERPWaveformTab);
            app.ChannelSelectorDropDown.Items = {'FCz', 'Cz'};
            app.ChannelSelectorDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectorDropDownValueChanged, true);
            app.ChannelSelectorDropDown.Value = 'FCz';
            app.ProcessDataButton = uibutton(app.ERPWaveformTab, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessDataButtonPushed, true);
            app.ProcessDataButton.Text = '处理数据';
            app.WaveformLabel = uilabel(app.ERPWaveformTab);
            app.WaveformLabel.Text = 'ERP波形图：';
            app.ERPLabel = uilabel(app.ERPWaveformTab);
            app.ERPLabel.Text = 'ERP频谱图：';
            app.StatusLabel = uilabel(app.ERPUIFigure);
            app.StatusLabel.Text = '就绪';
            app.ProgressLamp = uilamp(app.ERPUIFigure);
            % Show the figure after all components are created
            app.ERPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ERPUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ERPUIFigure)
        end
    end
end