classdef exp8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ProgressLamp                 matlab.ui.control.Lamp
        StatusLabel                  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        FilterDesignTab              matlab.ui.container.Tab
        FilterResponsePanel          matlab.ui.container.Panel
        FreqzDropDown                matlab.ui.control.DropDown
        FreqzLabel                   matlab.ui.control.Label
        CombinedResponseAxes         matlab.ui.control.UIAxes
        HighpassResponseAxes         matlab.ui.control.UIAxes
        LowpassResponseAxes          matlab.ui.control.UIAxes
        FilterOrderInfoLabel         matlab.ui.control.Label
        FilterParametersPanel        matlab.ui.container.Panel
        DesignFilterButton           matlab.ui.control.Button
        HighpassStopAttenEditField   matlab.ui.control.NumericEditField
        HighpassStopAttenLabel       matlab.ui.control.Label
        HighpassPassRippleEditField  matlab.ui.control.NumericEditField
        HighpassPassRippleLabel      matlab.ui.control.Label
        HighpassCutoffEditField      matlab.ui.control.NumericEditField
        HighpassCutoffLabel          matlab.ui.control.Label
        HighpassParametersLabel      matlab.ui.control.Label
        LowpassStopAttenEditField    matlab.ui.control.NumericEditField
        LowpassStopAttenLabel        matlab.ui.control.Label
        LowpassPassRippleEditField   matlab.ui.control.NumericEditField
        LowpassPassRippleLabel       matlab.ui.control.Label
        LowpassCutoffEditField       matlab.ui.control.NumericEditField
        LowpassCutoffLabel           matlab.ui.control.Label
        LowpassParametersLabel       matlab.ui.control.Label
        Tab                          matlab.ui.container.Tab
        ProcessECGButton             matlab.ui.control.Button
        LoadECGDataButton            matlab.ui.control.Button
        FilteredSpectrumAxes         matlab.ui.control.UIAxes
        FilteredECGAxes              matlab.ui.control.UIAxes
        ECGSpectrumAxes              matlab.ui.control.UIAxes
        ECGTimeAxes                  matlab.ui.control.UIAxes
        Tab_2                        matlab.ui.container.Tab
        WindowStepEditField          matlab.ui.control.NumericEditField
        WindowStepLabel              matlab.ui.control.Label
        WindowTimeEditField          matlab.ui.control.NumericEditField
        WindowTimeLabel              matlab.ui.control.Label
        FreqDomainResultsLabel       matlab.ui.control.Label
        TimeDomainResultsLabel       matlab.ui.control.Label
        ResultsSummaryLabel          matlab.ui.control.Label
        CalculateHRButton            matlab.ui.control.Button
        FreqDomainHRAxes             matlab.ui.control.UIAxes
        TimeDomainHRAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Description
        % 数据属性
        Fs = 1000                   % 采样频率 (Hz)
        timeVector                  % 时间向量
        rawECG                      % 原始心电信号
        filteredECG                 % 滤波后心电信号
        rawSpectrum                 % 原始信号频谱
        filteredSpectrum            % 滤波后信号频谱
        freqVector                  % 频率向量

        % 心率检测结果
        hrTimeDomain                % 时域法心率
        hrFreqDomain                % 频域法心率
        hrTimeAxis                  % 心率时间轴

        % 滤波器系数
        lpCoeff                    % 低通滤波器系数
        hpCoeff                    % 高通滤波器系数
        lpH                        % 低通滤波器频率响应
        hpH                        % 高通滤波器频率响应
        combinedH                  % 组合滤波器频率响应
        combinedF                  % 滤波器频率范围

        % 滤波器参数
        LowpassCutoff = 45         % 低通截止频率 (Hz)
        LowpassPassRipple = 2      % 低通通带波纹 (dB)
        LowpassStopAtten = 12      % 低通阻带衰减 (dB)

        HighpassCutoff = 0.5       % 高通截止频率 (Hz)
        HighpassPassRipple = 2     % 高通通带波纹 (dB)
        HighpassStopAtten = 6     % 高通阻带衰减 (dB)

    end

    methods (Access = private)

        function filterSignal = applyCascadeFilter(app, signal)
            % 应用级联滤波器
            lpFiltered = filter(app.hpCoeff.b, app.hpCoeff.a, signal);
            filterSignal = filter(app.lpCoeff.b, app.lpCoeff.a, lpFiltered);
        end

        function displayResults(app)
            % 显示结果：波形图和频谱图
            if isempty(app.rawECG)
                return;
            end

            % 显示原始心电信号（前10秒）
            displayLength = min(10000, length(app.rawECG));
            timeDisplay = app.timeVector(1:displayLength);
            rawDisplay = app.rawECG(1:displayLength);

            plot(app.ECGTimeAxes, timeDisplay, rawDisplay, 'b', 'LineWidth', 1);
            app.ECGTimeAxes.Title.String = '原始心电信号';
            app.ECGTimeAxes.XLabel.String = '时间/秒';
            app.ECGTimeAxes.YLabel.String = '幅度';
            app.ECGTimeAxes.XGrid = 'on';
            app.ECGTimeAxes.YGrid = 'on';

            % 显示滤波后心电信号
            if ~isempty(app.filteredECG)
                filteredDisplay = app.filteredECG(1:displayLength);
                plot(app.FilteredECGAxes, timeDisplay, filteredDisplay, 'r', 'LineWidth', 1);
                app.FilteredECGAxes.Title.String = '滤波后心电信号';
                app.FilteredECGAxes.XLabel.String = '时间/秒';
                app.FilteredECGAxes.YLabel.String = '幅度';
                app.FilteredECGAxes.XGrid = 'on';
                app.FilteredECGAxes.YGrid = 'on';
            end

            % 计算并显示频谱图
            if ~isempty(app.rawSpectrum)
                plot(app.ECGSpectrumAxes, app.freqVector, app.rawSpectrum, 'b', 'LineWidth', 1);
                app.ECGSpectrumAxes.Title.String = '原始心电信号频谱';
                app.ECGSpectrumAxes.XLabel.String = '频率/Hz';
                app.ECGSpectrumAxes.YLabel.String = '幅度';
                app.ECGSpectrumAxes.XGrid = 'on';
                app.ECGSpectrumAxes.YGrid = 'on';
                xlim(app.ECGSpectrumAxes, [0 100]);
            end

            if ~isempty(app.filteredSpectrum)
                plot(app.FilteredSpectrumAxes, app.freqVector, app.filteredSpectrum, 'r', 'LineWidth', 1);
                app.FilteredSpectrumAxes.Title.String = '滤波后心电信号频谱';
                app.FilteredSpectrumAxes.XLabel.String = '频率/Hz';
                app.FilteredSpectrumAxes.YLabel.String = '幅度';
                app.FilteredSpectrumAxes.XGrid = 'on';
                app.FilteredSpectrumAxes.YGrid = 'on';
                xlim(app.FilteredSpectrumAxes, [0 100]);
            end
        end

        function PlotAmpFreqz(app)
            % 幅频响应
            % 显示低通滤波器响应
            plot(app.LowpassResponseAxes, app.combinedF, 20*log10(abs(app.lpH)), 'b', 'LineWidth', 2);
            app.LowpassResponseAxes.Title.String = sprintf('低通滤波器');
            app.LowpassResponseAxes.XLabel.String = '频率/Hz';
            app.LowpassResponseAxes.YLabel.String = '幅度/dB';
            app.LowpassResponseAxes.XGrid = 'on';
            app.LowpassResponseAxes.YGrid = 'on';
            xlim(app.LowpassResponseAxes, [0 100]);
            ylim(app.LowpassResponseAxes, 'auto')
            yticks(app.LowpassResponseAxes, 'auto')
            yticklabels(app.LowpassResponseAxes, 'auto')

            % 显示高通滤波器响应
            plot(app.HighpassResponseAxes, app.combinedF, 20*log10(abs(app.hpH)), 'b', 'LineWidth', 2);
            app.HighpassResponseAxes.Title.String = sprintf('高通滤波器');
            app.HighpassResponseAxes.XLabel.String = '频率/Hz';
            app.HighpassResponseAxes.YLabel.String = '幅度/dB';
            app.HighpassResponseAxes.XGrid = 'on';
            app.HighpassResponseAxes.YGrid = 'on';
            xlim(app.HighpassResponseAxes, [0 5]);
            ylim(app.HighpassResponseAxes, 'auto')
            yticks(app.HighpassResponseAxes, 'auto')
            yticklabels(app.HighpassResponseAxes, 'auto')

            % 显示组合滤波器响应
            plot(app.CombinedResponseAxes, app.combinedF, 20*log10(abs(app.combinedH)), 'b', 'LineWidth', 2);
            app.CombinedResponseAxes.XLabel.String = '频率/Hz';
            app.CombinedResponseAxes.YLabel.String = '幅度/dB';
            app.CombinedResponseAxes.XGrid = 'on';
            app.CombinedResponseAxes.YGrid = 'on';
            xlim(app.CombinedResponseAxes, [0 100]);
            ylim(app.CombinedResponseAxes, 'auto')
            yticks(app.CombinedResponseAxes, 'auto')
            yticklabels(app.CombinedResponseAxes, 'auto')
        end

        function PlotPhasedFreqz(app)
            % 相频响应
            % 显示低通滤波器响应
            plot(app.LowpassResponseAxes, app.combinedF, angle(app.lpH), 'b', 'LineWidth', 2);
            app.LowpassResponseAxes.Title.String = sprintf('低通滤波器');
            app.LowpassResponseAxes.XLabel.String = '频率/Hz';
            app.LowpassResponseAxes.YLabel.String = '相位/rad';
            app.LowpassResponseAxes.XGrid = 'on';
            app.LowpassResponseAxes.YGrid = 'on';
            xlim(app.LowpassResponseAxes, [0 100]);
            ylim(app.LowpassResponseAxes, [-pi pi]);
            yticks(app.LowpassResponseAxes, -pi:pi/2:pi);
            yticklabels(app.LowpassResponseAxes, {'-\pi','-\pi/2','0','\pi/2','\pi'});

            % 显示高通滤波器响应
            plot(app.HighpassResponseAxes, app.combinedF, angle(app.hpH), 'b', 'LineWidth', 2);
            app.HighpassResponseAxes.Title.String = sprintf('高通滤波器');
            app.HighpassResponseAxes.XLabel.String = '频率/Hz';
            app.HighpassResponseAxes.YLabel.String = '相位/rad';
            app.HighpassResponseAxes.XGrid = 'on';
            app.HighpassResponseAxes.YGrid = 'on';
            xlim(app.HighpassResponseAxes, [0 5]);
            ylim(app.HighpassResponseAxes, [-pi pi]);
            yticks(app.HighpassResponseAxes, -pi:pi/2:pi);
            yticklabels(app.HighpassResponseAxes, {'-\pi','-\pi/2','0','\pi/2','\pi'});

            % 显示组合滤波器响应
            plot(app.CombinedResponseAxes, app.combinedF, angle(app.combinedH), 'b', 'LineWidth', 2);
            app.CombinedResponseAxes.XLabel.String = '频率/Hz';
            app.CombinedResponseAxes.YLabel.String = '相位/rad';
            app.CombinedResponseAxes.XGrid = 'on';
            app.CombinedResponseAxes.YGrid = 'on';
            xlim(app.CombinedResponseAxes, [0 100]);
            ylim(app.CombinedResponseAxes, [-pi pi]);
            yticks(app.CombinedResponseAxes, -pi:pi/2:pi);
            yticklabels(app.CombinedResponseAxes, {'-\pi','-\pi/2','0','\pi/2','\pi'});

        end

        function analyzeECGSpectrum(app)
            % 分析心电信号频谱
            app.StatusLabel.Text = '正在分析心电信号频谱...';
            drawnow;

            % 计算原始信号频谱
            N_raw = length(app.rawECG);

            if N_raw > 0
                % 使用大于等于序列长度的最小2的幂次作为FFT点数
                nfft_raw = 2^nextpow2(N_raw);

                % 计算FFT
                Y_raw = fft(app.rawECG, nfft_raw);
                P2_raw = abs(Y_raw/app.Fs);
                P1_raw = P2_raw(1:nfft_raw/2+1);
                P1_raw(2:end-1) = 2*P1_raw(2:end-1);
                app.rawSpectrum = P1_raw;

                % 生成频率向量
                app.freqVector = app.Fs*(0:(nfft_raw/2))/nfft_raw;
            end

            % 计算滤波后信号频谱
            if ~isempty(app.filteredECG)
                N_filt = length(app.filteredECG);

                % 使用大于等于序列长度的最小2的幂次作为FFT点数
                nfft_filt = 2^nextpow2(N_filt);

                % 确保滤波后信号的FFT点数与原始信号一致，以便使用相同的频率向量
                if nfft_filt ~= nfft_raw
                    % 如果长度不同，使用原始信号的FFT点数来保持一致
                    nfft_filt = nfft_raw;
                end

                % 计算FFT
                Y_filt = fft(app.filteredECG, nfft_filt);
                P2_filt = abs(Y_filt/app.Fs);
                P1_filt = P2_filt(1:nfft_filt/2+1);
                P1_filt(2:end-1) = 2*P1_filt(2:end-1);
                app.filteredSpectrum = P1_filt;
            end
        end

        function detectHRTimeDomain(app, ecgSignal, progressDialog, targetProgress)
            % 时域法心率检测
            % 输入：滤波后的心电信号
            % 输出：心率随时间变化数组

            N = length(ecgSignal);
            windowLength = round(app.WindowTimeEditField.Value * app.Fs);
            stepSize = app.WindowStepEditField.Value;

            % 计算滑动窗口数量
            numWindows = floor((N - windowLength) / stepSize) + 1;

            % 初始化结果数组
            app.hrTimeDomain = zeros(numWindows, 1);

            % 计算每个窗口的进度增量
            progressIncrement = targetProgress / numWindows;

            for i = 1:numWindows
                % 计算窗口起始和结束索引
                startIdx = (i-1) * stepSize + 1;
                endIdx = startIdx + windowLength - 1;

                % 提取当前窗口数据
                windowData = ecgSignal(startIdx:endIdx);

                % 检测R峰
                [~, peakLocs] = findpeaks(windowData, 'MinPeakHeight', 0.5*max(windowData), ...
                    'MinPeakDistance', 0.3*app.Fs); % R峰最小间隔0.3秒

                % 计算心率（次/分钟）
                if length(peakLocs) >= 2
                    rrIntervals = diff(peakLocs) / app.Fs; % R-R间隔（秒）
                    app.hrTimeDomain(i) = 60 / mean(rrIntervals);
                else
                    app.hrTimeDomain(i) = 0;
                end

                % 每10个窗口或最后一个窗口更新一次进度条
                if mod(i, 10) == 0 || i == numWindows
                    currentProgress = i * progressIncrement;
                    progressDialog.Value = currentProgress;
                    progressDialog.Message = sprintf('时域法计算进度: %d/%d (%.1f%%)', ...
                        i, numWindows, currentProgress*100);
                    drawnow;
                end
            end
            % 移除无效值
            app.hrTimeDomain = app.hrTimeDomain(app.hrTimeDomain > 0);
            app.hrTimeDomain = app.hrTimeDomain;


            % 显示心率检测结果
            app.StatusLabel.Text = '正在显示时域法心率检测结果...';
            drawnow;

            % 检查是否有有效的心率数据
            if isempty(app.hrTimeDomain)
                app.ResultsSummaryLabel.Text = '请先计算心率';
                return;
            end

            % 创建时间轴
            timeStart = app.WindowTimeEditField.Value/2;
            timeStep = app.WindowStepEditField.Value/app.Fs;
            timeAxis = timeStart:timeStep:(timeStart + (length(app.hrTimeDomain)-1)*timeStep);

            % 确保时间轴长度匹配
            minLength = min(length(timeAxis), length(app.hrTimeDomain));
            timeAxis = timeAxis(1:minLength);

            % 绘制时域法心率曲线
            plot(app.TimeDomainHRAxes, timeAxis, app.hrTimeDomain, 'b-', 'LineWidth', 2);
            app.TimeDomainHRAxes.Title.String = '时域法心率检测结果';
            app.TimeDomainHRAxes.XLabel.String = '时间/秒';
            app.TimeDomainHRAxes.YLabel.String = '心率/BPM';
            app.TimeDomainHRAxes.XGrid = 'on';
            app.TimeDomainHRAxes.YGrid = 'on';
            xlim(app.TimeDomainHRAxes, [0 240]);
            ylim(app.TimeDomainHRAxes, [40 160]);
            xticks(app.TimeDomainHRAxes, 0:40:240);
            xticklabels(app.TimeDomainHRAxes, 0:40:240);


            % 计算时域法统计信息
            avgTime = mean(app.hrTimeDomain);
            stdTime = std(app.hrTimeDomain);
            app.TimeDomainResultsLabel.Text = sprintf('平均心率: %.1f ± %.1f BPM', avgTime, stdTime);
        end

        function detectHRFreqDomain(app, ecgSignal, progressDialog, baseProgress, targetProgress)
            % 频域法心率检测
            % 输入：滤波后的心电信号
            % 输出：心率随时间变化数组

            N = length(ecgSignal);
            windowLength = round(app.WindowTimeEditField.Value * app.Fs);
            stepSize = app.WindowStepEditField.Value;

            % 计算滑动窗口数量
            numWindows = floor((N - windowLength) / stepSize) + 1;

            % 初始化结果数组
            app.hrFreqDomain = zeros(numWindows, 1);

            % 确定FFT点数：使用大于等于窗口长度的最小2的幂次加2
            nfft = 2^(nextpow2(windowLength) + 2);

            % 计算每个窗口的进度增量
            progressRange = targetProgress - baseProgress;
            progressIncrement = progressRange / numWindows;

            for i = 1:numWindows
                % 计算窗口起始和结束索引
                startIdx = (i-1) * stepSize + 1;
                endIdx = startIdx + windowLength - 1;

                % 提取当前窗口数据
                windowData = ecgSignal(startIdx:endIdx);

                % 计算频谱，使用确定的FFT点数
                Y = fft(windowData, nfft);
                P2 = abs(Y/app.Fs);
                P1 = P2(1:nfft/2+1);
                P1(2:end-1) = 2*P1(2:end-1);

                % 生成频率向量
                f = app.Fs*(0:(nfft/2))/nfft;

                % 寻找0.5-2.5Hz范围内的最大峰值
                freqRange = (f >= 0.5) & (f <= 2.5);
                if any(freqRange)
                    [~, idx] = max(P1(freqRange));
                    freqValues = f(freqRange);
                    dominantFreq = freqValues(idx);
                    app.hrFreqDomain(i) = dominantFreq * 60; % 转换为BPM
                else
                    app.hrFreqDomain(i) = 0;
                end

                % 每10个窗口或最后一个窗口更新一次进度条
                if mod(i, 10) == 0 || i == numWindows
                    currentProgress = baseProgress + i * progressIncrement;
                    progressDialog.Value = currentProgress;
                    progressDialog.Message = sprintf('频域法计算进度: %d/%d (%.1f%%)', ...
                        i, numWindows, currentProgress*100);
                    drawnow;
                end
            end
            % 移除无效值
            app.hrFreqDomain = app.hrFreqDomain(app.hrFreqDomain > 0);
            app.hrFreqDomain = app.hrFreqDomain;


            % 显示心率检测结果
            app.StatusLabel.Text = '正在显示频域法心率检测结果...';
            drawnow;

            % 检查是否有有效的心率数据
            if isempty(app.hrFreqDomain)
                app.ResultsSummaryLabel.Text = '请先计算心率';
                return;
            end

            % 创建时间轴
            timeStart = app.WindowTimeEditField.Value/2;
            timeStep = app.WindowStepEditField.Value/app.Fs;
            timeAxis = timeStart:timeStep:(timeStart + (length(app.hrTimeDomain)-1)*timeStep);

            % 确保时间轴长度匹配
            minLength = min(length(timeAxis), length(app.hrTimeDomain));
            timeAxis = timeAxis(1:minLength);
            hrFreq = app.hrFreqDomain(1:minLength);

            % 绘制频域法心率曲线
            plot(app.FreqDomainHRAxes, timeAxis, hrFreq, 'r-', 'LineWidth', 2);
            app.FreqDomainHRAxes.Title.String = '频域法心率检测结果';
            app.FreqDomainHRAxes.XLabel.String = '时间/秒';
            app.FreqDomainHRAxes.YLabel.String = '心率/BPM';
            app.FreqDomainHRAxes.XGrid = 'on';
            app.FreqDomainHRAxes.YGrid = 'on';
            xticks(app.FreqDomainHRAxes, 0:40:240);
            xticklabels(app.FreqDomainHRAxes, 0:40:240);
            xlim(app.FreqDomainHRAxes, [0 240]);
            ylim(app.FreqDomainHRAxes, [40 160]);

            % 计算频域法统计信息
            avgFreq = mean(hrFreq);
            stdFreq = std(hrFreq);
            app.FreqDomainResultsLabel.Text = sprintf('平均心率: %.1f ± %.1f BPM', avgFreq, stdFreq);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DesignFilterButton
        function DesignFilterButtonPushed(app, event)
            % 设计巴特沃斯IIR滤波器
            try
                app.StatusLabel.Text = '正在设计滤波器...';
                app.ProgressLamp.Color = 'yellow';
                drawnow;

                % 获取滤波器参数
                app.LowpassCutoff = app.LowpassCutoffEditField.Value;
                app.LowpassPassRipple = app.LowpassPassRippleEditField.Value;
                app.LowpassStopAtten = app.LowpassStopAttenEditField.Value;

                app.HighpassCutoff = app.HighpassCutoffEditField.Value;
                app.HighpassPassRipple = app.HighpassPassRippleEditField.Value;
                app.HighpassStopAtten = app.HighpassStopAttenEditField.Value;

                % 归一化频率
                nyquist = app.Fs / 2;

                % 低通滤波器设计参数
                lpWp = app.LowpassCutoff / nyquist;       % 通带截止频率
                lpWs = (app.LowpassCutoff + 5) / nyquist; % 阻带截止频率（比通带高5Hz）

                % 高通滤波器设计参数
                hpWp = app.HighpassCutoff / nyquist;      % 通带截止频率
                hpWs = (app.HighpassCutoff - 0.1) / nyquist; % 阻带截止频率
                % 设计低通滤波器
                [lpN, lpWn] = buttord(lpWp, lpWs, app.LowpassPassRipple, app.LowpassStopAtten);
                [lpB, lpA] = butter(lpN, lpWn, 'low');

                % 设计高通滤波器
                [hpN, hpWn] = buttord(hpWp, hpWs, app.HighpassPassRipple, app.HighpassStopAtten);
                [hpB, hpA] = butter(hpN, hpWn, 'high');

                % 保存滤波器系数
                app.lpCoeff.b = lpB;
                app.lpCoeff.a = lpA;
                app.lpCoeff.order = lpN;
                app.lpCoeff.passRipple = app.LowpassPassRipple;
                app.lpCoeff.stopAtten = app.LowpassStopAtten;

                app.hpCoeff.b = hpB;
                app.hpCoeff.a = hpA;
                app.hpCoeff.order = hpN;
                app.hpCoeff.passRipple = app.HighpassPassRipple;
                app.hpCoeff.stopAtten = app.HighpassStopAtten;

                % 计算频率响应
                N = 32768;
                [app.lpH, app.combinedF] = freqz(lpB, lpA, N, app.Fs);
                [app.hpH, ~] = freqz(hpB, hpA, N, app.Fs);

                % 计算组合响应
                app.combinedH = app.lpH .* app.hpH;
                % 绘制频率响应
                switch app.FreqzDropDown.Value
                    case '幅频响应'
                        PlotAmpFreqz(app)
                    case '相频响应'
                        PlotPhasedFreqz(app)
                    otherwise
                        PlotAmpFreqz(app)
                end

                % 显示滤波器阶数信息
                app.FilterOrderInfoLabel.Text = sprintf('滤波器阶数 - 低通: %d ,高通: %d', lpN ,hpN);
                app.StatusLabel.Text = '滤波器设计完成';
                app.ProgressLamp.Color = 'green';

            catch ME
                uialert(app.UIFigure, ME.message, '滤波器设计错误');
                app.StatusLabel.Text = '滤波器设计失败';
                app.ProgressLamp.Color = 'red';
            end
        end

        % Value changed function: FreqzDropDown
        function FreqzValueChanged(app, event)
            % 绘制频率响应
            switch app.FreqzDropDown.Value
                case '幅频响应'
                    PlotAmpFreqz(app)
                case '相频响应'
                    PlotPhasedFreqz(app)
                otherwise
                    PlotAmpFreqz(app)
            end
        end

        % Button pushed function: LoadECGDataButton
        function LoadECGDataButtonPushed(app, event)
            try
                app.StatusLabel.Text = '正在加载心电数据...';
                app.ProgressLamp.Color = 'yellow';
                drawnow;

                % 弹出文件选择对话框
                [filename, pathname] = uigetfile('*.mat', '选择心电数据文件');

                if isequal(filename, 0)
                    app.StatusLabel.Text = '用户取消选择';
                    app.ProgressLamp.Color = [0.8 0.8 0.8];
                    return;
                end

                % 加载MAT文件
                filepath = fullfile(pathname, filename);
                data = load(filepath);

                % 查找心电数据变量（按照实验要求是a_2n2）
                if isfield(data, 'a_2n2')
                    app.rawECG = data.a_2n2;
                    app.StatusLabel.Text = sprintf('已加载数据: a_2n2 (%d点)', length(app.rawECG));
                else
                    % 如果没有a_2n2，使用第一个数值变量
                    varNames = fieldnames(data);
                    found = false;
                    for i = 1:length(varNames)
                        varData = data.(varNames{i});
                        if isnumeric(varData) && isvector(varData)
                            app.rawECG = varData;
                            app.StatusLabel.Text = sprintf('已加载数据: %s (%d点)', varNames{i}, length(app.rawECG));
                            found = true;
                            break;
                        end
                    end

                    if ~found
                        error('未找到合适的心电数据变量');
                    end
                end

                % 确保是列向量
                if isrow(app.rawECG)
                    app.rawECG = app.rawECG';
                end

                % 创建时间向量
                app.timeVector = (0:length(app.rawECG)-1)' / app.Fs;

                % 分析频谱
                analyzeECGSpectrum(app);

                % 显示结果
                displayResults(app);

                app.StatusLabel.Text = sprintf('心电数据加载完成: %s', filename);
                app.ProgressLamp.Color = 'green';

            catch ME
                uialert(app.ERPUIFigure, ME.message, '数据加载错误');
                app.StatusLabel.Text = '数据加载失败';
                app.ProgressLamp.Color = 'red';
            end
        end

        % Button pushed function: ProcessECGButton
        function ProcessECGButtonPushed(app, event)
            try
                app.StatusLabel.Text = '正在处理心电信号...';
                app.ProgressLamp.Color = 'yellow';
                drawnow;

                % 检查是否已加载数据
                if isempty(app.rawECG)
                    uialert(app.ERPUIFigure, '请先加载心电数据', '数据缺失');
                    return;
                end

                % 检查是否已设计滤波器
                if isempty(app.lpCoeff) || isempty(app.hpCoeff)
                    uialert(app.ERPUIFigure, '请先设计滤波器', '滤波器未设计');
                    return;
                end

                % 应用滤波器
                app.filteredECG = applyCascadeFilter(app, app.rawECG);

                % 分析频谱
                analyzeECGSpectrum(app);

                % 显示结果
                displayResults(app);

                app.StatusLabel.Text = '心电信号处理完成';
                app.ProgressLamp.Color = 'green';

            catch ME
                uialert(app.ERPUIFigure, ME.message, '信号处理错误');
                app.StatusLabel.Text = '信号处理失败';
                app.ProgressLamp.Color = 'red';
            end
        end

        % Button pushed function: CalculateHRButton
        function CalculateHRButtonPushed(app, event)
            try
                app.StatusLabel.Text = '正在计算心率...';
                app.ProgressLamp.Color = 'yellow';
                drawnow;

                % 检查是否已处理数据
                if isempty(app.filteredECG)
                    uialert(app.ERPUIFigure, '请先处理心电信号', '数据缺失');
                    return;
                end

                % 创建进度条对话框
                progressDialog = uiprogressdlg(app.UIFigure, ...
                    'Title', '心率计算进度', ...
                    'Message', '正在初始化计算...', ...
                    'Indeterminate', 'off', ...
                    'Value', 0);

                % 时域法检测心率
                app.StatusLabel.Text = '正在使用时域法检测心率...';
                progressDialog.Message = '正在使用时域法检测心率...';
                progressDialog.Value = 0;
                drawnow;
                % detectHRTimeDomain(app, app.filteredECG);
                detectHRTimeDomain(app, app.filteredECG, progressDialog, 0.5);

                % 频域法检测心率
                app.StatusLabel.Text = '正在使用频域法检测心率...';
                progressDialog.Message = '正在使用频域法检测心率...';
                progressDialog.Value = 0.5;
                drawnow;
                % detectHRFreqDomain(app, app.filteredECG);
                detectHRFreqDomain(app, app.filteredECG, progressDialog, 0.5, 1.0);

                % 关闭进度条
                close(progressDialog);

                % 显示结果
                app.ResultsSummaryLabel.Text = '心率检测完成';
                app.StatusLabel.Text = '心率计算完成';
                app.ProgressLamp.Color = 'green';

            catch ME
                uialert(app.UIFigure, ME.message, '心率计算错误');
                app.StatusLabel.Text = '心率计算失败';
                app.ProgressLamp.Color = 'red';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '心电信号心率检测方法';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FilterDesignTab = uitab(app.TabGroup);
            app.FilterDesignTab.Title = '滤波器设计';
            app.FilterParametersPanel = uipanel(app.FilterDesignTab);
            app.FilterParametersPanel.Title = '滤波器参数';
            app.LowpassParametersLabel = uilabel(app.FilterParametersPanel);
            app.LowpassParametersLabel.Text = '低通滤波器参数';
            app.LowpassCutoffLabel = uilabel(app.FilterParametersPanel);
            app.LowpassCutoffLabel.Text = '截止频率';
            app.LowpassCutoffEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.LowpassCutoffEditField.Limits = [10 200];
            app.LowpassCutoffEditField.Value = 45;
            app.LowpassPassRippleLabel = uilabel(app.FilterParametersPanel);
            app.LowpassPassRippleLabel.Text = '通带波纹/dB';
            app.LowpassPassRippleEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.LowpassPassRippleEditField.Value = 2;
            app.LowpassStopAttenLabel = uilabel(app.FilterParametersPanel);
            app.LowpassStopAttenLabel.Text = '阻带衰减/dB';
            app.LowpassStopAttenEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.LowpassStopAttenEditField.Value = 12;
            app.HighpassParametersLabel = uilabel(app.FilterParametersPanel);
            app.HighpassParametersLabel.Text = '高通滤波器参数';
            app.HighpassCutoffLabel = uilabel(app.FilterParametersPanel);
            app.HighpassCutoffLabel.Text = '截止频率';
            app.HighpassCutoffEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.HighpassCutoffEditField.Limits = [0.1 20];
            app.HighpassCutoffEditField.Value = 0.5;
            app.HighpassPassRippleLabel = uilabel(app.FilterParametersPanel);
            app.HighpassPassRippleLabel.Text = '通带波纹/dB';
            app.HighpassPassRippleEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.HighpassPassRippleEditField.Value = 2;
            app.HighpassStopAttenLabel = uilabel(app.FilterParametersPanel);
            app.HighpassStopAttenLabel.Text = '阻带衰减/dB';
            app.HighpassStopAttenEditField = uieditfield(app.FilterParametersPanel, 'numeric');
            app.HighpassStopAttenEditField.Value = 6;
            app.DesignFilterButton = uibutton(app.FilterParametersPanel, 'push');
            app.DesignFilterButton.ButtonPushedFcn = createCallbackFcn(app, @DesignFilterButtonPushed, true);
            app.DesignFilterButton.Text = '设计滤波器';
            app.FilterOrderInfoLabel = uilabel(app.FilterDesignTab);
            app.FilterOrderInfoLabel.Text = '滤波器阶数: 未设计';
            app.FilterResponsePanel = uipanel(app.FilterDesignTab);
            app.FilterResponsePanel.Title = '滤波器频率响应';
            app.LowpassResponseAxes = uiaxes(app.FilterResponsePanel);
            title(app.LowpassResponseAxes, '低通滤波器响应')
            xlabel(app.LowpassResponseAxes, '频率/Hz')
            ylabel(app.LowpassResponseAxes, '幅度/dB')
            zlabel(app.LowpassResponseAxes, 'Z')
            app.HighpassResponseAxes = uiaxes(app.FilterResponsePanel);
            title(app.HighpassResponseAxes, '高通滤波器响应')
            xlabel(app.HighpassResponseAxes, '频率/Hz')
            ylabel(app.HighpassResponseAxes, '幅度/dB')
            zlabel(app.HighpassResponseAxes, 'Z')
            app.CombinedResponseAxes = uiaxes(app.FilterResponsePanel);
            title(app.CombinedResponseAxes, '组合带通滤波器响应')
            xlabel(app.CombinedResponseAxes, '频率/Hz')
            ylabel(app.CombinedResponseAxes, '幅度/dB')
            zlabel(app.CombinedResponseAxes, 'Z')
            app.FreqzLabel = uilabel(app.FilterResponsePanel);
            app.FreqzLabel.Text = '频率响应类型';
            app.FreqzDropDown = uidropdown(app.FilterResponsePanel);
            app.FreqzDropDown.Items = {'幅频响应', '相频响应'};
            app.FreqzDropDown.ValueChangedFcn = createCallbackFcn(app, @FreqzValueChanged, true);
            app.FreqzDropDown.Value = '幅频响应';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '心电信号分析';
            app.ECGTimeAxes = uiaxes(app.Tab);
            title(app.ECGTimeAxes, '原始心电信号')
            xlabel(app.ECGTimeAxes, '时间/s')
            ylabel(app.ECGTimeAxes, '幅度')
            zlabel(app.ECGTimeAxes, 'Z')
            app.ECGSpectrumAxes = uiaxes(app.Tab);
            title(app.ECGSpectrumAxes, '原始信号频谱')
            xlabel(app.ECGSpectrumAxes, '频率/Hz')
            ylabel(app.ECGSpectrumAxes, '幅度')
            zlabel(app.ECGSpectrumAxes, 'Z')
            app.FilteredECGAxes = uiaxes(app.Tab);
            title(app.FilteredECGAxes, '滤波后心电信号')
            xlabel(app.FilteredECGAxes, '时间/s')
            ylabel(app.FilteredECGAxes, '幅度')
            zlabel(app.FilteredECGAxes, 'Z')
            app.FilteredSpectrumAxes = uiaxes(app.Tab);
            title(app.FilteredSpectrumAxes, '滤波后信号频谱')
            xlabel(app.FilteredSpectrumAxes, '频率/Hz')
            ylabel(app.FilteredSpectrumAxes, '幅度')
            zlabel(app.FilteredSpectrumAxes, 'Z')
            app.LoadECGDataButton = uibutton(app.Tab, 'push');
            app.LoadECGDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadECGDataButtonPushed, true);
            app.LoadECGDataButton.Text = '加载心电数据';
            app.ProcessECGButton = uibutton(app.Tab, 'push');
            app.ProcessECGButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessECGButtonPushed, true);
            app.ProcessECGButton.Text = '滤波处理';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '心率检测结果';
            app.TimeDomainHRAxes = uiaxes(app.Tab_2);
            title(app.TimeDomainHRAxes, '时域法心率检测')
            xlabel(app.TimeDomainHRAxes, '时间/s')
            ylabel(app.TimeDomainHRAxes, '心率')
            zlabel(app.TimeDomainHRAxes, 'Z')
            app.FreqDomainHRAxes = uiaxes(app.Tab_2);
            title(app.FreqDomainHRAxes, '频域法心率检测')
            xlabel(app.FreqDomainHRAxes, '时间/s')
            ylabel(app.FreqDomainHRAxes, '心率')
            zlabel(app.FreqDomainHRAxes, 'Z')
            app.CalculateHRButton = uibutton(app.Tab_2, 'push');
            app.CalculateHRButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateHRButtonPushed, true);
            app.CalculateHRButton.Text = '计算心率';
            app.ResultsSummaryLabel = uilabel(app.Tab_2);
            app.ResultsSummaryLabel.Text = '心率检测结果';
            app.TimeDomainResultsLabel = uilabel(app.Tab_2);
            app.TimeDomainResultsLabel.Text = '时域法: --';
            app.FreqDomainResultsLabel = uilabel(app.Tab_2);
            app.FreqDomainResultsLabel.Text = '频域法: --';
            app.WindowTimeLabel = uilabel(app.Tab_2);
            app.WindowTimeLabel.Text = '时间窗长度/s';
            app.WindowTimeEditField = uieditfield(app.Tab_2, 'numeric');
            app.WindowTimeEditField.Value = 5;
            app.WindowStepLabel = uilabel(app.Tab_2);
            app.WindowStepLabel.Text = '时间窗步长';
            app.WindowStepEditField = uieditfield(app.Tab_2, 'numeric');
            app.WindowStepEditField.Value = 1;
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = '就绪';
            app.ProgressLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end