classdef exp9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        RightPanel             matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        UIAxes4                matlab.ui.control.UIAxes
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
        LeftPanel              matlab.ui.container.Panel
        DeleteButton           matlab.ui.control.Button
        PerformancePanel       matlab.ui.container.Panel
        BeamwidthLabel         matlab.ui.control.Label
        SINRLabel              matlab.ui.control.Label
        SIRLabel               matlab.ui.control.Label
        InfoLabel              matlab.ui.control.Label
        ParametersPanel        matlab.ui.container.Panel
        RCheckBox              matlab.ui.control.CheckBox
        ClearButton            matlab.ui.control.Button
        RunButton              matlab.ui.control.Button
        NoiseCheckBox          matlab.ui.control.CheckBox
        SNREditField           matlab.ui.control.NumericEditField
        SNRLabel               matlab.ui.control.Label
        fsEditField            matlab.ui.control.NumericEditField
        fsEditFieldLabel       matlab.ui.control.Label
        fEditField             matlab.ui.control.NumericEditField
        fEditFieldLabel        matlab.ui.control.Label
        AjEditField            matlab.ui.control.NumericEditField
        AjEditFieldLabel       matlab.ui.control.Label
        theta_iEditField       matlab.ui.control.NumericEditField
        theta_iEditFieldLabel  matlab.ui.control.Label
        theta_dEditField       matlab.ui.control.NumericEditField
        theta_dEditFieldLabel  matlab.ui.control.Label
        NEditField             matlab.ui.control.NumericEditField
        NEditFieldLabel        matlab.ui.control.Label
        AsEditField            matlab.ui.control.NumericEditField
        AsEditFieldLabel       matlab.ui.control.Label
        MEditField             matlab.ui.control.NumericEditField
        MEditFieldLabel        matlab.ui.control.Label
    end


    properties (Access = private)
        Property % Description
        w = [];      % 权矢量
        pattern = []; % 方向图数据
        theta_scan = []; % 扫描角度
        Xt = [];     % 接收信号
        y_out = [];  % 输出信号
        st = [];     % 期望信号
        time_axis = []; % 时间轴
    end

    methods (Access = private)

        % 计算导向矢量
        function a = steering_vector(~, theta, M, d, lambda)
            theta_rad = deg2rad(theta);
            a = zeros(M, 1);
            for m = 0:M-1
                a(m+1) = exp(1j * 2 * pi * d * m * sin(theta_rad) / lambda);
            end
        end

        % 生成测试信号并估计协方差矩阵
        function [R_estimated, Xt, st, jt, a_s, a_j, time_axis] = generate_and_estimate_covariance(app, M, N, theta_s, theta_j, As, Aj, add_noise, SNR_dB)
            % 信号参数
            f = app.fEditField.Value; % 信号频率 (Hz)
            fs = app.fsEditField.Value; % 采样频率 (Hz)
            c = 3e8;
            lambda = c / f; % 波长
            d = lambda / 2; % 阵元间距

            % 导向矢量
            a_s = app.steering_vector(theta_s, M, d, lambda);
            a_j = app.steering_vector(theta_j, M, d, lambda);
            Ns = max(N, 5*fs);

            % 时间轴
            time_axis = (0:Ns-1) / fs;

            % 数字角频率
            omega = 2 * pi * f / fs;

            % ==================== 生成测试信号 ====================
            % 期望信号: 正弦信号
            % 干扰信号: 余弦信号（与正弦同频，相位差90度）
            st = As * sin(omega * (0:Ns-1));
            jt = Aj * cos(omega * (0:Ns-1));
            % st = As * cos(omega * (0:N-1));
            % jt = Aj * sin(omega * (0:N-1));

            % 噪声处理
            if add_noise
                % 信号功率
                Ps = As^2 / 2;  % 正弦信号功率 = A^2/2

                % 噪声功率
                Pn = Ps / (10^(SNR_dB/10));

                % 生成实数高斯噪声
                nt = sqrt(Pn) * randn(M, Ns);
            else
                nt = zeros(M, Ns);
            end

            % 接收信号
            Xt = a_s * st + a_j * jt + nt;

            % ==================== 估计协方差矩阵 ====================
            % 使用快拍估计协方差矩阵：R = (1/N) * X * X^H            
            if app.RCheckBox.Value == 1
                X_t = Xt(:,1:N);
                R_estimated = (X_t * X_t') / N;
            else
                X_t = a_s * st + a_j * jt;
                X_t = X_t(:,1:N);
                R_estimated = (X_t * X_t') / N;
            end          

            % 对角加载（提高数值稳定性）
            epsilon = 1e-6 * trace(R_estimated) / M;
            R_estimated = R_estimated + epsilon * eye(M);
        end

        % 计算MVDR权矢量
        function w = calculate_mvdr_weights(~, R, a_s)
            % 使用更稳定的求逆方法
            w = (R \ a_s) / (a_s' * (R' \ a_s));
        end

        % 计算方向图
        function [theta_scan, pattern, pattern_db] = calculate_pattern(app, w, M, ~)
            % 信号频率
            f = app.fEditField.Value;
            c = 3e8;
            lambda = c / f;
            d = lambda / 2;

            theta_scan = -180:0.1:180;
            pattern = zeros(size(theta_scan));

            for k = 1:length(theta_scan)
                a_tmp = app.steering_vector(theta_scan(k), M, d, lambda);
                pattern(k) = abs(w' * a_tmp);
            end

            % 归一化到0dB
            pattern = pattern / max(pattern);
            pattern_db = 20 * log10(pattern);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NoiseCheckBox
        function NoiseNoiseCheckBoxValueChanged(app, event)
            value = app.NoiseCheckBox.Value;

            % 根据是否选择添加噪声，启用/禁用SNR输入框
            if value
                app.SNREditField.Enable = 'on';
                app.SNRLabel.Enable = 'on';
                app.RCheckBox.Enable = 'on';
            else
                app.SNREditField.Enable = 'off';
                app.SNRLabel.Enable = 'off';
                app.RCheckBox.Enable = 'off';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % 获取用户输入参数
            M = app.MEditField.Value;              % 阵元数量
            N = app.NEditField.Value;              % 快拍个数
            theta_s = app.theta_dEditField.Value;  % 期望信号角度
            theta_j = app.theta_iEditField.Value;  % 干扰信号角度
            As = app.AsEditField.Value;            % 期望信号幅度
            Aj = app.AjEditField.Value;            % 干扰信号幅度
            add_noise = app.NoiseCheckBox.Value;   % 是否添加噪声
            SNR_dB = app.SNREditField.Value;       % 信噪比(dB)

            % 参数验证
            if M <= 0 || N <= 0
                app.InfoLabel.Text = '错误：阵元数量和快拍数必须大于0';
                return;
            end

            if As <= 0 || Aj <= 0
                app.InfoLabel.Text = '错误：信号幅度必须大于0';
                return;
            end

            if abs(theta_s) > 90 || abs(theta_j) > 90
                app.InfoLabel.Text = '注意：角度应在-90到90度之间';
            else
                app.InfoLabel.Text = '正在计算...';
            end

            % ==================== 第一步：生成测试信号并估计协方差矩阵 ====================
            [R_estimated, app.Xt, app.st, ~, a_s, a_j, app.time_axis] = ...
                generate_and_estimate_covariance(app, M, N, theta_s, theta_j, As, Aj, add_noise, SNR_dB);

            % ==================== 第二步：计算MVDR权矢量 ====================
            app.w = calculate_mvdr_weights(app, R_estimated, a_s);

            % ==================== 第三步：滤波处理 ====================
            app.y_out = app.w' * app.Xt;

            % ==================== 第四步：计算方向图 ====================
            f = app.fEditField.Value;
            c = 3e8;
            lambda = c / f;

            [app.theta_scan, ~, pattern_db] = calculate_pattern(...
                app, app.w, M, lambda);

            % ==================== 第五步：绘制方向图 ====================
            plot(app.UIAxes, app.theta_scan, pattern_db, 'b-', 'LineWidth', 2);
            hold(app.UIAxes, 'on');

            % 标记期望信号和干扰信号方向
            plot(app.UIAxes, [theta_s theta_s], [-80 10], '--g', 'LineWidth', 1.5);
            plot(app.UIAxes, [theta_j theta_j], [-80 10], '--r', 'LineWidth', 1.5);
            plot(app.UIAxes, [-180 180], [0 0], 'k:', 'LineWidth', 1);

            hold(app.UIAxes, 'off');

            app.UIAxes.Title.String = '空域滤波器方向图';
            app.UIAxes.XLabel.String = '入射角度/°';
            app.UIAxes.YLabel.String = '幅度响应/dB';
            app.UIAxes.XLim = [-180 180];
            app.UIAxes.YLim = [-80 10];
            grid(app.UIAxes, 'on');

            % 添加图例和标注
            legend(app.UIAxes, {'方向图', '期望方向', '干扰方向', '0dB参考线'}, 'Location', 'northwest');
            text(app.UIAxes, theta_s, 2, sprintf('  %.0f°', theta_s), 'Color', 'g', 'FontWeight', 'bold');
            text(app.UIAxes, theta_j, -40, sprintf('  %.0f°', theta_j), 'Color', 'r', 'FontWeight', 'bold');

            % ==================== 第六步：绘制信号对比图 ====================
            % 计算5个完整周期对应的点数
            f = app.fEditField.Value; % 信号频率 (Hz)
            fs = app.fsEditField.Value; % 采样频率 (Hz)
            T = 1/f; % 信号周期 (秒)
            points_per_cycle = round(fs * T); % 每个周期的采样点数
            points_5_cycles = 5 * points_per_cycle + 1; % 5个周期的采样点数
            
            % 确保不超过总采样点数
            % n_points = min(points_5_cycles, N);
            t_idx = 1:points_5_cycles;

            % 时间轴转换为毫秒
            time_ms = app.time_axis(t_idx) * 1000;

            % 获取第一个阵元的接收信号
            x1 = app.Xt(1, t_idx);
            
            plot(app.UIAxes2, time_ms, real(x1), ...
                'Color', [0.7 0.7 0.7], 'LineWidth', 1);
            hold(app.UIAxes2, 'on');

            plot(app.UIAxes2, time_ms, app.st(t_idx), ...
                'g', 'LineWidth', 2);

            plot(app.UIAxes2, time_ms, real(app.y_out(t_idx)), ...
                'b-', 'LineWidth', 1.5);

            hold(app.UIAxes2, 'off');

            app.UIAxes2.Title.String = '时域波形对比 (正弦 vs 余弦同频干扰)';
            app.UIAxes2.XLabel.String = '时间/ms';
            app.UIAxes2.YLabel.String = '幅度';
            
            % 设置X轴范围为5个周期
            time_5_cycles_ms = 5 * T * 1000; % 5个周期的时间长度（毫秒）
            app.UIAxes2.XLim = [0, time_5_cycles_ms]
            grid(app.UIAxes2, 'on');
            legend(app.UIAxes2, {'滤波前(含强干扰)', '理想期望信号(正弦)', '滤波后输出'}, 'Location', 'northeast');

            % ==================== 第七步：计算性能指标 ====================
            % 计算波束宽度
            [~ ,center_idx] = max(pattern_db);
            left_idx = center_idx;
            right_idx = center_idx;            
            pattern_db_norm = pattern_db - max(pattern_db);
            while left_idx > 1 && pattern_db_norm(left_idx) > -3
                left_idx = left_idx - 1;
            end
            while right_idx < length(pattern_db_norm) && pattern_db_norm(right_idx) > -3
                right_idx = right_idx + 1;
            end
            beamwidth = app.theta_scan(right_idx) - app.theta_scan(left_idx);

            % 计算信号功率
            Ps = As^2 / 2;  % 正弦信号功率
            Pj = Aj^2 / 2;  % 余弦信号功率

            % 计算输出功率
            output_power_exsignal = abs(app.w' * a_s)^2 * Ps;
            output_power_interference = abs(app.w' * a_j)^2 * Pj;

            % SIR（信号干扰比）
            if output_power_interference > 0
                SIR_dB = 10 * log10(output_power_exsignal / output_power_interference);
            else
                SIR_dB = Inf;
            end

            % SINR（信号干扰噪声比）
            if add_noise
                % 噪声功率
                Pn = Ps / (10^(SNR_dB/10));
                SINR_dB = 10 * log10(output_power_exsignal / (output_power_interference + Pn * norm(app.w)^2));
            else
                SINR_dB = 10 * log10(output_power_exsignal / output_power_interference);
            end

            % ==================== 第八步：绘制权矢量幅度变化图 ====================
            % 获取权矢量幅度
            w_magnitude = abs(app.w);
            
            % 绘制权矢量幅度变化图
            stem(app.UIAxes3, 1:M, w_magnitude, 'b', 'LineWidth', 1.5, 'MarkerFaceColor', 'b', 'MarkerEdgeColor', 'b');
            
            app.UIAxes3.Title.String = '权矢量幅度变化图';
            app.UIAxes3.XLabel.String = '阵元序号';
            app.UIAxes3.YLabel.String = '幅度';
            app.UIAxes3.XLim = [0.5, M + 0.5];
            grid(app.UIAxes3, 'on');
            xlim(app.UIAxes3, [1, M]);
            
            % ==================== 第九步：绘制权矢量相位变化图 ====================
            % 获取权矢量相位（转换为角度）
            w_phase = angle(app.w) * 180 / pi;
            
            % 绘制权矢量相位变化图
            stem(app.UIAxes4, 1:M, w_phase, 'r', 'LineWidth', 1.5, 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'r');
            
            app.UIAxes4.Title.String = '权矢量相位变化图';
            app.UIAxes4.XLabel.String = '阵元序号';
            app.UIAxes4.YLabel.String = '相位/°';
            app.UIAxes4.XLim = [0.5, M + 0.5];
            grid(app.UIAxes4, 'on');
            xlim(app.UIAxes4, [1, M]);

            % 更新性能显示
            app.SIRLabel.Text = sprintf('信号干扰比 (SIR): %.2f dB', SIR_dB);
            app.SINRLabel.Text = sprintf('信干噪比 (SINR): %.2f dB', SINR_dB);
            app.BeamwidthLabel.Text = sprintf('3dB波束宽度: %.1f°', beamwidth);

            app.InfoLabel.Text = sprintf('设计完成！');
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % 清除图形
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);

            % 重置性能显示
            app.SIRLabel.Text = '信号干扰比 (SIR): -- dB';
            app.SINRLabel.Text = '信干噪比 (SINR): -- dB';
            app.BeamwidthLabel.Text = '波束宽度 (3dB): --°'
            app.InfoLabel.Text = '已清除图形，请输入新参数';
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '空域滤波器设计';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParametersPanel = uipanel(app.LeftPanel);
            app.ParametersPanel.Title = '参数设置';
            app.MEditFieldLabel = uilabel(app.ParametersPanel);
            app.MEditFieldLabel.Text = '阵元数量 M:';
            app.MEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.MEditField.Tooltip = {'天线阵列的阵元数量'};
            app.MEditField.Value = 12;
            app.AsEditFieldLabel = uilabel(app.ParametersPanel);
            app.AsEditFieldLabel.Text = '期望信号幅度 As:';
            app.AsEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.AsEditField.Tooltip = {'期望信号的幅度值'};
            app.AsEditField.Value = 1;
            app.NEditFieldLabel = uilabel(app.ParametersPanel);
            app.NEditFieldLabel.Text = '快拍个数 N:';
            app.NEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.NEditField.Tooltip = {'时间采样点数'};
            app.NEditField.Value = 100;
            app.theta_dEditFieldLabel = uilabel(app.ParametersPanel);
            app.theta_dEditFieldLabel.Text = '期望信号角度 θs:';
            app.theta_dEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.theta_dEditField.Tooltip = {'期望信号入射方向/°'};
            app.theta_dEditField.Value = 30;
            app.theta_iEditFieldLabel = uilabel(app.ParametersPanel);
            app.theta_iEditFieldLabel.Text = '干扰信号角度 θj:';
            app.theta_iEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.theta_iEditField.Tooltip = {'干扰信号入射方向/°'};
            app.theta_iEditField.Value = 20;
            app.AjEditFieldLabel = uilabel(app.ParametersPanel);
            app.AjEditFieldLabel.Text = '干扰信号幅度 Aj:';
            app.AjEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.AjEditField.Tooltip = {'干扰信号的幅度值'};
            app.AjEditField.Value = 1;
            app.fEditFieldLabel = uilabel(app.ParametersPanel);
            app.fEditFieldLabel.Text = '信号频率 f/Hz:';
            app.fEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.fEditField.ValueDisplayFormat = '%.0f';
            app.fEditField.Tooltip = {'期望信号和干扰信号的频率/Hz'};
            app.fEditField.Value = 1000;
            app.fsEditFieldLabel = uilabel(app.ParametersPanel);
            app.fsEditFieldLabel.Text = '采样频率 fs/Hz';
            app.fsEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.fsEditField.ValueDisplayFormat = '%.0f';
            app.fsEditField.Tooltip = {'采样频率/Hz'};
            app.fsEditField.Value = 100000;
            app.SNRLabel = uilabel(app.ParametersPanel);
            app.SNRLabel.Text = '信噪比 SNR/dB:';
            app.SNREditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SNREditField.Tooltip = {'信噪比/dB'};
            app.SNREditField.Value = 10;
            app.NoiseCheckBox = uicheckbox(app.ParametersPanel);
            app.NoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoiseNoiseCheckBoxValueChanged, true);
            app.NoiseCheckBox.Text = '添加噪声';
            app.NoiseCheckBox.Value = true;
            app.RunButton = uibutton(app.ParametersPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = '运行仿真';
            app.ClearButton = uibutton(app.ParametersPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = '清除图像';
            app.RCheckBox = uicheckbox(app.ParametersPanel);
            app.RCheckBox.Text = '使用含噪信号估计协方差矩阵';
            app.InfoLabel = uilabel(app.LeftPanel);
            app.InfoLabel.Text = '请输入参数并点击"运行仿真"';
            app.PerformancePanel = uipanel(app.LeftPanel);
            app.PerformancePanel.Title = '性能指标';
            app.SIRLabel = uilabel(app.PerformancePanel);
            app.SIRLabel.Text = '信号干扰比 (SIR): -- dB';
            app.SINRLabel = uilabel(app.PerformancePanel);
            app.SINRLabel.Text = '信干噪比 (SINR): -- dB';
            app.BeamwidthLabel = uilabel(app.PerformancePanel);
            app.BeamwidthLabel.Text = '3dB波束宽度: --°';
            app.DeleteButton = uibutton(app.LeftPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = '关闭界面';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, '空域滤波器方向图')
            xlabel(app.UIAxes, '入射角度/°')
            ylabel(app.UIAxes, '幅度响应/dB')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, '时域波形对比 (余弦 vs 正弦同频干扰)')
            xlabel(app.UIAxes2, '时间/ms')
            ylabel(app.UIAxes2, '幅度')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, '权矢量幅度变化图')
            xlabel(app.UIAxes3, '阵元序号')
            ylabel(app.UIAxes3, '幅度')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, '权矢量相位变化图')
            xlabel(app.UIAxes4, '阵元序号')
            ylabel(app.UIAxes4, '相位/°')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end