classdef week1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        exp2                matlab.ui.container.Tab
        Panel               matlab.ui.container.Panel
        SignalType          matlab.ui.control.DropDown
        Label_14            matlab.ui.control.Label
        NumPanel            matlab.ui.container.Panel
        aveedit             matlab.ui.control.NumericEditField
        Label_11            matlab.ui.control.Label
        generatesignal      matlab.ui.control.Button
        pedit               matlab.ui.control.NumericEditField
        Label_10            matlab.ui.control.Label
        NoiseType           matlab.ui.control.DropDown
        Label_23            matlab.ui.control.Label
        checknoise          matlab.ui.control.CheckBox
        ampedit             matlab.ui.control.NumericEditField
        Label_8             matlab.ui.control.Label
        hunheampedit        matlab.ui.control.EditField
        EditField_6Label    matlab.ui.control.Label
        hunhefedit          matlab.ui.control.EditField
        HzEditFieldLabel_2  matlab.ui.control.Label
        hunhepedit          matlab.ui.control.EditField
        Label_16            matlab.ui.control.Label
        file                matlab.ui.control.Button
        Dutyratio           matlab.ui.control.NumericEditField
        Label_12            matlab.ui.control.Label
        varedit             matlab.ui.control.NumericEditField
        Label_13            matlab.ui.control.Label
        fedit               matlab.ui.control.NumericEditField
        HzEditField_2Label  matlab.ui.control.Label
        tedit               matlab.ui.control.NumericEditField
        sEditFieldLabel     matlab.ui.control.Label
        fsedit              matlab.ui.control.NumericEditField
        HzEditFieldLabel    matlab.ui.control.Label
        signal_freq         matlab.ui.control.UIAxes
        signal              matlab.ui.control.UIAxes
        exp4                matlab.ui.container.Tab
        Panel_2             matlab.ui.container.Panel
        dtzzudaiHz          matlab.ui.control.EditField
        HzLabel             matlab.ui.control.Label
        dtztongdaiHz        matlab.ui.control.EditField
        sLabel              matlab.ui.control.Label
        cut                 matlab.ui.control.Button
        lvbo                matlab.ui.control.Button
        FIRType             matlab.ui.control.DropDown
        FIRDropDownLabel    matlab.ui.control.Label
        lvboType            matlab.ui.control.DropDown
        DropDown_2Label     matlab.ui.control.Label
        zudaidB             matlab.ui.control.NumericEditField
        Label_28            matlab.ui.control.Label
        tongdaidB           matlab.ui.control.NumericEditField
        Label_27            matlab.ui.control.Label
        zudaiHz             matlab.ui.control.NumericEditField
        Label_26            matlab.ui.control.Label
        tongdaiHz           matlab.ui.control.NumericEditField
        Label_25            matlab.ui.control.Label
        tdType              matlab.ui.control.DropDown
        Label_24            matlab.ui.control.Label
        IIRType             matlab.ui.control.DropDown
        IIRDropDownLabel    matlab.ui.control.Label
        Hfreq               matlab.ui.control.UIAxes
        hsignal             matlab.ui.control.UIAxes
        pH                  matlab.ui.control.UIAxes
        ampH                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Description
        generatedSignal
        generatedFs
        filecheck
    end

    methods (Access = private)
        function hideSigControls(app)
            % 隐藏所有参数控件
            set([app.Label_8, app.ampedit, app.Label_10, app.pedit], 'Visible', 'off'); % 正弦波
            set([app.HzEditField_2Label, app.fedit], 'Visible', 'off'); % 频率
            set([app.Label_12, app.Dutyratio], 'Visible', 'off'); % 方波占空比
            set([app.EditField_6Label, app.hunheampedit], 'Visible', 'off'); % 混合幅度
            set([app.HzEditFieldLabel_2, app.hunhefedit], 'Visible', 'off'); % 混合频率
            set([app.Label_16, app.hunhepedit], 'Visible', 'off'); % 混合相位
            app.file.Visible = 'off'; % 文件选择按钮
            app.tedit.Visible = 'off';
            set([app.sEditFieldLabel,app.tedit], 'Visible', 'off'); % 混合相位

        end

        function plotSpectrum(app, s, fs, signalType, addNoise)
            % 绘制信号频谱（无噪）或功率谱（含噪）

            N = length(s);
            L = 2^nextpow2(N); % 使用2的幂次方作为FFT长度

            % 计算FFT
            Y = fft(s, L);
            P2 = abs(Y/fs);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);

            % 频率轴
            f = fs*(0:(L/2))/L;

            % 清除之前的图形
            cla(app.signal_freq);

            if addNoise
                % 加噪信号：绘制功率谱
                [pxx, f_pxx] = periodogram(s, [], [], fs);
                pxx_dB = 10*log10(pxx);

                % 绘制功率谱
                plot(app.signal_freq, f_pxx, pxx_dB, 'b', 'LineWidth', 1.5);
                xlabel(app.signal_freq, '频率/Hz');
                ylabel(app.signal_freq, '功率谱密度 /dB');
                title(app.signal_freq, '功率谱分析');
                grid(app.signal_freq, 'on');
                box(app.signal_freq, 'on');

                % 设置横坐标范围为0到fs/2
                xlim(app.signal_freq, [0, fs/2]);
                xticks(app.signal_freq,0:fs/20:fs/2)
                xticklabels(app.signal_freq,0:fs/20:fs/2)

                % 自动调整纵坐标范围
                y_vals = pxx_dB;
                if ~isempty(y_vals)
                    y_min = min(y_vals);
                    y_max = max(y_vals);
                    y_range = y_max - y_min;
                    ylim(app.signal_freq, [y_min - 0.1*y_range, y_max + 0.1*y_range]);
                end

                % 标注主要频率分量
                if ismember(signalType, {'正弦波', '方波', '混合正弦波'})
                    % 寻找显著的峰值（幅度大于最大值的10%）
                    threshold = max(pxx) * 0.1;
                    peaks = [];
                    locs = [];

                    % 简单的峰值检测
                    for i = 2:length(pxx_dB)-1
                        if pxx(i) > pxx(i-1) && pxx(i) > pxx(i+1) && pxx(i) > threshold
                            peaks = [peaks, pxx_dB(i)];
                            locs = [locs, i];
                        end
                    end

                    % 按峰值大小排序
                    [peaks_sorted, sort_idx] = sort(peaks, 'descend');
                    locs_sorted = locs(sort_idx);

                    % 标注前3个主要频率
                    hold(app.signal_freq, 'on');
                    for i = 1:min(3, length(peaks_sorted))
                        plot(app.signal_freq, f_pxx(locs_sorted(i)), peaks_sorted(i), 'ro', ...
                            'MarkerSize', 5, 'LineWidth', 1.5);
                        text(app.signal_freq, f_pxx(locs_sorted(i))+fs/120, peaks_sorted(i), ...
                            sprintf('%.1f Hz', f_pxx(locs_sorted(i))), ...
                            'FontSize', 10, 'HorizontalAlignment', 'left');
                    end
                    hold(app.signal_freq, 'off');
                end

            else
                % 不加噪信号：绘制FFT频谱
                plot(app.signal_freq, f, P1, 'b', 'LineWidth', 1.5);
                xlabel(app.signal_freq, '频率/Hz');
                ylabel(app.signal_freq, '幅度');
                title(app.signal_freq, 'FFT频谱分析');
                grid(app.signal_freq, 'on');
                box(app.signal_freq, 'on');

                % 设置横坐标范围为0到fs/2
                xlim(app.signal_freq, [0, fs/2]);
                xticks(app.signal_freq,0:fs/20:fs/2)
                xticklabels(app.signal_freq,0:fs/20:fs/2)

                % 自动调整纵坐标范围
                if ~isempty(P1)
                    y_min = min(P1);
                    y_max = max(P1);
                    y_range = y_max - y_min;
                    % 设置纵坐标范围，留出10%的边距
                    if y_min == y_max
                        ylim(app.signal_freq, [y_min - 1, y_max + 1]);
                    else
                        ylim(app.signal_freq, [max(y_min - 0.1*y_range, 0), y_max + 0.1*y_range]);
                    end
                end

                % 标注主要频率分量
                if ismember(signalType, {'正弦波', '方波', '混合正弦波'})
                    % 寻找显著的峰值（幅度大于最大值的10%）
                    threshold = max(P1) * 0.1;
                    peaks = [];
                    locs = [];

                    % 简单的峰值检测
                    for i = 2:length(P1)-1
                        if P1(i) > P1(i-1) && P1(i) > P1(i+1) && P1(i) > threshold
                            peaks = [peaks, P1(i)];
                            locs = [locs, i];
                        end
                    end

                    % 按峰值大小排序
                    [peaks_sorted, sort_idx] = sort(peaks, 'descend');
                    locs_sorted = locs(sort_idx);

                    % 标注前3个主要频率
                    hold(app.signal_freq, 'on');
                    for i = 1:min(3, length(peaks_sorted))
                        plot(app.signal_freq, f(locs_sorted(i)), peaks_sorted(i), 'ro', ...
                            'MarkerSize', 5, 'LineWidth', 1.5);
                        text(app.signal_freq, f(locs_sorted(i))+fs/120, peaks_sorted(i), ...
                            sprintf('%.1f Hz', f(locs_sorted(i))), ...
                            'FontSize', 10, 'HorizontalAlignment', 'left');
                    end
                    hold(app.signal_freq, 'off');
                end
            end
        end

        function updateUIVisibility(app)
            % 更新UI控件的可见性
            signalType = app.SignalType.Value;
            addNoise = app.checknoise.Value;

            % 先隐藏所有控件
            hideSigControls(app);

            % 始终显示公共参数
            set([app.HzEditFieldLabel, app.fsedit], 'Visible', 'on');
            set([app.sEditFieldLabel, app.tedit], 'Visible', 'on');

            % 始终显示噪声复选框
            app.checknoise.Visible = 'on';

            % 根据信号类型显示相应参数
            switch signalType
                case '正弦波'
                    set([app.Label_8, app.ampedit], 'Visible', 'on'); % 幅度
                    set([app.Label_10, app.pedit], 'Visible', 'on');  % 相位
                    set([app.HzEditField_2Label, app.fedit], 'Visible', 'on'); % 频率

                case '方波'
                    set([app.Label_8, app.ampedit], 'Visible', 'on'); % 幅度
                    set([app.Label_12, app.Dutyratio], 'Visible', 'on'); % 占空比
                    set([app.HzEditField_2Label, app.fedit], 'Visible', 'on'); % 频率

                case '混合正弦波'
                    set([app.EditField_6Label, app.hunheampedit], 'Visible', 'on'); % 幅度(文本)
                    set([app.HzEditFieldLabel_2, app.hunhefedit], 'Visible', 'on'); % 频率(文本)
                    set([app.Label_16, app.hunhepedit], 'Visible', 'on'); % 相位(文本)

                case '加载文件信号'
                    app.file.Visible = 'on';
                    app.tedit.Visible = 'off';
            end

            % 如果选中添加噪声，显示噪声参数
            if addNoise
                set([app.Label_23, app.NoiseType, app.Label_11, app.aveedit, app.Label_13, app.varedit], 'Visible', 'on');
            end
        end

        function updateFilterUI(app)
            % 更新滤波器UI控件的可见性
            filterType = app.lvboType.Value;

            if strcmp(filterType, 'IIR滤波器')
                % 显示IIR滤波器相关控件
                app.IIRDropDownLabel.Visible = 'on';
                app.IIRType.Visible = 'on';
                app.FIRDropDownLabel.Visible = 'off';
                app.FIRType.Visible = 'off';
            else
                % 显示FIR滤波器相关控件
                app.IIRDropDownLabel.Visible = 'off';
                app.IIRType.Visible = 'off';
                app.FIRDropDownLabel.Visible = 'on';
                app.FIRType.Visible = 'on';
            end
        end


        function [b, a] = designFilter(app)
            % 设计滤波器并返回系数
            % 返回值: b - 分子系数, a - 分母系数

            filterType = app.lvboType.Value;      % IIR或FIR
            passType = app.tdType.Value;          % 低通或高通
            Rp = app.tongdaidB.Value;               % 通带波纹
            Rs = app.zudaidB.Value;                 % 阻带衰减
            fs = app.generatedFs;                   % 采样频率

            % 初始化输出参数（防止未赋值错误）
            b = [];
            a = [];

            % 检查必要的参数
            if isempty(fs) || fs <= 0
                errordlg('采样频率无效，请先生成信号', '参数错误');
                return;
            end

            % 根据滤波器类型获取频率参数
            if strcmp(passType, '低通滤波器') || strcmp(passType, '高通滤波器')
                % 低通或高通滤波器
                fp = app.tongdaiHz.Value;               % 通带截止频率
                fst = app.zudaiHz.Value;             % 阻带截止频率

                % 归一化频率
                wp = fp / (fs/2);
                ws = fst / (fs/2);

                % 将中文通带类型转换为英文
                if strcmp(passType, '低通滤波器')
                    passTypeEnglish = 'low';
                else 
                    passTypeEnglish = 'high';
                end
            else

                % 带通或带阻滤波器
                % 从文本字段读取频率参数
                fp_text = strtrim(app.dtztongdaiHz.Value);
                fst_text = strtrim(app.dtzzudaiHz.Value);

                % 分割字符串
                fp_strs = strsplit(fp_text, ',');
                fst_strs = strsplit(fst_text, ',');

                % 转换为数值
                fp = str2double(fp_strs);
                fst_values = str2double(fst_strs);

                % 归一化频率
                wp = fp / (fs/2);
                ws = fst_values / (fs/2);

                % 将中文通带类型转换为英文
                if strcmp(passType, '带通滤波器')
                    passTypeEnglish = 'bandpass';
                else
                    passTypeEnglish = 'stop';
                end
            end

            if strcmp(filterType, 'IIR滤波器')
                % IIR滤波器设计
                iirType = app.IIRType.Value;

                try
                    % 计算最小阶数
                    switch iirType
                        case '巴特沃斯'
                            [N, Wn] = buttord(wp, ws, Rp, Rs);
                            [b, a] = butter(N, Wn, passTypeEnglish);
                        case '切比雪夫I型'
                            [N, Wn] = cheb1ord(wp, ws, Rp, Rs);
                            [b, a] = cheby1(N, Rp, Wn, passTypeEnglish);
                        case '切比雪夫II型'
                            [N, Wn] = cheb2ord(wp, ws, Rp, Rs);
                            [b, a] = cheby2(N, Rs, Wn, passTypeEnglish);
                        case '椭圆'
                            [N, Wn] = ellipord(wp, ws, Rp, Rs);
                            [b, a] = ellip(N, Rp, Rs, Wn, passTypeEnglish);
                        otherwise
                            [N, Wn] = buttord(wp, ws, Rp, Rs);
                            [b, a] = butter(N, Wn, passTypeEnglish);
                    end
                catch ME
                    errordlg(sprintf('IIR滤波器设计失败: %s', ME.message), '设计错误');
                    return;
                end
            else
                % FIR滤波器设计
                firType = app.FIRType.Value;   
                
                % 缩小过渡带，更好满足指标
                wp = ws/3 + 2*wp/3;
                ws = 2*ws/3 + wp/3;  
                Delta_w = min(abs(ws - wp));      % 过渡带宽

                try
                    % 设置窗函数
                    switch firType
                        case '矩形窗'
                            N = round(1.8/(Delta_w) + abs(mod(1.8/(Delta_w),2)-1));
                            if N <= 65
                                N = 65;
                            end
                            window = rectwin(N);
                        case '汉宁窗'
                            N = round(6.2/(Delta_w) + abs(mod(6.2/(Delta_w),2)-1));
                            if N <= 65
                                N = 65;
                            end
                            window = hann(N);
                        case '汉明窗'
                            N = round(6.6/(Delta_w) + abs(mod(6.6/(Delta_w),2)-1));
                            if N <= 65
                                N = 65;
                            end
                            window = hamming(N);
                        case '布莱克曼窗'
                            N = round(11/(Delta_w) + abs(mod(11/(Delta_w),2)-1));
                            if N <= 65
                                N = 65;
                            end
                            window = blackman(N);
                        case '凯瑟窗'
                            beta = 0.1102 * (Rs - 8.7);  
                            N = ceil((Rs - 7.95)/(2.285 * Delta_w * pi) + 1);
                            N = round(N + abs(mod(N,2)-1)); 
                            if N <= 65
                                N = 65;
                            end
                            window = kaiser(N, beta);
                        otherwise
                            window = hamming(65);
                    end

                    % 使用英文通带类型
                    Wn = fp / (fs/2);
                    b = fir1(N - 1, Wn, passTypeEnglish, window);
                    a = 1; % FIR滤波器分母系数为1
                catch ME
                    errordlg(sprintf('FIR滤波器设计失败: %s', ME.message), '设计错误');
                    return;
                end
            end
        end


        function plotFilteredSignal(app, filteredSignal, fs)
            % 绘制滤波后信号
            % 绘制时域信号
            t = (0:length(filteredSignal)-1)/fs;

            plot(app.hsignal, t, filteredSignal, 'b', 'LineWidth', 1.5);
            title(app.hsignal, '滤波后信号波形');
            xlabel(app.hsignal, '时间/s');
            ylabel(app.hsignal, '幅度');
            grid(app.hsignal, 'on');
            box(app.hsignal, 'on');
            xlim(app.hsignal, [min(t), max(t)]);

            % 自动调整纵坐标范围
            if ~isempty(filteredSignal)
                y_min = min(filteredSignal);
                y_max = max(filteredSignal);
                y_range = y_max - y_min;
                ylim(app.hsignal, [y_min - 0.1*y_range, y_max + 0.1*y_range]);
            end

            % 绘制频谱
            N = length(filteredSignal);
            L = 2^nextpow2(N);
            Y = fft(filteredSignal, L);
            P2 = abs(Y/fs);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = fs*(0:(L/2))/L;

            plot(app.Hfreq, f, P1, 'b', 'LineWidth', 1.5);
            title(app.Hfreq, '滤波后信号频谱');
            xlabel(app.Hfreq, '频率/Hz');
            ylabel(app.Hfreq, '幅度');
            grid(app.Hfreq, 'on');
            box(app.Hfreq, 'on');
            xlim(app.Hfreq, [0, fs/2]);

            % 自动调整纵坐标范围
            if ~isempty(P1)
                y_min = min(P1);
                y_max = max(P1);
                y_range = y_max - y_min;
                if y_min == y_max
                    ylim(app.Hfreq, y_min - 1, y_max + 1);
                else
                    ylim(app.Hfreq, [max(0, y_min - 0.1*y_range), y_max + 0.1*y_range]);
                end
            end
        end

        function plotFilterResponse(app, b, a, fs)
            % 绘制滤波器响应
            % 计算频率响应
            [H, f] = freqz(b, a, 1024, fs);

            % 计算幅频响应（dB幅度）
            mag_dB = 20*log10(abs(H));

            % 计算折叠相频响应
            phase = angle(H);

            % 在app.ampH上绘制幅频响应（dB幅度）
            plot(app.ampH, f, mag_dB, 'b', 'LineWidth', 1.5);
            title(app.ampH, '滤波器幅频响应');
            xlabel(app.ampH, '频率/Hz');
            ylabel(app.ampH, '幅度/dB');
            grid(app.ampH, 'on');
            box(app.ampH, 'on');
            xlim(app.ampH, [0, fs/2]);

            % 自动调整纵坐标范围
            if ~isempty(mag_dB)
                y_min = min(mag_dB);
                y_max = max(mag_dB);
                y_range = y_max - y_min;
                % 设置纵坐标范围，留出10%的边距
                ylim(app.ampH, [y_min - 0.1*y_range, y_max + 0.1*y_range]);
            end

            % 在app.pH上绘制相频响应（折叠相位）
            plot(app.pH, f, phase, 'b', 'LineWidth', 1.5);
            title(app.pH, '滤波器相频响应');
            xlabel(app.pH, '频率/Hz');
            ylabel(app.pH, '相位/弧度');
            grid(app.pH, 'on');
            box(app.pH, 'on');
            xlim(app.pH, [0, fs/2]);

            % 设置相频响应的纵坐标范围为-pi到pi
            ylim(app.pH, [-pi, pi]);

            % 在相频响应图上添加水平参考线
            hold(app.pH, 'on');
            % 添加y=0的参考线
            plot(app.pH, [0, fs/2], [0, 0], 'k--', 'LineWidth', 0.5);
            % 添加y=pi和y=-pi的参考线
            plot(app.pH, [0, fs/2], [pi, pi], 'k--', 'LineWidth', 0.5);
            plot(app.pH, [0, fs/2], [-pi, -pi], 'k--', 'LineWidth', 0.5);
            hold(app.pH, 'off');

            % 添加纵坐标刻度标签，显示π的倍数
            app.pH.YTick = [-pi, -pi/2, 0, pi/2, pi];
            app.pH.YTickLabel = {'-\pi', '-\pi/2', '0', '\pi/2', '\pi'};
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: exp2
        function exp2ButtonDown(app, event)
            % updateSignalParams(app);
        end

        % Button pushed function: file
        function fileButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', '选择信号文件');

            if isequal(filename, 0)
                return;
            end

            fullpath = fullfile(pathname, filename);
            try
                data = load(fullpath);

                % 获取信号
                if isfield(data, 's')
                    s = data.s;
                else
                    fieldNames = fieldnames(data);
                    if ~isempty(fieldNames)
                        s = data.(fieldNames{1});
                    else
                        errordlg('文件中没有找到有效数据', '错误');
                        return;
                    end
                end

                % 获取采样频率
                fs = app.fsedit.Value;
                if fs <= 0
                    fs = 1000;
                    app.fsedit.Value = fs;
                end
            catch ME
                errordlg(sprintf('加载文件失败: %s', ME.message), '错误');
                return;
            end
            app.generatedSignal = s;
            app.generatedFs = fs;
            app.filecheck = 1;
        end

        % Button pushed function: generatesignal
        function generateSignalButtonPushed(app, event)
            % 获取公共参数
            signalType = app.SignalType.Value;
            fs = app.fsedit.Value;
            L = app.tedit.Value;
            addNoise = app.checknoise.Value;

            % 参数检查
            if fs <= 0
                errordlg('采样频率必须大于0', '参数错误');
                return;
            end

            if L <= 0
                errordlg('信号时长必须大于0', '参数错误');
                return;
            end

            % 生成时间轴
            N = floor(fs * L);
            t = (0:N)/fs;

            % 生成基础信号
            switch signalType
                case '正弦波'
                    A = app.ampedit.Value;
                    phi = app.pedit.Value;
                    f = app.fedit.Value;

                    if f <= 0
                        errordlg('信号频率必须大于0', '参数错误');
                        return;
                    end

                    s = A * cos(2*pi*f*t + deg2rad(phi));
                    title_str = sprintf('正弦波 (f=%.1fHz, A=%.1f, φ=%.1f°)', f, A, phi);

                case '方波'
                    A = app.ampedit.Value;
                    duty_cycle = app.Dutyratio.Value;
                    f = app.fedit.Value;

                    if f <= 0
                        errordlg('信号频率必须大于0', '参数错误');
                        return;
                    end

                    if duty_cycle < 0 || duty_cycle > 100
                        errordlg('占空比必须在0~100%之间', '参数错误');
                        return;
                    end

                    s = A * square(2*pi*f*t, duty_cycle);
                    title_str = sprintf('方波 (f=%.1fHz, A=%.1f, 占空比=%.1f%%)', f, A, duty_cycle);

                case '混合正弦波'
                    % 解析文本参数
                    A_text = strtrim(app.hunheampedit.Value);
                    f_text = strtrim(app.hunhefedit.Value);
                    phi_text = strtrim(app.hunhepedit.Value);

                    % 分割字符串
                    A_strs = strsplit(A_text, ',');
                    f_strs = strsplit(f_text, ',');
                    phi_strs = strsplit(phi_text, ',');

                    % 转换为数值
                    A_values = str2double(A_strs);
                    f_values = str2double(f_strs);
                    phi_values = str2double(phi_strs);

                    % 检查参数有效性
                    if any(isnan(A_values)) || any(isnan(f_values)) || any(isnan(phi_values))
                        errordlg('请输入有效的数值（用逗号分隔）', '参数错误');
                        return;
                    end

                    if length(A_values) ~= length(f_values) || length(A_values) ~= length(phi_values)
                        errordlg('幅度、频率和相位的数量必须相同', '参数错误');
                        return;
                    end

                    if any(f_values <= 0)
                        errordlg('所有频率必须大于0', '参数错误');
                        return;
                    end

                    % 生成混合信号
                    s = zeros(size(t));
                    for i = 1:length(A_values)
                        s = s + A_values(i) * cos(2*pi*f_values(i)*t + deg2rad(phi_values(i)));
                    end

                    % 构建标题
                    title_str = '混合正弦波';
                    for i = 1:length(A_values)
                        title_str = sprintf('%s (A%d=%.1f, f%d=%.1fHz, φ%d=%.1f°)', ...
                            title_str, i, A_values(i), i, f_values(i), i, phi_values(i));
                    end

                case '加载文件信号'
                    if app.filecheck == 0
                        errordlg('请先打开文件！','参数错误');
                        return;
                    end
                    s = app.generatedSignal;
                    fs = app.generatedFs;

                    % 生成时间轴
                    N = length(s);
                    t = (0:N-1)/fs;

                    % 绘制时域信号
                    plot(app.signal, t, s,'b');
                    xlabel(app.signal, '时间t/s');
                    ylabel(app.signal, '幅度');
                    title(app.signal, '加载的信号波形');
                    grid(app.signal, 'on');
                    box(app.signal, 'on');
                    xlim(app.signal, [t(1),t(end)])
                    ylim(app.signal, [min(s),max(s)])

                    % 检查是否添加噪声
                    addNoise = app.checknoise.Value;

                    if addNoise
                        % 获取噪声参数
                        mu = app.aveedit.Value;
                        sigma2 = app.varedit.Value;
                        noiseType = app.NoiseType.Value;

                        if sigma2 < 0
                            errordlg('方差不能为负数', '错误');
                            return;
                        end

                        % 生成噪声
                        switch noiseType
                            case '高斯白噪声'
                                noise = mu + sqrt(sigma2) * randn(size(s));
                            case '均匀白噪声'
                                a = mu - sqrt(3*sigma2);
                                b = mu + sqrt(3*sigma2);
                                noise = a + (b-a) * rand(size(s));
                        end

                        s = s + noise;
                    end

                    % 频谱分析
                    plotSpectrum(app, s, fs, '加载文件信号', addNoise);
                    return;
            end

            % 添加噪声（如果选中）
            if addNoise
                mu = app.aveedit.Value;
                sigma2 = app.varedit.Value;
                noiseType = app.NoiseType.Value;

                if sigma2 < 0
                    errordlg('方差不能为负数', '参数错误');
                    return;
                end

                % 生成噪声
                switch noiseType
                    case '高斯白噪声'
                        noise = mu + sqrt(sigma2) * randn(size(s));
                        noise_str = sprintf(' + 高斯噪声(μ=%.2f,σ²=%.2f)', mu, sigma2);
                    case '均匀白噪声'
                        a = mu - sqrt(3*sigma2);
                        b = mu + sqrt(3*sigma2);
                        noise = a + (b-a) * rand(size(s));
                        noise_str = sprintf(' + 均匀噪声(μ=%.2f,σ²=%.2f)', mu, sigma2);
                end

                s = s + noise;
                title_str = [title_str, noise_str];
            end
 
            % 绘制时域波形
            plot(app.signal, t, s ,'b', 'LineWidth', 1.5);
            xlabel(app.signal, '时间t/s');
            ylabel(app.signal, '幅度');
            title(app.signal, title_str);
            grid(app.signal, 'on');
            box(app.signal, 'on');

            % 设置横坐标范围为整个信号持续时间
            xlim(app.signal, [min(t), max(t)]);

            % 自动调整纵坐标范围
            if ~isempty(s)
                y_min = min(s);
                y_max = max(s);
                y_range = y_max - y_min;
                % 设置纵坐标范围，留出10%的边距
                if y_min == y_max
                    ylim(app.signal, [y_min - 1, y_max + 1]);
                else 
                    ylim(app.signal, [y_min - 0.1*y_range, y_max + 0.1*y_range]);
                end
            end

            % 频谱分析
            plotSpectrum(app, s, fs, signalType, addNoise);
            app.generatedSignal = s;
            app.generatedFs = fs;
        end

        % Value changed function: SignalType
        function signalTypeChanged(app, event)
            % 信号类型变更回调：更新对应UI显示并设置默认值
            % 获取当前选择的信号类型
            signalType = app.SignalType.Value;

            % 隐藏所有参数控件
            hideSigControls(app);

            % 显示采样频率和信号时长（这些是所有信号共用的）
            set([app.HzEditFieldLabel, app.fsedit], 'Visible', 'on');
            app.fsedit.Value = 1000;
            app.tedit.Value = 1;
            app.pedit.Value = 0;
            app.fedit.Value = 5;
            app.ampedit.Value = 1;
            app.aveedit.Value = 0;
            app.varedit.Value = 0.01;
            app.checknoise.Value = 0;

            % 根据信号类型显示特定参数控件
            switch signalType
                case '正弦波'
                    % 显示正弦波参数
                    set([app.Label_8, app.ampedit], 'Visible', 'on'); % 幅度
                    set([app.Label_10, app.pedit], 'Visible', 'on');  % 相位
                    set([app.HzEditField_2Label, app.fedit], 'Visible', 'on'); % 频率
                    set([app.sEditFieldLabel, app.tedit], 'Visible', 'on');

                case '方波'
                    % 显示方波参数
                    set([app.Label_8, app.ampedit], 'Visible', 'on'); % 幅度
                    set([app.Label_12, app.Dutyratio], 'Visible', 'on'); % 占空比
                    set([app.HzEditField_2Label, app.fedit], 'Visible', 'on'); % 频率
                    set([app.sEditFieldLabel, app.tedit], 'Visible', 'on');
                    app.Dutyratio.Value = 50;

                case '混合正弦波'
                    % 显示混合正弦波的文本输入框
                    set([app.EditField_6Label, app.hunheampedit], 'Visible', 'on'); % 幅度
                    set([app.HzEditFieldLabel_2, app.hunhefedit], 'Visible', 'on'); % 频率
                    set([app.Label_16, app.hunhepedit], 'Visible', 'on'); % 相位
                    set([app.sEditFieldLabel, app.tedit], 'Visible', 'on');
                    app.hunheampedit.Value = '1,1';
                    app.hunhefedit.Value = '5,150';
                    app.hunhepedit.Value = '0,0';

                case '高斯白噪声'
                    % 显示高斯白噪声参数
                    set([app.Label_11, app.aveedit], 'Visible', 'on'); % 均值
                    set([app.Label_13, app.varedit], 'Visible', 'on'); % 方差
                    set([app.sEditFieldLabel, app.tedit], 'Visible', 'on');

                case '均匀白噪声'
                    % 显示均匀白噪声参数
                    set([app.Label_11, app.aveedit], 'Visible', 'on'); % 均值
                    set([app.Label_13, app.varedit], 'Visible', 'on'); % 方差
                    set([app.sEditFieldLabel, app.tedit], 'Visible', 'on');

                case '加载文件信号'
                    % 显示文件选择按钮
                    app.file.Visible = 'on';
                    app.filecheck = 0;
            end
        end

        % Button pushed function: lvbo
        function lvboButtonPushed(app, event)
            % 检查是否生成信号
            if isempty(app.generatedSignal)
                errordlg('请先生成信号', '错误');
                return;
            end

            % 检查是否已存储采样频率
            if isempty(app.generatedFs)
                errordlg('未找到采样频率信息', '错误');
                return;
            end

            passType = app.tdType.Value;          % 低通或高通

            % 根据滤波器类型获取频率参数
            if strcmp(passType, '低通滤波器') || strcmp(passType, '高通滤波器')
                % 低通或高通滤波器
                fp = app.tongdaiHz.Value;               % 通带截止频率
                fst = app.zudaiHz.Value;             % 阻带截止频率

                % 参数检查
                if fp <= 0 || fst <= 0
                    errordlg('截止频率必须大于0', '参数错误');
                    return;
                end

                if strcmp(passType, '低通滤波器')
                    if fp >= fst
                        errordlg('低通滤波器：通带截止频率应小于阻带截止频率', '参数错误');
                        return;
                    end
                else % 高通滤波器
                    if fp <= fst
                        errordlg('高通滤波器：通带截止频率应大于阻带截止频率', '参数错误');
                        return;
                    end
                end
            else
                % 带通或带阻滤波器
                % 从文本字段读取频率参数
                fp_text = strtrim(app.dtztongdaiHz.Value);
                fst_text = strtrim(app.dtzzudaiHz.Value);

                % 分割字符串
                fp_strs = strsplit(fp_text, ',');
                fst_strs = strsplit(fst_text, ',');

                % 转换为数值
                fp_values = str2double(fp_strs);
                fst_values = str2double(fst_strs);

                % 检查参数有效性
                if any(isnan(fp_values)) || any(isnan(fst_values))
                    errordlg('请输入有效的频率数值（用逗号分隔）', '参数错误');
                    return;
                end

                if length(fp_values) ~= 2 || length(fst_values) ~= 2
                    errordlg('带通/带阻滤波器需要两个频率参数', '参数错误');
                    return;
                end

                if any(fp_values <= 0) || any(fst_values <= 0)
                    errordlg('截止频率必须大于0', '参数错误');
                    return;
                end

                % 检查频率顺序
                if fp_values(1) >= fp_values(2)
                    errordlg('通带截止频率必须由低到高', '参数错误');
                    return;
                end

                if fst_values(1) >= fst_values(2)
                    errordlg('阻带截止频率必须由低到高', '参数错误');
                    return;
                end

                % 根据滤波器类型检查频率关系
                if strcmp(passType, '带通滤波器')
                    % 带通滤波器：通带在中间，阻带在两边
                    if not(fp_values(1) > fst_values(1) && fp_values(2) < fst_values(2))
                        errordlg('带通滤波器：通带应在阻带之内', '参数错误');
                        return;
                    end
                else 
                    % 带阻滤波器：阻带在中间，通带在两边
                    if not(fst_values(1) > fp_values(1) && fst_values(2) < fp_values(2))
                        errordlg('带阻滤波器：阻带应在通带之内', '参数错误');
                        return;
                    end
                end
            end

            % 获取信号和采样频率
            ge_signal = app.generatedSignal;
            fs = app.generatedFs;

            % 设计滤波器
            try
                [b, a] = designFilter(app);
            catch ME
                errordlg(sprintf('滤波器设计失败: %s', ME.message), '错误');
                return;
            end

            % 绘制滤波器响应
            plotFilterResponse(app, b, a, fs);

            % 滤波信号
            filteredSignal = filter(b, a, ge_signal);

            % 绘制滤波后信号
            plotFilteredSignal(app, filteredSignal, fs);
        end

        % Button pushed function: cut
        function cutButtonPushed(app, event)
            delete(app.UIFigure)
        end

        % Value changed function: lvboType
        function lvboTypeChanged(app, event)
            % 滤波器类型变更回调：更新对应UI显示并设置默认值
            lvbotype = app.lvboType.Value;
            set([app.IIRDropDownLabel, app.IIRType, app.FIRDropDownLabel, app.FIRType], 'Visible', 'off');
            % 根据选中类型切换UI显示
            switch lvbotype
                case 'IIR滤波器'
                    set([app.IIRDropDownLabel, app.IIRType], 'Visible', 'on');
                    app.IIRType.Value = '巴特沃斯型';
                case 'FIR滤波器'
                    set([app.FIRDropDownLabel, app.FIRType], 'Visible', 'on');
                    app.FIRType.Value = '矩形窗';
            end
        end

        % Value changed function: tdType
        function tdTypeChanged(app, event)
            % 滤波器通带类型变更回调：更新对应UI显示并设置默认值
            tdtype = app.tdType.Value;
            set([app.tongdaiHz, app.Label_25, app.zudaiHz, app.Label_26], 'Visible', 'off');
            set([app.dtztongdaiHz, app.sLabel, app.dtzzudaiHz, app.HzLabel], 'Visible', 'off');
            app.lvboType.Value = 'IIR滤波器';
            set([app.IIRDropDownLabel, app.IIRType], 'Visible', 'on');
            set([app.FIRDropDownLabel, app.FIRType], 'Visible', 'off');
            app.IIRType.Value = '巴特沃斯型';
            % 根据选中类型切换UI显示，设置默认值
            switch tdtype
                case '低通滤波器'
                    set([app.tongdaiHz, app.Label_25, app.zudaiHz, app.Label_26], 'Visible', 'on');
                    app.tongdaiHz.Value = 100;
                    app.zudaiHz.Value = 200;
                    app.tongdaidB.Value = 0.25;
                    app.zudaidB.Value = 40;
                    
                case '高通滤波器'
                    set([app.tongdaiHz, app.Label_25, app.zudaiHz, app.Label_26], 'Visible', 'on');
                    app.tongdaiHz.Value = 200;
                    app.zudaiHz.Value = 100;
                    app.tongdaidB.Value = 0.25;
                    app.zudaidB.Value = 40;

                case '带通滤波器'
                    set([app.dtztongdaiHz, app.sLabel, app.dtzzudaiHz, app.HzLabel], 'Visible', 'on');
                    app.dtztongdaiHz.Value = '125,175';
                    app.dtzzudaiHz.Value = '100,200';
                    app.tongdaidB.Value = 0.25;
                    app.zudaidB.Value = 40;

                case '带阻滤波器'
                    set([app.dtztongdaiHz, app.sLabel, app.dtzzudaiHz, app.HzLabel], 'Visible', 'on');
                    app.dtztongdaiHz.Value = '100,200';
                    app.dtzzudaiHz.Value = '125,175';
                    app.tongdaidB.Value = 0.25;
                    app.zudaidB.Value = 40;

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '实验1-实验4';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.exp2 = uitab(app.TabGroup);
            app.exp2.Title = '典型信号生成与频谱分析';
            app.exp2.ButtonDownFcn = createCallbackFcn(app, @exp2ButtonDown, true);
            app.signal = uiaxes(app.exp2);
            title(app.signal, '信号波形')
            xlabel(app.signal, '时间t/s')
            ylabel(app.signal, '幅度')
            zlabel(app.signal, 'Z')
            app.signal_freq = uiaxes(app.exp2);
            title(app.signal_freq, '频谱/功率谱')
            xlabel(app.signal_freq, '频率/Hz')
            ylabel(app.signal_freq, '幅度')
            zlabel(app.signal_freq, 'Z')
            app.NumPanel = uipanel(app.exp2);
            app.NumPanel.Title = '信号参数设置';
            app.HzEditFieldLabel = uilabel(app.NumPanel);
            app.HzEditFieldLabel.Text = '采样频率/Hz';
            app.fsedit = uieditfield(app.NumPanel, 'numeric');
            app.fsedit.Value = 1000;
            app.sEditFieldLabel = uilabel(app.NumPanel);
            app.sEditFieldLabel.Text = '信号时长/s';
            app.tedit = uieditfield(app.NumPanel, 'numeric');
            app.tedit.Value = 1;
            app.HzEditField_2Label = uilabel(app.NumPanel);
            app.HzEditField_2Label.Text = '信号频率/Hz';
            app.fedit = uieditfield(app.NumPanel, 'numeric');
            app.fedit.Value = 5;
            app.Label_13 = uilabel(app.NumPanel);
            app.Label_13.Text = '方差';
            app.varedit = uieditfield(app.NumPanel, 'numeric');
            app.varedit.Value = 0.01;
            app.Label_12 = uilabel(app.NumPanel);
            app.Label_12.Visible = 'off';
            app.Label_12.Text = '占空比/%';
            app.Dutyratio = uieditfield(app.NumPanel, 'numeric');
            app.Dutyratio.Visible = 'off';
            app.Dutyratio.Value = 50;
            app.file = uibutton(app.NumPanel, 'push');
            app.file.ButtonPushedFcn = createCallbackFcn(app, @fileButtonPushed, true);
            app.file.Visible = 'off';
            app.file.Text = '选择文件';
            app.Label_16 = uilabel(app.NumPanel);
            app.Label_16.Visible = 'off';
            app.Label_16.Text = '信号相位/°';
            app.hunhepedit = uieditfield(app.NumPanel, 'text');
            app.hunhepedit.Visible = 'off';
            app.hunhepedit.Value = '0,0';
            app.HzEditFieldLabel_2 = uilabel(app.NumPanel);
            app.HzEditFieldLabel_2.Visible = 'off';
            app.HzEditFieldLabel_2.Text = '信号频率/Hz';
            app.hunhefedit = uieditfield(app.NumPanel, 'text');
            app.hunhefedit.Visible = 'off';
            app.hunhefedit.Value = '5,150';
            app.EditField_6Label = uilabel(app.NumPanel);
            app.EditField_6Label.Visible = 'off';
            app.EditField_6Label.Text = '信号幅度';
            app.hunheampedit = uieditfield(app.NumPanel, 'text');
            app.hunheampedit.Visible = 'off';
            app.hunheampedit.Value = '1,1';
            app.Label_8 = uilabel(app.NumPanel);
            app.Label_8.Text = '信号幅度';
            app.ampedit = uieditfield(app.NumPanel, 'numeric');
            app.ampedit.Value = 1;
            app.checknoise = uicheckbox(app.NumPanel);
            app.checknoise.Text = '是否添加噪声';
            app.Label_23 = uilabel(app.NumPanel);
            app.Label_23.Text = '噪声类型';
            app.NoiseType = uidropdown(app.NumPanel);
            app.NoiseType.Items = {'高斯白噪声', '均匀白噪声'};
            app.NoiseType.Value = '高斯白噪声';
            app.Label_10 = uilabel(app.NumPanel);
            app.Label_10.Text = '信号相位/°';
            app.pedit = uieditfield(app.NumPanel, 'numeric');
            app.generatesignal = uibutton(app.NumPanel, 'push');
            app.generatesignal.ButtonPushedFcn = createCallbackFcn(app, @generateSignalButtonPushed, true);
            app.generatesignal.Text = '生成信号';
            app.Label_11 = uilabel(app.NumPanel);
            app.Label_11.Text = '均值';
            app.aveedit = uieditfield(app.NumPanel, 'numeric');
            app.Panel = uipanel(app.exp2);
            app.Panel.Title = '信号类型选择';
            app.Label_14 = uilabel(app.Panel);
            app.Label_14.Text = '信号类型';
            app.SignalType = uidropdown(app.Panel);
            app.SignalType.Items = {'正弦波', '方波', '混合正弦波', '加载文件信号'};
            app.SignalType.ValueChangedFcn = createCallbackFcn(app, @signalTypeChanged, true);
            app.SignalType.Value = '正弦波';
            app.exp4 = uitab(app.TabGroup);
            app.exp4.Title = '数字滤波器设计';
            app.ampH = uiaxes(app.exp4);
            title(app.ampH, '滤波器幅频响应')
            xlabel(app.ampH, '频率/Hz')
            ylabel(app.ampH, '幅度/dB')
            zlabel(app.ampH, 'Z')
            app.pH = uiaxes(app.exp4);
            title(app.pH, '滤波器相频响应')
            xlabel(app.pH, '频率/Hz')
            ylabel(app.pH, '相位/弧度')
            zlabel(app.pH, 'Z')
            app.hsignal = uiaxes(app.exp4);
            title(app.hsignal, '滤波后信号波形')
            xlabel(app.hsignal, '时间/s')
            ylabel(app.hsignal, '幅度')
            zlabel(app.hsignal, 'Z')
            app.Hfreq = uiaxes(app.exp4);
            title(app.Hfreq, '滤波后信号频谱')
            xlabel(app.Hfreq, '频率/Hz')
            ylabel(app.Hfreq, '幅度')
            zlabel(app.Hfreq, 'Z')
            app.Panel_2 = uipanel(app.exp4);
            app.Panel_2.Title = '滤波器设计';
            app.IIRDropDownLabel = uilabel(app.Panel_2);
            app.IIRDropDownLabel.Text = 'IIR滤波器类型';
            app.IIRType = uidropdown(app.Panel_2);
            app.IIRType.Items = {'巴特沃斯型', '切比雪夫I型', '切比雪夫II型', '椭圆型'};
            app.IIRType.Value = '巴特沃斯型';
            app.Label_24 = uilabel(app.Panel_2);
            app.Label_24.Text = '通带类型';
            app.tdType = uidropdown(app.Panel_2);
            app.tdType.Items = {'低通滤波器', '高通滤波器', '带通滤波器', '带阻滤波器'};
            app.tdType.ValueChangedFcn = createCallbackFcn(app, @tdTypeChanged, true);
            app.tdType.Value = '低通滤波器';
            app.Label_25 = uilabel(app.Panel_2);
            app.Label_25.Text = '通带截止频率/Hz';
            app.tongdaiHz = uieditfield(app.Panel_2, 'numeric');
            app.tongdaiHz.Value = 100;
            app.Label_26 = uilabel(app.Panel_2);
            app.Label_26.Text = '阻带截止频率/Hz';
            app.zudaiHz = uieditfield(app.Panel_2, 'numeric');
            app.zudaiHz.Value = 200;
            app.Label_27 = uilabel(app.Panel_2);
            app.Label_27.Text = '通带波纹/dB';
            app.tongdaidB = uieditfield(app.Panel_2, 'numeric');
            app.tongdaidB.Value = 0.25;
            app.Label_28 = uilabel(app.Panel_2);
            app.Label_28.Text = '阻带衰减/dB';
            app.zudaidB = uieditfield(app.Panel_2, 'numeric');
            app.zudaidB.Value = 40;
            app.DropDown_2Label = uilabel(app.Panel_2);
            app.DropDown_2Label.Text = '滤波器类型';
            app.lvboType = uidropdown(app.Panel_2);
            app.lvboType.Items = {'IIR滤波器', 'FIR滤波器'};
            app.lvboType.ValueChangedFcn = createCallbackFcn(app, @lvboTypeChanged, true);
            app.lvboType.Value = 'IIR滤波器';
            app.FIRDropDownLabel = uilabel(app.Panel_2);
            app.FIRDropDownLabel.Visible = 'off';
            app.FIRDropDownLabel.Text = 'FIR滤波器类型';
            app.FIRType = uidropdown(app.Panel_2);
            app.FIRType.Items = {'矩形窗', '汉宁窗', '汉明窗', '布莱克曼窗', '凯瑟窗'};
            app.FIRType.Visible = 'off';
            app.FIRType.Value = '矩形窗';
            app.lvbo = uibutton(app.Panel_2, 'push');
            app.lvbo.ButtonPushedFcn = createCallbackFcn(app, @lvboButtonPushed, true);
            app.lvbo.Text = '开始滤波';
            app.cut = uibutton(app.Panel_2, 'push');
            app.cut.ButtonPushedFcn = createCallbackFcn(app, @cutButtonPushed, true);
            app.cut.Text = '关闭页面';
            app.sLabel = uilabel(app.Panel_2);
            app.sLabel.Visible = 'off';
            app.sLabel.Text = '通带截止频率/Hz';
            app.dtztongdaiHz = uieditfield(app.Panel_2, 'text');
            app.dtztongdaiHz.Visible = 'off';
            app.dtztongdaiHz.Value = '125,175';
            app.HzLabel = uilabel(app.Panel_2);
            app.HzLabel.Visible = 'off';
            app.HzLabel.Text = '阻带截止频率/Hz';
            app.dtzzudaiHz = uieditfield(app.Panel_2, 'text');
            app.dtzzudaiHz.Visible = 'off';
            app.dtzzudaiHz.Value = '100,200';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = week1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end